classdef S2V < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Surface2volumealgorithmUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadstateMenu                   matlab.ui.container.Menu
        SavestateMenu                   matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        READMEMenu                      matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        reiterate                       matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        S2VTab                          matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        AdjustTab                       matlab.ui.container.Tab
        ConvexifyoutersurfaceButton     matlab.ui.control.Button
        CollapseselectedsurfaceButton   matlab.ui.control.Button
        DomainTab                       matlab.ui.container.Tab
        DomainlimitsPanel               matlab.ui.container.Panel
        zminEditField                   matlab.ui.control.NumericEditField
        zminEditFieldLabel              matlab.ui.control.Label
        zmaxEditField                   matlab.ui.control.NumericEditField
        zmaxEditFieldLabel              matlab.ui.control.Label
        yminEditField                   matlab.ui.control.NumericEditField
        yminEditFieldLabel              matlab.ui.control.Label
        ymaxEditField                   matlab.ui.control.NumericEditField
        ymaxEditFieldLabel              matlab.ui.control.Label
        xmaxEditField                   matlab.ui.control.NumericEditField
        xmaxEditFieldLabel              matlab.ui.control.Label
        xminEditField                   matlab.ui.control.NumericEditField
        xminEditFieldLabel              matlab.ui.control.Label
        EnforcesymmetryabouttheyzplaneCheckBox  matlab.ui.control.CheckBox
        EnforcesymmetryaboutthexzplaneCheckBox  matlab.ui.control.CheckBox
        EnforcesymmetryaboutthexyplaneCheckBox  matlab.ui.control.CheckBox
        AlgorithmsTab                   matlab.ui.container.Tab
        AlgorithmForCase123456          matlab.ui.control.DropDown
        CaseS123456DropDownLabel        matlab.ui.control.Label
        AlgorithmForCase13456           matlab.ui.control.DropDown
        CaseS13456Label                 matlab.ui.control.Label
        AlgorithmForCase1345            matlab.ui.control.DropDown
        CaseS1345Label                  matlab.ui.control.Label
        AlgorithmForCase1234            matlab.ui.control.DropDown
        CaseS1234Label                  matlab.ui.control.Label
        AlgorithmForCase134             matlab.ui.control.DropDown
        CaseS134Label                   matlab.ui.control.Label
        AlgorithmForCase135             matlab.ui.control.DropDown
        CaseS135Label                   matlab.ui.control.Label
        AlgorithmForCase13              matlab.ui.control.DropDown
        CaseS13Label                    matlab.ui.control.Label
        AlgorithmForCase1               matlab.ui.control.DropDown
        CaseS1Label                     matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        PrioritizeleastnormaldeviationCheckBox  matlab.ui.control.CheckBox
        WeightsformakingprioritizationsPanel  matlab.ui.container.Panel
        InputsurfaceEditField           matlab.ui.control.NumericEditField
        InputsurfaceEditFieldLabel      matlab.ui.control.Label
        CaseswithsingularitiesEditField  matlab.ui.control.NumericEditField
        CaseswithsingularitiesEditFieldLabel  matlab.ui.control.Label
        CaseswithoppositefacesEditField  matlab.ui.control.NumericEditField
        CaseswithoppositefacesEditFieldLabel  matlab.ui.control.Label
        UseaveragenormalvectorsCheckBox  matlab.ui.control.CheckBox
        MaintaincollapsednessCheckBox   matlab.ui.control.CheckBox
        noExtraLayersEditField          matlab.ui.control.NumericEditField
        NumberofextralayersLabel        matlab.ui.control.Label
        EpsEditField                    matlab.ui.control.NumericEditField
        GeometrictoleranceEditFieldLabel  matlab.ui.control.Label
        AngledeviationEditField         matlab.ui.control.NumericEditField
        AngledeviationEditFieldLabel    matlab.ui.control.Label
        ThicknessEditField              matlab.ui.control.NumericEditField
        ThicknessEditFieldLabel         matlab.ui.control.Label
        AnglethresholdEditField         matlab.ui.control.NumericEditField
        AnglethresholdEditFieldLabel    matlab.ui.control.Label
        PostprocTab                     matlab.ui.container.Tab
        ExportPanel                     matlab.ui.container.Panel
        ExportButton                    matlab.ui.control.Button
        ExportObjSelection              matlab.ui.control.DropDown
        RefinementPanel                 matlab.ui.container.Panel
        RefineButton                    matlab.ui.control.Button
        RefineObjSelection              matlab.ui.control.DropDown
        Seth_maxEditField               matlab.ui.control.NumericEditField
        Seth_maxEditFieldLabel          matlab.ui.control.Label
        EditUniformDegree               matlab.ui.control.NumericEditField
        UseUniformDegree                matlab.ui.control.CheckBox
        InfoTab                         matlab.ui.container.Tab
        nurbsInfo                       matlab.ui.control.TextArea
        ClearselectionButton            matlab.ui.control.Button
        SelectpatchSpinner              matlab.ui.control.Spinner
        SelectpatchSpinnerLabel         matlab.ui.control.Label
        InfoObjSelection                matlab.ui.control.DropDown
        SelectobjectDropDownLabel       matlab.ui.control.Label
        NURBSTab                        matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        ForwardEnd                      matlab.ui.control.Button
        Forward                         matlab.ui.control.Button
        PlayNext                        matlab.ui.control.Button
        Play                            matlab.ui.control.Button
        Backward                        matlab.ui.control.Button
        BackwardEnd                     matlab.ui.control.Button
        reset                           matlab.ui.control.Button
        Loadg2fileButton                matlab.ui.control.Button
        ORLabel                         matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        OutputmessagesPanel             matlab.ui.container.Panel
        OutputMessages                  matlab.ui.control.TextArea
        PopoutButton                    matlab.ui.control.Button
        ReadyLampLabel                  matlab.ui.control.Label
        ReadyLamp                       matlab.ui.control.Lamp
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        PlotsettingsTab                 matlab.ui.container.Tab
        ObjectvisibilityPanel           matlab.ui.container.Panel
        S2VsurfaceCheckBox              matlab.ui.control.CheckBox
        RefinedoutputvolumeCheckBox     matlab.ui.control.CheckBox
        OutputvolumeCheckBox            matlab.ui.control.CheckBox
        RefinedinputsurfaceCheckBox     matlab.ui.control.CheckBox
        InputsurfaceCheckBox            matlab.ui.control.CheckBox
        RefinedinputobjectCheckBox      matlab.ui.control.CheckBox
        InputobjectCheckBox             matlab.ui.control.CheckBox
        SettingsforplottingPanel        matlab.ui.container.Panel
        useDrawnowCheckBox              matlab.ui.control.CheckBox
        ELEditField                     matlab.ui.control.NumericEditField
        ELEditFieldLabel                matlab.ui.control.Label
        AZEditField                     matlab.ui.control.NumericEditField
        AZEditFieldLabel                matlab.ui.control.Label
        ViewLabel                       matlab.ui.control.Label
        ColorfunctionEditField          matlab.ui.control.EditField
        CustomfunctionforcoloringLabel  matlab.ui.control.Label
        ColoringDropDown                matlab.ui.control.DropDown
        ColoringDropDownLabel           matlab.ui.control.Label
        AlphavalueEditField             matlab.ui.control.NumericEditField
        AlphavalueSlider                matlab.ui.control.Slider
        AlphavalueSliderLabel           matlab.ui.control.Label
        QuiverstepEditField             matlab.ui.control.NumericEditField
        QuiverstepEditFieldLabel        matlab.ui.control.Label
        AutoscaleCheckBox               matlab.ui.control.CheckBox
        QuiverscaleEditField            matlab.ui.control.NumericEditField
        QuiverscaleEditFieldLabel       matlab.ui.control.Label
        PlottingresolutionEditField     matlab.ui.control.NumericEditField
        PlottingresolutionEditFieldLabel  matlab.ui.control.Label
        showAvgNormals                  matlab.ui.control.CheckBox
        useSingleColorForInput          matlab.ui.control.CheckBox
        showAxis                        matlab.ui.control.CheckBox
        coarseLinearSamplingCheckBox    matlab.ui.control.CheckBox
        CamlightCheckBox                matlab.ui.control.CheckBox
        ShownormalvectorsCheckBox       matlab.ui.control.CheckBox
        ShowparametricdirectionsCheckBox  matlab.ui.control.CheckBox
        ShowcontrolpolygonCheckBox      matlab.ui.control.CheckBox
        ShowelementedgesCheckBox        matlab.ui.control.CheckBox
        ShowobjectCheckBox              matlab.ui.control.CheckBox
        PlotbrowserTab                  matlab.ui.container.Tab
        Tree                            matlab.ui.container.CheckBoxTree
        InputobjectNode                 matlab.ui.container.TreeNode
        InputsurfaceNode                matlab.ui.container.TreeNode
        OutputvolumeNode                matlab.ui.container.TreeNode
        S2VsurfaceNode                  matlab.ui.container.TreeNode
        RefinedinputobjectNode          matlab.ui.container.TreeNode
        RefinedinputsurfaceNode         matlab.ui.container.TreeNode
        RefinedoutputvolumeNode         matlab.ui.container.TreeNode
        RefreshtreeButton               matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        ObjectMenu                      matlab.ui.container.Menu
        ElementedgesMenu                matlab.ui.container.Menu
        ControlpolygonMenu              matlab.ui.container.Menu
        ParametricdirectionsMenu        matlab.ui.container.Menu
        NormalvectorsMenu               matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        thickness   % Thickness of patches to be extruded
        sharpAngle 
        avg_v_n_threshholdAngle
        plotHandles
        camlightHandle
        rotateHandle
        plotColor
        options_default
        colors % Colors used for volumetric meshing
        no_colors % Colors used for volumetric meshing
        stringShift
        objectVisibility
        plotFieldNames
        selectedObjects
        prevSelectedObjects
        lastSelectedObject
        S2VerrorOccured
        SelectedPointHandle
        selectedPointColor
    end

    properties (Access = public)
        nurbsObjects % A 3 by 2 cell array of all NURBS objects and their refinement objects
        Eps % Geometric tolerance matching
        leftClickSelectionColor % Color for selected surfaces
        rightClickSelectionColor % Color for selected surfaces
        S2Vobj   % Object containing current state of the S2V algorithm
        noSurfPatches
        idleAutomation
        noBdryPatches
        history
        iteration
    end
    
    methods (Access = private)
            
        
        function allocatePlotHandle(app,i_p_arr,j_p_arr)
            if nargin < 2
                i_p_arr = 1:size(app.nurbsObjects,1);
            end
            if nargin < 3
                j_p_arr = 1:size(app.nurbsObjects,2);
            end
            for i_p = i_p_arr
                for j_p = j_p_arr
                    noPatches = numel(app.nurbsObjects{i_p,j_p}.nurbs);
                    if isempty(app.plotHandles{i_p,j_p}) || isempty(app.plotHandles{i_p,j_p}.objectHandle)
                        app.plotHandles{i_p,j_p}.objectHandle = gobjects(1,noPatches);
                    end
                    if ~isfield(app.plotHandles{i_p,j_p}, 'elementEdgesHandle') || isempty(app.plotHandles{i_p,j_p}.elementEdgesHandle)
                        app.plotHandles{i_p,j_p}.elementEdgesHandle = gobjects(1,noPatches);
                    end
                    if ~isfield(app.plotHandles{i_p,j_p}, 'controlPolygonHandle') || isempty(app.plotHandles{i_p,j_p}.controlPolygonHandle)
                        app.plotHandles{i_p,j_p}.controlPolygonHandle = gobjects(1,noPatches);
                    end
                    if ~isfield(app.plotHandles{i_p,j_p}, 'parmDirHandle') || isempty(app.plotHandles{i_p,j_p}.parmDirHandle)
                        app.plotHandles{i_p,j_p}.parmDirHandle = gobjects(3,noPatches);
                    end
                    if ~isfield(app.plotHandles{i_p,j_p}, 'normalVectorsHandle') || isempty(app.plotHandles{i_p,j_p}.normalVectorsHandle)
                        app.plotHandles{i_p,j_p}.normalVectorsHandle = gobjects(1,noPatches);
                    end
                end
            end
            app.plotFieldNames = fieldnames(app.plotHandles{1,1}).';
        end

        function obj = deleteObjects(app,obj,indices,removeElements)
            if nargin < 4
                removeElements = true;
            end
            if nargin < 3
                indices = 1:numel(obj);
            end
            if isempty(indices)
                return
            end
            if isempty(obj)
                return
            end
            % Used to delete figure handles
            if isa(obj,'struct')
                fieldNames = fieldnames(obj);
                for i = 1:numel(fieldNames)
                    obj.(fieldNames{i}) = deleteObjects(app,obj.(fieldNames{i}),indices,removeElements);
                end
            else
                if size(obj,1) > 1
                    indices(indices > size(obj,2)) = [];
                    delete(obj(:,indices))
                    if removeElements
                        obj(:,indices) = [];
                    end
                else
                    indices(indices > numel(obj)) = [];
                    delete(obj(indices))
                    if removeElements
                        obj(indices) = [];
                    end
                end
            end
        end

        function resetCamlight(app)
            if app.CamlightCheckBox.Value 
                app.CamlightCheckBox.Value = false;
                CamlightCheckBoxValueChanged(app)
                app.CamlightCheckBox.Value = true;
                CamlightCheckBoxValueChanged(app)
            end
        end

        function cleanUpPlots(app,i_p_arr,j_p_arr,indices,removeElements)
            if nargin < 2
                i_p_arr = 1:size(app.plotHandles,1);
            end
            if nargin < 3
                j_p_arr = 1:size(app.plotHandles,2);
            end
            if nargin < 5
                removeElements = true;
            end
            ClearselectionButtonPushed(app,false)
            for i_p = i_p_arr
                for j_p = j_p_arr
                    if ~isempty(app.plotHandles{i_p,j_p})
                        if nargin < 4
                            indices = 1:numel(app.plotHandles{i_p,j_p}.objectHandle);
                        end

                        app.plotHandles{i_p,j_p} = deleteObjects(app,app.plotHandles{i_p,j_p},indices,removeElements);
                    end
                end
            end
            for i = 1:numel(app.history)
                if isfield(app.history(i),'avgNormalVectorsHandle')
                    if nargin < 4
                        indices = 1:numel(app.history(i).avgNormalVectorsHandle);
                    end
                    app.history(i).avgNormalVectorsHandle = deleteObjects(app,app.history(i).avgNormalVectorsHandle,indices,removeElements);
                end
            end
            deleteObjects(app,app.SelectedPointHandle);
            if nargin < 2
                delete(app.UIAxes.Children) % Remove objects not deleted due to potential bugs (This should not be needed in a bug free code)
            end
        end

        function refreshPlots(app)
            cleanUpPlots(app)
            allocatePlotHandle(app)
            options = getOptions(app);
            options.i_p_arr = 4;
            options.j_p_arr = 1;
            app.S2VsurfaceCheckBox.Value = true;
            plot_nurbs(app,options)
            set(app.UIAxes,'xlimMode', 'auto') 
            set(app.UIAxes,'ylimMode', 'auto')
            set(app.UIAxes,'zlimMode', 'auto')
            updateNURBSinfo(app)
%             resetCamlight(app)
        end

        function clearTree(app,i_p_arr,j_p_arr)
            for i_p = i_p_arr
                for j_p = j_p_arr
                    i_obj = (j_p-1)*size(app.plotHandles,1) + i_p;
                    if ~(i_p == 4 && j_p == 2)
                        delete(app.Tree.Children(i_obj).Children)
                    end
                end
            end
        end

        function color = getS2VpatchColor(app,indices,i_p)
            if i_p == 1 || i_p == 2
                color = repmat(getColor(),numel(indices),1);
            else
                color = app.colors(mod(indices.'-1,app.no_colors)+1,:);
                greyPatches = and(all(isnan(app.S2Vobj.angles(indices,:)),2),i_p == 4);
                color(greyPatches,:) = repmat(getColor(),sum(greyPatches),1);
    
                % Selection color takes presedence over gray color
                indicesSelection = ismember(indices,app.selectedObjects);
                if any(indicesSelection)
                    color(indicesSelection,:) = repmat(app.leftClickSelectionColor,sum(indicesSelection),1);
                end
            end
        end

        function plot_nurbs(app,varargin)
            switchLamp(app,'Plotting ...')
            updateObjectVisibilityFromPanel(app)
            options = app.options_default;
            if nargin > 1
                if numel(varargin) > 2
                    newOptions = varargin(1:end);
                else
                    newOptions = varargin{1};
                end
                options = updateOptions(options,newOptions);
            end
            if isfield(options, 'i_p_arr')
                i_p_arr = options.i_p_arr;
            else
                i_p_arr = 1:size(app.nurbsObjects,1);
            end
            if isfield(options, 'j_p_arr')
                j_p_arr = options.j_p_arr;
            else
                j_p_arr = 1:size(app.nurbsObjects,2);
            end
            ax = app.UIAxes;
            tic
            printOutputMessage(app,sprintf(['%-' num2str(app.stringShift) 's'], 'Plotting patches ... '))
            for i_p = i_p_arr
                for j_p = j_p_arr
                    if app.objectVisibility(i_p,j_p)
                        if isfield(options, 'indices')
                            if i_p == 3
                                indices = options.indices{1};
                            else
                                indices = options.indices{2};
                            end
                        else
                            indices = 1:numel(app.nurbsObjects{i_p,j_p}.nurbs);
                        end


                        % Temporarily save original handle visibilities
                        for i = 1:numel(app.plotFieldNames)
                            if strcmp(app.plotFieldNames{i},'parmDirHandle')
                                visibleObjects.(app.plotFieldNames{i}) = false(3,numel(app.nurbsObjects{i_p,j_p}.nurbs));
                            else
                                visibleObjects.(app.plotFieldNames{i}) = false(1,numel(app.nurbsObjects{i_p,j_p}.nurbs));
                            end
                            if options.replot
                                for patch = indices
                                    if strcmp(app.plotFieldNames{i},'parmDirHandle')
                                        for j = 1:size(app.plotHandles{i_p,j_p}.(app.plotFieldNames{i}),1)
                                            if objIsValid(app,app.plotHandles{i_p,j_p}.(app.plotFieldNames{i})(j,:), patch)
                                                visibleObjects.(app.plotFieldNames{i})(j,patch) = app.plotHandles{i_p,j_p}.(app.plotFieldNames{i})(j,patch).Visible;
                                            end
                                        end
                                    else
                                        if objIsValid(app,app.plotHandles{i_p,j_p}.(app.plotFieldNames{i}), patch)
                                            visibleObjects.(app.plotFieldNames{i})(:,patch) = app.plotHandles{i_p,j_p}.(app.plotFieldNames{i})(patch).Visible;
                                        end
                                    end
                                end
                            end
                        end
                        objectLacking = false(size(app.plotFieldNames));
                        if options.replot
                            objectLacking(:) = true;
                            cleanUpPlots(app,i_p,j_p,indices,false)
                        else
                            for i = 1:numel(app.plotFieldNames)
                                objectLacking(i) = max(indices) > numel(app.plotHandles{i_p,j_p}.(app.plotFieldNames{i})) ... % A new S2V iteration requires new patches to be plotted
                                                    || isempty(app.plotHandles{i_p,j_p}.(app.plotFieldNames{i})(indices)) ...
                                                    || isa(app.plotHandles{i_p,j_p}.(app.plotFieldNames{i})(1),'matlab.graphics.GraphicsPlaceholder') ...
                                                    || ~all(isvalid(app.plotHandles{i_p,j_p}.(app.plotFieldNames{i})(indices)));
                            end
                        end
                        objVisibility = [options.plotObject || any(visibleObjects.objectHandle);
                                         options.plotElementEdges || any(visibleObjects.elementEdgesHandle);
                                         options.plotControlPolygon || any(visibleObjects.controlPolygonHandle);
                                         options.plotParmDir || any(visibleObjects.parmDirHandle,'all');
                                         options.plotNormalVectors || any(visibleObjects.normalVectorsHandle)].';
                        evaluateObj = and(objectLacking, objVisibility);
                        if ~isempty(app.nurbsObjects{i_p,j_p}.nurbs) && any(evaluateObj)
                            if i_p == 3 || i_p == 4 || ~app.useSingleColorForInput.Value
                                options.color = getS2VpatchColor(app,indices,i_p);
                                if app.showAvgNormals.Value %j_p == 1
                                    plotAverageNormalVectors(app)
                                end
                            end
                            options.displayName = @(patch_i) ['Patch ' num2str(indices(patch_i))];
                            options.plotObject = evaluateObj(1);
                            options.plotElementEdges = evaluateObj(2);
                            options.plotControlPolygon = evaluateObj(3);
                            options.plotParmDir = evaluateObj(4);
                            options.plotNormalVectors = evaluateObj(5);
                            options.UserData.indices = indices;
                            options.UserData.i_p = i_p;
                            options.UserData.j_p = j_p;
                            h = plotNURBS(ax,app.nurbsObjects{i_p,j_p}.nurbs(indices),options);
                            for i = 1:numel(app.plotFieldNames)
                                if evaluateObj(i)
                                    if strcmp(app.plotFieldNames{i},'parmDirHandle')
                                        app.plotHandles{i_p,j_p}.(app.plotFieldNames{i})(:,indices) = h.(app.plotFieldNames{i});
                                    else
                                        app.plotHandles{i_p,j_p}.(app.plotFieldNames{i})(indices) = h.(app.plotFieldNames{i});
                                    end
                                end
                            end
                        end
                        if options.replot
                            % Restore original handle visibilities
                            for i = 1:numel(app.plotFieldNames)
                                for patch = indices
                                    if strcmp(app.plotFieldNames{i},'parmDirHandle')
                                        for j = 1:size(app.plotHandles{i_p,j_p}.(app.plotFieldNames{i}),1)
                                            if objIsValid(app,app.plotHandles{i_p,j_p}.(app.plotFieldNames{i})(j,:), patch)
                                                app.plotHandles{i_p,j_p}.(app.plotFieldNames{i})(j,patch).Visible = visibleObjects.(app.plotFieldNames{i})(j,patch);
                                            end
                                        end
                                    else
                                        if objIsValid(app,app.plotHandles{i_p,j_p}.(app.plotFieldNames{i}), patch)
                                            app.plotHandles{i_p,j_p}.(app.plotFieldNames{i})(patch).Visible = visibleObjects.(app.plotFieldNames{i})(:,patch);
                                        end
                                    end
                                end
                            end
                        end

%                         toggleObjects(app,true,i_p,j_p,indices)

%                         updateTree(app,i_p,j_p,indices) % This is way to slow to be used here. The "RefreshtreeButtonPushed" function was
%                         therefore introduced
                    end
                end
            end
            printOutputMessage(app,sprintf('using %12f seconds.', toc))
            showAxisValueChanged(app)
            if app.useDrawnowCheckBox.Value
                drawnow
            end
            
            if app.CamlightCheckBox.Value && ~(~isempty(app.camlightHandle) && isvalid(app.camlightHandle) && strcmp(app.camlightHandle.Visible,'on'))
                app.camlightHandle = camlight(ax);
            end
            if isempty(app.rotateHandle) || ~isvalid(app.rotateHandle)
                app.rotateHandle = rotate3d(app.UIAxes);
            end
            
        end

        function plotAverageNormalVectors(app)
            noPatches = numel(app.S2Vobj.nurbs_bdry);
            app.history(app.iteration+1).avgNormalVectorsHandle = gobjects(4,noPatches);
            for patch = 1:noPatches
                if ~all(isnan(app.S2Vobj.angles(patch,:)))
                    for midx = 1:4
                        app.history(app.iteration+1).avgNormalVectorsHandle(midx,patch) = quiver3(app.UIAxes, ...
                                app.S2Vobj.edgeData(patch).X{midx}(:,1), ...
                                app.S2Vobj.edgeData(patch).X{midx}(:,2), ...
                                app.S2Vobj.edgeData(patch).X{midx}(:,3), ...
                                app.S2Vobj.edgeData(patch).avg_v_n{midx}(:,1), ...
                                app.S2Vobj.edgeData(patch).avg_v_n{midx}(:,2), ...
                                app.S2Vobj.edgeData(patch).avg_v_n{midx}(:,3), ...
                                'LineWidth',1,'AutoScale','off','DisplayName',['Avg. normals, patch = ' num2str(patch)]);
                    end
                end
            end
        end

        function valid = objIsValid(~,obj,indices)
            if nargin < 3
                indices = 1:numel(obj);
            end
            if isempty(indices) || isempty(obj)
                valid = false;
                return
            end
            if max(indices) > numel(obj) 
                valid = false;
                return
            end
            anyIsPlaceHolder = false;
            for i = 1:numel(indices)
                if isa(obj(indices(i)),'matlab.graphics.GraphicsPlaceholder') 
                    anyIsPlaceHolder = true;
                    break
                end
            end
            valid = ~anyIsPlaceHolder && all(isvalid(obj(indices)));
        end

        function alterVisibility(app,obj,indices,visible)
            if objIsValid(app,obj,indices)
                if nargin < 4
                    obj(indices).Visible = ~obj(indices).Visible;
                else
                    obj(indices).Visible = visible;
                end
            end
        end

        function toggleObjects(app,showObjects,i_p_arr,j_p_arr,indices)
            if nargin < 2
                showObjects = true;
            end
            showObjects_old = showObjects;
            if nargin < 3
                i_p_arr = 1:size(app.plotHandles,1);
            end
            if nargin < 4
                j_p_arr = 1:size(app.plotHandles,2);
            end
            options = getOptions(app);
            for i_p = i_p_arr
                for j_p = j_p_arr
                    if nargin < 5
                        indices = 1:numel(app.nurbsObjects{i_p,j_p}.nurbs);
                    end
                    for patch = indices
                        try
                            if i_p == 3
                                showObject = showObjects_old && (patch <= app.iteration || app.S2Vobj.S2Vcompleted) && app.objectVisibility(i_p,j_p);
                            elseif i_p == 4
                                showObject = showObjects_old && patch <= app.noBdryPatches(app.iteration+1) && app.objectVisibility(i_p,j_p);
                            else
                                showObject = showObjects_old && app.objectVisibility(i_p,j_p);
                            end
                            alterVisibility(app,app.plotHandles{i_p,j_p}.objectHandle,          patch, app.ShowobjectCheckBox.Value         && showObject)
                            alterVisibility(app,app.plotHandles{i_p,j_p}.elementEdgesHandle,    patch, app.ShowelementedgesCheckBox.Value   && showObject)
                            alterVisibility(app,app.plotHandles{i_p,j_p}.controlPolygonHandle,  patch, app.ShowcontrolpolygonCheckBox.Value && showObject)
                            alterVisibility(app,app.plotHandles{i_p,j_p}.normalVectorsHandle,   patch, app.ShownormalvectorsCheckBox.Value  && showObject)
                            for j = 1:size(app.plotHandles{i_p,j_p}.parmDirHandle,1)
                                alterVisibility(app,app.plotHandles{i_p,j_p}.parmDirHandle(j,:), patch, app.ShowparametricdirectionsCheckBox.Value && showObject)
                            end
                            if app.ShownormalvectorsCheckBox.Value && showObject
                                if app.AutoscaleCheckBox.Value
                                    app.plotHandles{i_p,j_p}.normalVectorsHandle(patch).AutoScale = 'on';
                                    app.plotHandles{i_p,j_p}.normalVectorsHandle(patch).AutoScaleFactor = app.QuiverscaleEditField.Value;
                                end
                            end
                            if app.ShowparametricdirectionsCheckBox.Value && showObject
                                for ii = 1:size(app.plotHandles{i_p,j_p}.parmDirHandle,1)
                                    if ~isa(app.plotHandles{i_p,j_p}.parmDirHandle(ii,patch),'matlab.graphics.GraphicsPlaceholder')
                                        app.plotHandles{i_p,j_p}.parmDirHandle(ii,patch).AutoScale = 'on';
                                        app.plotHandles{i_p,j_p}.parmDirHandle(ii,patch).AutoScaleFactor = app.QuiverscaleEditField.Value;
                                    end
                                end
                            end
                            obj = app.plotHandles{i_p,j_p}.objectHandle;
                            if objIsValid(app,app.plotHandles{i_p,j_p}.objectHandle,patch)
                                obj(patch).FaceAlpha = app.AlphavalueEditField.Value;
                                if strcmp(app.ColoringDropDown.Value,'None')
                                    if i_p == 3 || i_p == 4 || ~app.useSingleColorForInput.Value
                                        obj(patch).FaceColor = getS2VpatchColor(app,patch,i_p);
                                    else
                                        obj(patch).FaceColor = options.color;
                                    end
                                end
                            end
                        catch ME
                            warning(['Could not toggle objects due to the following error: ' ME.message])
                        end
                        if app.showAvgNormals.Value
                            if i_p == 4 && j_p == 1
                                for i = 1:numel(app.history)
                                    showObject = showObjects_old && app.objectVisibility(i_p,j_p);
                                    for patch = 1:numel(app.history(i).S2Vobj.nurbs_bdry)
                                        for midx = 1:4
                                            if app.history(i).avgNormalVectorsHandle(midx,patch) == 0
                                                keyboard
                                            end
                                            alterVisibility(app,app.history(i).avgNormalVectorsHandle(midx,patch), patch, app.showAvgNormals.Value && showObject && i == app.iteration)
                                        end
                                    end
                                end
                            end
                        end
                    end
                end
            end
        end

        function plotIfNotPresent(app,showPlot,i_p,j_p)
            if showPlot && ~isempty(app.nurbsObjects{i_p,j_p}.nurbs) && (~objIsValid(app,app.plotHandles{i_p,j_p}.objectHandle) || numel(app.plotHandles{i_p,j_p}.objectHandle) < numel(app.nurbsObjects{i_p,j_p}.nurbs)) 
                options = getOptions(app);
                options.i_p_arr = i_p;
                options.j_p_arr = j_p;
                plot_nurbs(app,options)
            end
            toggleObjects(app,showPlot,i_p,j_p)
        end

        function iterateCounters(app,shift)
            app.iteration = max(app.iteration + shift,0);
        end

        function switchLamp(app,text)
            if nargin < 2
                app.ReadyLamp.Color = 'green';
                app.ReadyLampLabel.Text = 'Ready';
            else
                app.ReadyLamp.Color = 'red';
                app.ReadyLampLabel.Text = text;
                if app.useDrawnowCheckBox.Value
                    drawnow
                end
            end      
        end
        
        function options = getOptions(app)
            options = struct('plotObject',app.ShowobjectCheckBox.Value,...
                                'plotElementEdges',app.ShowelementedgesCheckBox.Value,...
                                'plotControlPolygon',app.ShowcontrolpolygonCheckBox.Value,...
                                'plotParmDir',app.ShowparametricdirectionsCheckBox.Value,...
                                'plotNormalVectors',app.ShownormalvectorsCheckBox.Value,...
                                'plotColorFun',strcmp(app.ColoringDropDown.Value,'Custom'),...
                                'plotWeights',strcmp(app.ColoringDropDown.Value,'Weights'),...
                                'plotJacobian',strcmp(app.ColoringDropDown.Value,'Jacobian'),...
                                'coarseLinearSampling',app.coarseLinearSamplingCheckBox.Value,...
                                'alphaValue',app.AlphavalueSlider.Value,...
                                'quiverScale',app.QuiverscaleEditField.Value,...
                                'qstep',app.QuiverstepEditField.Value,...
                                'quiverAutoScale',app.AutoscaleCheckBox.Value,...
                                'color',app.plotColor,...
                                'colorFun', str2func(app.ColorfunctionEditField.Value), ...
                                'resolution',app.PlottingresolutionEditField.Value*ones(1,3), ...
                                'app', app, ...
                                'replot', false);
        end
        
        function options = getS2VOptions(app)
            options = struct('S2V_algorithm', struct('A1',app.AlgorithmForCase1.Value,...
                                                    'A13',app.AlgorithmForCase13.Value,...
                                                    'A135',app.AlgorithmForCase135.Value,...
                                                    'A134',app.AlgorithmForCase134.Value,...
                                                    'A1234',app.AlgorithmForCase1234.Value,...
                                                    'A1345',app.AlgorithmForCase1345.Value,...
                                                    'A13456',app.AlgorithmForCase13456.Value,...
                                                    'A123456',app.AlgorithmForCase123456.Value), ...
                            't',app.ThicknessEditField.Value, ...
                            'sharpAngle',app.sharpAngle, ...
                            'avg_v_n_threshholdAngle',app.avg_v_n_threshholdAngle, ...
                            'Eps', app.EpsEditField.Value, ...
                            'maintainCollapsedness',app.MaintaincollapsednessCheckBox.Value, ...
                            'useAvgNormals', app.UseaveragenormalvectorsCheckBox.Value, ...
                            'default_degree',app.EditUniformDegree.Value, ...
                            'noExtraLayers', app.noExtraLayersEditField.Value, ... % Number of extra layer of patches after covering the input surface sufficiently with patches
                            'domainLimits', [app.xminEditField.Value,app.xmaxEditField.Value;app.yminEditField.Value,app.ymaxEditField.Value;app.zminEditField.Value,app.zmaxEditField.Value], ... % Set Cartesian limits for the meshing domain
                            'enforceSymmetryAboutTheXYplane',app.EnforcesymmetryaboutthexyplaneCheckBox.Value, ... % Assume symmetry about the xy-plane
                            'enforceSymmetryAboutTheXZplane',app.EnforcesymmetryaboutthexzplaneCheckBox.Value, ... % Assume symmetry about the xz-plane
                            'enforceSymmetryAboutTheYZplane',app.EnforcesymmetryabouttheyzplaneCheckBox.Value, ... % Assume symmetry about the yz-plane
                            'prioritizeLeastNormalDeviation', app.PrioritizeleastnormaldeviationCheckBox.Value, ... % Set the patch with opposite corner having the least amount of deviation between the vectors v_n and avg_v_n as master patch
                            'prioritizeCasesOfOppositeFacesWeight', app.CaseswithoppositefacesEditField.Value, ... % Adjust weight to prioritize cases where opposite faces are known
                            'prioritizeCasesSingularitiesWeight', app.CaseswithsingularitiesEditField.Value, ... % Adjust weight to prioritize cases containing singularities
                            'prioritizeInputFacesWeight', app.InputsurfaceEditField.Value); % Adjust weight to prioritize input surface
        end
        
        function processNURBSinput(app)
            app.nurbsObjects{1,1}.nurbs = cleanNURBS(app.nurbsObjects{1,1}.nurbs,[],app.Eps);
            app.nurbsObjects{2,1}.nurbs = explodeNURBS(app.nurbsObjects{1,1}.nurbs);
            if app.nurbsObjects{2,1}.nurbs{1}.d_p == 3
                app.nurbsObjects{2,1}.nurbs = extractFreeSurface(app.nurbsObjects{2,1}.nurbs,'topoSetName','outer');
            elseif ~(app.nurbsObjects{2,1}.nurbs{1}.d_p == 2)
                printOutputMessage(app,'Nonsensical input')
            end
            app.nurbsObjects{4,1}.nurbs = app.nurbsObjects{2,1}.nurbs;
            app.S2Vobj = surfaceToVolume(app.nurbsObjects{4,1}.nurbs,getS2VOptions(app));
            app.history(1).S2Vobj = app.S2Vobj;
            app.noSurfPatches = numel(app.nurbsObjects{2,1}.nurbs);
            app.noBdryPatches = app.noSurfPatches;
            app.iteration = 0;
            p_max = findMaxDegree(app.nurbsObjects{2,1}.nurbs);
            app.EditUniformDegree.Value = max(p_max);
            delete(app.Tree.CheckedNodes)
            delete(app.Tree.SelectedNodes)
            clearTree(app,1:4,1:2)
            app.InfoObjSelection.Value = 'S2V surface';
        end

        function resetS2Vobj(app)
            app.nurbsObjects{4,1}.nurbs = app.nurbsObjects{2,1}.nurbs;
            app.nurbsObjects{3,1}.nurbs = [];
            app.nurbsObjects{3,2}.nurbs = [];
            app.RefinedoutputvolumeCheckBox.Value = false;
            app.S2Vobj = surfaceToVolume(app.nurbsObjects{4,1}.nurbs,getS2VOptions(app));
        end

        function updatePrivateVariables(app)

            app.sharpAngle = app.AnglethresholdEditField.Value*pi/180;
            app.avg_v_n_threshholdAngle = app.AngledeviationEditField.Value*pi/180;
            
            app.options_default = getOptions(app);
        end

        function updateObjectVisibilityFromPanel(app)
            app.objectVisibility = [app.InputobjectCheckBox.Value, app.RefinedinputobjectCheckBox.Value;
                                    app.InputsurfaceCheckBox.Value, app.RefinedinputsurfaceCheckBox.Value;
                                    app.OutputvolumeCheckBox.Value, app.RefinedoutputvolumeCheckBox.Value;
                                    app.S2VsurfaceCheckBox.Value, false];
            if any(app.objectVisibility(:,1))
                i_p = find(app.objectVisibility(:,1));
                if i_p < 4
                    app.RefineObjSelection.Value = app.RefineObjSelection.Items{i_p};
                end
            end
        end

        function updateNURBSinfo(app)
            [~,i] = ismember(app.InfoObjSelection.Value,app.InfoObjSelection.Items);
            app.SelectpatchSpinner.Value = min([app.SelectpatchSpinner.Value, numel(app.nurbsObjects{i}.nurbs)]);
            patch = app.SelectpatchSpinner.Value;
            if isempty(app.nurbsObjects{i}.nurbs)
                return
            end
            noPatches = numel(app.nurbsObjects{i}.nurbs);
            if noPatches == 1
                app.SelectpatchSpinner.Limits = [0,noPatches];
                patch = 1;
                app.SelectpatchSpinner.Value = patch;
            else
                app.SelectpatchSpinner.Limits = [1,noPatches];
            end
            
            s = sprintf('Info summary for object: %s\n', app.InfoObjSelection.Items{i});
            s = sprintf('%sNumber of patches in object: %d\n', s, noPatches);
            totNoElements = 0;
            for patch = 1:noPatches
                noElems = 1;
                d_p = app.nurbsObjects{i}.nurbs{patch}.d_p;
                for j = 1:d_p
                    noElems = noElems*(numel(unique(app.nurbsObjects{i}.nurbs{patch}.knots{j}))-1);
                end
                totNoElements = totNoElements + noElems;
            end
            s = sprintf('%sTotal number of elements in object: %d\n', s, totNoElements);
                

            if ~isempty(app.selectedObjects) && i == 4
                s = sprintf('%s\n\nData for selected NURBS surfaces: %d\n', s);
                for patch = app.selectedObjects
                    s = printPatchInfo(app,s,i,patch);
                end
            else
                s = printPatchInfo(app,s,i,patch);
            end
            app.nurbsInfo.Value = s;
        end

        function s = printPatchInfo(app,s,i,patch)
            isRational = ~all(app.nurbsObjects{i}.nurbs{patch}.coeffs(end,:) == 1);
            if isRational
                rationalStr = 'True';
            else
                rationalStr = 'False';
            end
            d_p = app.nurbsObjects{i}.nurbs{patch}.d_p;
            s = sprintf('%s\nInfo for patch = %d:\n', s, patch);
            s = sprintf('%s\tNumber of parametric directions, d_p = %d\n', s, d_p);
            s = sprintf('%s\tNumber of physical dimensions, d = %d\n', s, app.nurbsObjects{i}.nurbs{patch}.d);
            s = sprintf('%s\tTotal number of basis functions: %d\n', s, prod(app.nurbsObjects{i}.nurbs{patch}.number));
            s = sprintf(['%s\tNumber of basis functions: (' repmat('%d, ',1,d_p-1), '%d)\n'], s, app.nurbsObjects{i}.nurbs{patch}.number);
            s = sprintf(['%s\tDegrees: (' repmat('%d, ',1,d_p-1), '%d)\n'], s, app.nurbsObjects{i}.nurbs{patch}.degree);
            s = sprintf('%s\tRational: %s\n', s, rationalStr);
        end

        function iterateS2V(app)
            tic
            app.S2VerrorOccured = false;
            printOutputMessage(app,sprintf(['%-' num2str(app.stringShift) 's'], 'Creating new volumetric patch ... '))
            S2Vobj_copy = app.S2Vobj; % Copy object in order not to change the object in case of errors
            S2Vobj_copy.selectedObjects = app.selectedObjects;
            try
                app.S2Vobj = S2Vobj_copy.iterate(getS2VOptions(app));
            catch ME
                printOutputMessage(app,sprintf('Could not iterate the S2V algorithm due to the following error: %s', ME.message))
                app.S2VerrorOccured = true;
                return
            end
            app.nurbsObjects{3,1}.nurbs = app.S2Vobj.nurbs_vol(1:app.S2Vobj.S2Vcounter);
            printOutputMessage(app,sprintf('using %12f seconds.', toc))
            if app.S2Vobj.S2Vcompleted
                if app.EnforcesymmetryaboutthexyplaneCheckBox.Value
                    nurbs_mirrored = mirrorNURBS(app.nurbsObjects{3,1}.nurbs,'z');
                    nurbs_mirrored = permuteNURBS(nurbs_mirrored,[2,1,3]); % Ensure right-handedness
                    app.nurbsObjects{3,1}.nurbs = [app.nurbsObjects{3,1}.nurbs, nurbs_mirrored];
                end
                if app.EnforcesymmetryaboutthexzplaneCheckBox.Value
                    nurbs_mirrored = mirrorNURBS(app.nurbsObjects{3,1}.nurbs,'y');
                    nurbs_mirrored = permuteNURBS(nurbs_mirrored,[2,1,3]); % Ensure right-handedness
                    app.nurbsObjects{3,1}.nurbs = [app.nurbsObjects{3,1}.nurbs, nurbs_mirrored];
                end
                if app.EnforcesymmetryabouttheyzplaneCheckBox.Value
                    nurbs_mirrored = mirrorNURBS(app.nurbsObjects{3,1}.nurbs,'x');
                    nurbs_mirrored = permuteNURBS(nurbs_mirrored,[2,1,3]); % Ensure right-handedness
                    app.nurbsObjects{3,1}.nurbs = [app.nurbsObjects{3,1}.nurbs, nurbs_mirrored];
                end
            end


            app.nurbsObjects{4,1}.nurbs = app.S2Vobj.nurbs_bdry;
            iterateCounters(app,+1)
            app.noBdryPatches(app.iteration+1) = numel(app.S2Vobj.nurbs_bdry);
            if numel(app.history) > app.iteration+1
                app.history(app.iteration+2:end) = [];
            end
            app.history(app.iteration+1).S2Vobj = app.S2Vobj;
            options = getOptions(app);
            options.i_p_arr = 3:4;
            options.j_p_arr = 1;
            options.indices = {app.S2Vobj.S2Vcounter, app.S2Vobj.newBdryPatches};
            cleanUpPlots(app,3,1,min(app.S2Vobj.newBdryPatches):numel(app.plotHandles{3,1}.objectHandle))
            cleanUpPlots(app,4,1,min(app.S2Vobj.newBdryPatches):numel(app.plotHandles{4,1}.objectHandle))
            plot_nurbs(app,options)

            if ~isempty(app.S2Vobj.patchesRefined) % Replot refined patches
                options.replot = 1;
                options.indices = {unique(app.S2Vobj.surf2volMap(app.S2Vobj.patchesRefined)), app.S2Vobj.patchesRefined};
                plot_nurbs(app,options)
            end
            ClearselectionButtonPushed(app,false)
        end


        function reiterateS2V(app)
            if app.iteration > 0
                app.selectedObjects = app.history(app.iteration+1).S2Vobj.selectedObjects;
                iterateCounters(app,-1)
                app.S2Vobj = app.history(app.iteration+1).S2Vobj;
                app.S2VsurfaceCheckBox.Value = true;
                iterateS2V(app)
                if app.S2VerrorOccured % Regret
                    iterateCounters(app,+1)
                    app.S2Vobj = app.history(app.iteration+1).S2Vobj;
                end
            end
        end

        function makeNextCaseBold(app)
            % Not completed
            app.CaseS1Label.FontWeight = 'normal';
            app.CaseS13Label.FontWeight = 'normal';
            app.CaseS135Label.FontWeight = 'normal';
            app.CaseS134Label.FontWeight = 'normal';
            app.CaseS1234Label.FontWeight = 'normal';
            app.CaseS1345Label.FontWeight = 'normal';
            app.CaseS13456Label.FontWeight = 'normal';
            app.CaseS123456DropDownLabel.FontWeight = 'normal';
%             switch app.S2Vobj.maxNoSharpAngles
%                 case 0
%                     app.CaseS1Label.FontWeight = 'bold';
%                 case 1
%                     app.CaseS1Label.FontWeight = 'bold';
%                 case 2
%                     app.CaseS1Label.FontWeight = 'bold';
%                 case 3
%                     app.CaseS1Label.FontWeight = 'bold';
%                 case 4
%                     app.CaseS1Label.FontWeight = 'bold';
%                 case 5
%                     app.CaseS1Label.FontWeight = 'bold';
%             end
        end

        function printOutputMessage(app,s)
            if strcmp(s(1:5),'using') || (numel(app.OutputMessages.Value) == 1 && isempty(app.OutputMessages.Value{1}))
                app.OutputMessages.Value{end} = [app.OutputMessages.Value{end}, s];
            else
                app.OutputMessages.Value{end+1} = s;
            end
            scroll(app.OutputMessages, 'bottom')
        end

        function updateTree(app,i_p_arr,j_p_arr,indices)
            if nargin < 2
                i_p_arr = 1:size(app.plotHandles,1);
            end
            if nargin < 3
                j_p_arr = 1:size(app.plotHandles,2);
            end
            CheckedNodes = gobjects(1000,1);
            counter = 1;
            for j_p = j_p_arr
                for i_p = i_p_arr
                    if nargin < 4
                        indices = 1:numel(app.plotHandles{i_p,j_p}.objectHandle);
                    end
                    i_obj = (j_p-1)*size(app.plotHandles,1) + i_p;
                    for patch = indices
                        if ~isempty(app.plotHandles{i_p,j_p}.objectHandle) ...
                                && ~isa(app.plotHandles{i_p,j_p}.objectHandle(patch),'matlab.graphics.GraphicsPlaceholder') ...
                                && isvalid(app.plotHandles{i_p,j_p}.objectHandle(patch))
                            %% Create nodes
                            if isempty(app.Tree.Children(i_obj).Children) || patch > numel(app.Tree.Children(i_obj).Children)
                                patchNode = uitreenode(app.Tree.Children(i_obj));
                            else
                                patchNode = app.Tree.Children(i_obj).Children(patch);
                            end
                            patchNode.Text = app.plotHandles{i_p,j_p}.objectHandle(patch).DisplayName;

                            if isempty(patchNode.Children) 
                                objectNode = uitreenode(patchNode, 'Text', 'Object');
                            else
                                objectNode = patchNode.Children(1);
                            end
                            if numel(app.plotHandles{i_p,j_p}.objectHandle) >= patch
                                objectNode.NodeData = app.plotHandles{i_p,j_p}.objectHandle(patch);  
                                obj = app.plotHandles{i_p,j_p}.objectHandle(patch);    
                                if objIsValid(app,obj) && obj.Visible
                                    CheckedNodes(counter) = objectNode;
                                    counter = counter + 1;
                                end
                            end

                            if numel(patchNode.Children) < 2
                                elementEdgesNode = uitreenode(patchNode, 'Text', 'Element edges');
                            else
                                elementEdgesNode = patchNode.Children(2);
                            end
                            if numel(app.plotHandles{i_p,j_p}.elementEdgesHandle) >= patch
                                elementEdgesNode.NodeData = app.plotHandles{i_p,j_p}.elementEdgesHandle(patch);
                                obj = app.plotHandles{i_p,j_p}.elementEdgesHandle(patch);    
                                if objIsValid(app,obj) && obj.Visible
                                    CheckedNodes(counter) = elementEdgesNode;
                                    counter = counter + 1;
                                end
                            end

                            if numel(patchNode.Children) < 3
                                controlPolygonNode = uitreenode(patchNode, 'Text', 'Control polygon');
                            else
                                controlPolygonNode = patchNode.Children(3);
                            end
                            if numel(app.plotHandles{i_p,j_p}.controlPolygonHandle) >= patch
                                controlPolygonNode.NodeData = app.plotHandles{i_p,j_p}.controlPolygonHandle(patch);  
                                obj = app.plotHandles{i_p,j_p}.controlPolygonHandle(patch);                            
                                if objIsValid(app,obj) && obj.Visible
                                    CheckedNodes(counter) = controlPolygonNode;
                                    counter = counter + 1;
                                end
                            end
                            
                            if numel(patchNode.Children) < 4
                                parmDirNode = uitreenode(patchNode, 'Text', 'Parametric directions');
                            else
                                parmDirNode = patchNode.Children(4);
                            end

                            if isempty(parmDirNode.Children)
                                parmDirNode_dir1 = uitreenode(parmDirNode, 'Text', 'dir = 1');
                            else
                                parmDirNode_dir1 = parmDirNode.Children(1);
                            end
                            if size(app.plotHandles{i_p,j_p}.parmDirHandle,2) >= patch
                                parmDirNode_dir1.NodeData = app.plotHandles{i_p,j_p}.parmDirHandle(1,patch);  
                                obj = app.plotHandles{i_p,j_p}.parmDirHandle(patch);    
                                if objIsValid(app,obj) && obj.Visible
                                    CheckedNodes(counter) = parmDirNode_dir1;
                                    counter = counter + 1;
                                end
                            end
                            
                            if app.nurbsObjects{i_p,j_p}.nurbs{patch}.d_p > 1
                                if numel(parmDirNode.Children) < 2
                                    parmDirNode_dir2 = uitreenode(parmDirNode, 'Text', 'dir = 2');
                                else
                                    parmDirNode_dir2 = parmDirNode.Children(2);
                                end
                                if size(app.plotHandles{i_p,j_p}.parmDirHandle,2) >= patch
                                    parmDirNode_dir2.NodeData = app.plotHandles{i_p,j_p}.parmDirHandle(2,patch);  
                                    obj = app.plotHandles{i_p,j_p}.parmDirHandle(2,patch);  
                                    if objIsValid(app,obj) && obj.Visible
                                        CheckedNodes(counter) = parmDirNode_dir2;
                                        counter = counter + 1;
                                    end
                                end
                            end

                            if app.nurbsObjects{i_p,j_p}.nurbs{patch}.d_p > 2
                                if numel(parmDirNode.Children) < 3
                                    parmDirNode_dir3 = uitreenode(parmDirNode, 'Text', 'dir = 3');
                                else
                                    parmDirNode_dir3 = parmDirNode.Children(3);
                                end
                                if size(app.plotHandles{i_p,j_p}.parmDirHandle,2) >= patch
                                    parmDirNode_dir3.NodeData = app.plotHandles{i_p,j_p}.parmDirHandle(3,patch);  
                                    obj = app.plotHandles{i_p,j_p}.parmDirHandle(3,patch);  
                                    if objIsValid(app,obj) && obj.Visible
                                        CheckedNodes(counter) = parmDirNode_dir3;
                                        counter = counter + 1;
                                    end
                                end
                            end

                            if numel(patchNode.Children) < 5
                                normalVectorsNode = uitreenode(patchNode, 'Text', 'Normal vectors');
                            else
                                normalVectorsNode = patchNode.Children(5);
                            end
                            if numel(app.plotHandles{i_p,j_p}.normalVectorsHandle) >= patch
                                normalVectorsNode.NodeData = app.plotHandles{i_p,j_p}.normalVectorsHandle(patch);  
                                obj = app.plotHandles{i_p,j_p}.normalVectorsHandle(patch);    
                                if objIsValid(app,obj) && obj.Visible
                                    CheckedNodes(counter) = normalVectorsNode;
                                    counter = counter + 1;
                                end
                            end
                        end
                    end
                end
            end
            app.Tree.CheckedNodes = [app.Tree.CheckedNodes; CheckedNodes(1:counter-1)];
            expand(app.Tree.Children(4))
        end

        function deactivateRightSelection(app)
            if objIsValid(app,app.lastSelectedObject)
                patch = app.lastSelectedObject.UserData.patch;
                i_p = app.lastSelectedObject.UserData.i_p;
                j_p = app.lastSelectedObject.UserData.j_p;
                if strcmp(app.plotHandles{i_p,j_p}.objectHandle(patch).Visible,'on')
                    app.plotHandles{i_p,j_p}.objectHandle(patch).FaceColor = getS2VpatchColor(app,patch,i_p);
                end
            end
        end
    end

    
    methods (Access = public)
        
        function S2Vmouse_click(app,~,eventData)
            patch = eventData.Source.UserData.patch;
            deactivateRightSelection(app)

            i_p = eventData.Source.UserData.i_p;
            j_p = eventData.Source.UserData.j_p;
            switch eventData.Button
                case 1 % Left click
                    if ismember(patch,app.selectedObjects)
                        printOutputMessage(app,sprintf('Patch %d unselected.', patch))
                        app.selectedObjects(app.selectedObjects == patch) = [];
                        color = getS2VpatchColor(app,patch,i_p);
                        app.plotHandles{i_p,j_p}.objectHandle(patch).FaceColor = color;
                    else
                        printOutputMessage(app,sprintf('Patch %d selected.', patch))
                        app.selectedObjects = [app.selectedObjects, patch];
                        app.plotHandles{i_p,j_p}.objectHandle(patch).FaceColor = app.leftClickSelectionColor;
                    end
                    app.InfoObjSelection.Value = 'S2V surface';
                    updateNURBSinfo(app) % First update app.SelectpatchSpinner limits
                    app.SelectpatchSpinner.Value = patch;
                    updateNURBSinfo(app) % Then update the info box

                    %% Update the NURBS tab
                    app.lastSelectedObject = eventData.Source;

                    nurbs = app.nurbsObjects{i_p,j_p}.nurbs{patch};
                    number = nurbs.number;
                    n1_arr = 1:number(1);
                    n2_arr = 1:number(2);
                    switch nurbs.d_p
                        case 2
                            [N1,N2] = ndgrid(n1_arr,n2_arr);
                            app.UITable.Data = [N1(:),N2(:),ones(size(N2(:))),nurbs.coeffs(:,:).'];
                            app.UITable.ColumnEditable = logical([0,0,0,1,1,1,1]);
                        case 3
                            n3_arr = 1:number(3);
                            [N1,N2,N3] = ndgrid(n1_arr,n2_arr,n3_arr);
                            app.UITable.Data = [N1(:),N2(:),N3(:),nurbs.coeffs(:,:).'];
                            app.UITable.ColumnEditable = false(1,6);
                    end
                    app.UITable.ColumnFormat = {'long e','long e','long e','long','long','long','long'};
                case 3 % Right click
                    printOutputMessage(app,sprintf('Patch %d selected.', patch))
                    app.plotHandles{i_p,j_p}.objectHandle(patch).FaceColor = app.rightClickSelectionColor;
                    app.lastSelectedObject = eventData.Source;
                    xy = app.Surface2volumealgorithmUIFigure.CurrentPoint;
                    drawnow
                    open(app.ContextMenu,xy(1),xy(2))
                    for i = 1:numel(app.ContextMenu.Children)
                        tag = app.ContextMenu.Children(i).Tag;
                        app.ContextMenu.Children(i).Checked = false;
                        if strcmp(tag,'parmDirHandle')
                            if objIsValid(app,app.plotHandles{i_p,j_p}.(tag)(1,:),patch)
                                app.ContextMenu.Children(i).Checked = app.plotHandles{i_p,j_p}.(tag)(1,patch).Visible;
                            end
                        else
                            if objIsValid(app,app.plotHandles{i_p,j_p}.(tag),patch)
                                app.ContextMenu.Children(i).Checked = app.plotHandles{i_p,j_p}.(tag)(patch).Visible;
                            end
                        end
                    end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set default values
            drawnow limitrate % Speed up background simulations
            app.Eps = app.EpsEditField.Value;
            app.leftClickSelectionColor = [0.8,0,0];
            app.rightClickSelectionColor = [0,0.8,0];
            app.selectedPointColor = [0,0.9,0];
            app.stringShift = 40;
            app.no_colors = 30;
            ax = app.UIAxes;
            app.colors = getColorMap('shsv',ax,app.no_colors);
            app.plotColor = getColor();
            app.idleAutomation = true;
            app.iteration = 0;
            updatePrivateVariables(app)
            app.plotHandles = cell(4,2);
            app.nurbsObjects = cell(4,2);
            for i_p = 1:size(app.nurbsObjects,1)
                for j_p = 1:size(app.nurbsObjects,2)
                    app.nurbsObjects{i_p,j_p}.nurbs = [];
                end
            end
            allocatePlotHandle(app)
            app.selectedObjects = [];
            axis(ax,'equal')
            app.S2VerrorOccured = false;

            updateObjectVisibilityFromPanel(app)

            % Set tool tip
            app.coarseLinearSamplingCheckBox.Tooltip = 'Evaluate every element only on the boundary in linear parametric directions';
            app.ShowobjectCheckBox.Tooltip = 'Visualize the patches of the NURBS object';
            app.ShownormalvectorsCheckBox.Tooltip = 'Visualize the normal vectors on the surface of the NURBS object';
            app.ShowparametricdirectionsCheckBox.Tooltip = 'Visualize the parametric directions at the  surface of the NURBS object';
            app.ShowelementedgesCheckBox.Tooltip = 'Visualize the elements of the NURBS object (shows also the interior elements of a NURBS patch)';
            app.ShowcontrolpolygonCheckBox.Tooltip = 'Visualize the full control polygon for the NURBS object';
            app.PlottingresolutionEditField.Tooltip = 'Edit the plotting resolution for the NURBS object. This number is scaled with the ratio between number of C0-elements and the total number of elements';
            app.PlottingresolutionEditFieldLabel.Tooltip = app.PlottingresolutionEditField.Tooltip;
            app.CamlightCheckBox.Tooltip = 'Turn on or off the camlight';
            app.ThicknessEditField.Tooltip = 'Specify the thickness of the patch away from a single surface patch';
            app.AnglethresholdEditField.Tooltip = 'Set the threshold angle between two patches for which the algorithm will try to form a patch between these surface patches';
            app.Play.Tooltip = 'Run/pause automated surface to volume patch generator';
            app.Loadg2fileButton.Tooltip = 'Open a .g2-file from your file system. For linux users: The app window might move down some layers';
            app.ModelDropDown.Tooltip = 'Select a predefined model that loads a NURBS geometry and a predefined set of parameters for the surface2volume algorithm';
            app.PopoutButton.Tooltip = 'Pop out figure to a separate window to enable plot browser/inspector (i.e. "View" -> "Plot Browser" is very convenient in order to hide/edit objects)';
            app.ReadyLamp.Tooltip = 'The lamp turns red when program is busy';
            app.QuiverstepEditField.Tooltip = 'Step over evaluation points when plotting any normal vectors/parametric directions. Setting quiver step = 1 will plot vectors at every evaluation points.';
            app.QuiverstepEditFieldLabel.Tooltip = app.QuiverstepEditField.Tooltip;
            app.QuiverscaleEditField.Tooltip = 'Scale the length of the quiver vectors used for normal vectors/parametric directions.';
            app.QuiverscaleEditFieldLabel.Tooltip = app.QuiverscaleEditField.Tooltip;
            app.AutoscaleCheckBox.Tooltip = 'Scale the length of the quiver vectors automatically using MATLAB built in algorithm.';
            app.AlphavalueEditField.Tooltip = 'Alter the transparancy of the object by changing the alpha value';
            app.AlphavalueSlider.Tooltip = app.AlphavalueEditField.Tooltip;
            app.AlphavalueSliderLabel.Tooltip = app.AlphavalueEditField.Tooltip;
            app.ReadyLampLabel.Tooltip = 'Status of app';
            app.Seth_maxEditField.Tooltip = 'Set the maximal (up to rounding) element edge length for all elements throughout the mesh for the refinement procedure';
            app.Seth_maxEditFieldLabel.Tooltip = app.Seth_maxEditField.Tooltip;
            app.RefineButton.Tooltip = 'Refine the volumetric mesh resulting from the S2V algorithm';
            app.ExportObjSelection.Tooltip = 'Select NURBS to export';
            app.ExportButton.Tooltip = 'Export selected NURBS to a file in your file system.';
            app.ColorfunctionEditField.Tooltip = 'Write a MATLAB function handle for coloring the NURBS object. This is convenient to show the geometric error, i.e., the exactness of the sphere representation using NURBS, which is the default expression.';
            app.ColoringDropDown.Tooltip = 'Choose coloring when plotting NURBS objects';

            app.ModelDropDown.Value = 'S1';
%             app.ModelDropDown.Value = 'Bridge';
            ModelDropDownValueChanged(app)


        end

        % Menu selected function: LoadstateMenu
        function LoadstateMenuSelected(app, event)
            switchLamp(app,'Loading state ...')
            [filename, pathname] = uigetfile('*.mat', 'Load a .mat state file','S2V/savedStates/');
            if filename == -1
                printOutputMessage(app,sprintf('Could not load state from file %s', filename))
                switchLamp(app)   
                return
            elseif filename == 0
                switchLamp(app)   
                return
            end
            cleanUpPlots(app)
            allocatePlotHandle(app)

            load([pathname, filename],'app_copy');
            for fieldName = fieldnames(app_copy).'
                app.(fieldName{1}) = app_copy.(fieldName{1});
            end

            options = getOptions(app);
            plot_nurbs(app,options)
            switchLamp(app)
        end

        % Menu selected function: SavestateMenu
        function SavestateMenuSelected(app, event)
            switchLamp(app,'Exporting file ...')
            [filename, pathname] = uiputfile('*.mat', 'Save a .mat state file','S2V/savedStates/');
            if filename == -1
                printOutputMessage(app,sprintf('Could not save state to file %s', filename))
                switchLamp(app)   
                return
            elseif filename == 0
                switchLamp(app)   
                return
            end
            app_copy = struct();
            for fieldName = properties('S2V').'
                type = class(app.(fieldName{1}));
                if ~(numel(type) >= 6 && strcmp(type(1:6),'matlab')) % Save not supported for matlab.apps.AppBase objects. 
                    app_copy.(fieldName{1}) = app.(fieldName{1});
                end
            end
            save([pathname, filename],'app_copy');
            switchLamp(app)
        end

        % Menu selected function: READMEMenu
        function READMEMenuSelected(app, event)
            S.fh = figure('units','pixels',...
                          'position',[100 100 720 720],...
                          'menubar','none',...
                          'resize','off',...
                          'numbertitle','off',...
                          'name','README');

            S.tx = uicontrol('style','edit',...
                             'Units','pix',...
                             'position',[10 10 700 700],...
                             'backgroundcolor','w',...
                             'HorizontalAlign','left',...
                             'min',0,'max',10,...
                             'FontSize',12, ...
                             'FontName','Helvetica',...
                             'enable','inactive');

            set(S.tx,'string',{['The Surface2volume (S2V) app simplifies mesh generation for surface to volume meshing for isogeometric analysis. ' ...
                'The algorithm is semi-automatic for more complex geometries as some cases must be handled manually. ' ...
                'The input is usually a surface at which volumetric patches will be attached. For interior problems (defined by the orienation of the input NURBS object), ' ...
                'the goal is to fill a closed surface mesh with volumetric patches. For exterior problems the goal is to attach volumetric patches to the input NURBS object ' ...
                'such that it is completely covered and the outermost surface of the generated volumetric patch forms a convex surface.'], ...
                [],'Hover the buttons to get tooltip for that functionality.'})
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            view(app.UIAxes,getView());
            model = app.ModelDropDown.Value;
            if strcmp(model,'<Select model>')
                switchLamp(app,'Cleaning up ...')
                cleanUpPlots(app)
                switchLamp(app)
                return
            else
                switchLamp(app,'Loading model ...')
            end
            app = getS2Vmodel(app,model);
            updatePrivateVariables(app)
            processNURBSinput(app)
            resetS2Vobj(app) 
            switchLamp(app,'Cleaning up previous plots and plotting new model ...')
            refreshPlots(app)
            switchLamp(app)
        end

        % Button pushed function: Loadg2fileButton
        function Loadg2fileButtonPushed(app, event)
            switchLamp(app,'Opening file ...')
            [filename, pathname] = uigetfile('*.g2', 'Open a .g2 file','NURBSgeometries/g2files/');
            if filename == -1
                printOutputMessage(app,sprintf('Could not open file %s', filename))
                switchLamp(app)   
                return
            elseif filename == 0
                switchLamp(app)   
                return
            end
            app.nurbsObjects{1,1}.nurbs = read_g2([pathname, filename]);
            processNURBSinput(app)
            resetS2Vobj(app)
            switchLamp(app,'Cleaning up previous plots and plotting new model ...')
            refreshPlots(app)
            view(app.UIAxes,getView());
            app.ModelDropDown.Value = '<Select model>';
            switchLamp(app)
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
            switchLamp(app,'Cleaning up plots and reloading the model ...')
            ClearselectionButtonPushed(app,false)
            cleanUpPlots(app,3)
            cleanUpPlots(app,4,1,numel(app.nurbsObjects{2,1}.nurbs)+1:numel(app.nurbsObjects{4,1}.nurbs))
            allocatePlotHandle(app)
            resetS2Vobj(app)
            app.history = [];
            app.iteration = 0;
            switchLamp(app)
        end

        % Button pushed function: BackwardEnd
        function BackwardEndButtonPushed(app, event)
            switchLamp(app,'Iterating back to the begining  ...')
            if app.iteration > 0
                ClearselectionButtonPushed(app,false)
                toggleObjects(app,false,3,1:2,1:app.iteration+1)
                toggleObjects(app,false,4,1:2,app.S2Vobj.noOriginalBdryPatches+1:app.S2Vobj.noBdryPatches)
                app.S2Vobj.angles = app.history(1).S2Vobj.angles; % Must be done in order to get the coloring correct
                toggleObjects(app,true,4,1:2,app.history(1).S2Vobj.nurbs_covered)
                iterateCounters(app,-app.iteration)
                resetS2Vobj(app)
            end
            switchLamp(app)
        end

        % Button pushed function: Backward
        function BackwardButtonPushed(app, event)
            switchLamp(app,'Iterating backwards ...')
            if app.iteration > 0
                ClearselectionButtonPushed(app,false)
                toggleObjects(app,false,3,1:2,app.iteration+1)
                toggleObjects(app,false,4,1:2,app.S2Vobj.newBdryPatches)
                app.S2Vobj.angles = app.history(app.iteration).S2Vobj.angles; % Must be done in order to get the coloring correct
                toggleObjects(app,true,4,1:2,app.S2Vobj.nurbs_covered)
                iterateCounters(app,-1)
                if app.iteration == 0
                    resetS2Vobj(app)
                else
                    app.S2Vobj = app.history(app.iteration+1).S2Vobj;
                end
            end
            switchLamp(app)
        end

        % Button pushed function: Play
        function PlayButtonPushed(app, event)
            switchLamp(app,'Running S2V algorithm ...')
            app.idleAutomation = ~app.idleAutomation;

            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Play.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'pause.svg');
            if isempty(app.nurbsObjects{4,1}.nurbs)
                app.idleAutomation = true;
                app.Play.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'play.svg');
                switchLamp(app)
                return
            else
                app.S2VsurfaceCheckBox.Value = true;
                t_start = tic;
                
                while ~app.S2Vobj.S2Vcompleted && ~app.S2Vobj.selfIntersection && ~app.idleAutomation && ~app.S2VerrorOccured
                    iterateS2V(app)
                end
                if app.S2Vobj.S2Vcompleted
                    printOutputMessage(app,sprintf(['%-' num2str(app.stringShift) 's'], 'Completed S2V algorithm ... '))
                    printOutputMessage(app,sprintf('using %12f seconds.', toc(t_start)))
                end
            end
            updateNURBSinfo(app)
            app.idleAutomation = true;
            app.Play.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'play.svg');
            switchLamp(app)
        end

        % Button pushed function: PlayNext
        function PlayNextButtonPushed(app, event)
            switchLamp(app,'Applying one iteration of the S2V algorithm ...')
            if ~app.OutputvolumeCheckBox.Value
                app.S2VsurfaceCheckBox.Value = true;
            end
            if ~app.S2Vobj.S2Vcompleted && ~app.S2Vobj.selfIntersection 
                iterateS2V(app)
            end
            switchLamp(app)
        end

        % Button pushed function: Forward
        function ForwardButtonPushed(app, event)
            switchLamp(app,'Iterating forwards ...')
            if app.iteration < numel(app.history)
                ClearselectionButtonPushed(app,false)
                iterateCounters(app,+1)
                app.S2Vobj = app.history(app.iteration+1).S2Vobj;
                app.nurbsObjects{4,1}.nurbs = app.S2Vobj.nurbs_bdry;
                toggleObjects(app,true,3:4,1:2,app.S2Vobj.newBdryPatches)
            end
            switchLamp(app)
        end

        % Button pushed function: ForwardEnd
        function ForwardEndButtonPushed(app, event)
            switchLamp(app,'Iterating forward to the current state  ...')
            if app.iteration < numel(app.history)
                ClearselectionButtonPushed(app,false)
                app.S2Vobj = app.history(end).S2Vobj;
                app.nurbsObjects{4,1}.nurbs = app.S2Vobj.nurbs_bdry;
                iterateCounters(app,numel(app.history)-app.iteration)
                toggleObjects(app,true,3:4,1:2)
            end
            switchLamp(app)
        end

        % Value changed function: AnglethresholdEditField
        function AnglethresholdEditFieldValueChanged(app, event)
            app.sharpAngle = app.AnglethresholdEditField.Value*pi/180;
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AngledeviationEditField
        function AngledeviationEditFieldValueChanged(app, event)
            app.avg_v_n_threshholdAngle = app.AngledeviationEditField.Value*pi/180;
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: EpsEditField
        function EpsEditFieldValueChanged(app, event)
            app.Eps = app.EpsEditField.Value;
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AlgorithmForCase1
        function AlgorithmForCase1ValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AlgorithmForCase13
        function AlgorithmForCase13ValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AlgorithmForCase135
        function AlgorithmForCase135ValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AlgorithmForCase134
        function AlgorithmForCase134ValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AlgorithmForCase1234
        function AlgorithmForCase1234ValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AlgorithmForCase1345
        function AlgorithmForCase1345ValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AlgorithmForCase13456
        function AlgorithmForCase13456ValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AlgorithmForCase123456
        function AlgorithmForCase123456ValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...') 
            reiterateS2V(app)
            switchLamp(app)
        end

        % Button pushed function: CollapseselectedsurfaceButton
        function CollapseselectedsurfaceButtonPushed(app, event)
            if isempty(app.selectedObjects)
                printOutputMessage(app,sprintf('You must select a patch to collapse.'))
                return
            end
            if app.iteration == 0
                printOutputMessage(app,sprintf('Not possible to collapse patch without changing original surface.'))
                return
            end
            if numel(app.selectedObjects) > 1
                printOutputMessage(app,sprintf('Only a single patch can be collapsed.'))
                return
            end
            slaveSelectedObject = NaN(1,4);
            for i = 1:4
                if ~isempty(app.S2Vobj.topologyMap{app.selectedObjects}(i).slave)
                    slaveSelectedObject(i) = app.S2Vobj.topologyMap{app.selectedObjects}(i).slave;
                end
            end
            if all(slaveSelectedObject <= app.S2Vobj.noOriginalBdryPatches) || app.selectedObjects <= app.S2Vobj.noOriginalBdryPatches
                printOutputMessage(app,sprintf('Not possible to collapse patch without changing original surface.'))
            else
                surroundingPatches = NaN(1,4);
                for midx = 1:4
                    if ~isempty(app.S2Vobj.topologyMap{app.selectedObjects}(midx).slave)
                        surroundingPatches(midx) = app.S2Vobj.topologyMap{app.selectedObjects}(midx).slave;
                    end
                end
                surroundingPatches(isnan(surroundingPatches)) = [];

                patch_vol = app.S2Vobj.surf2volMap(app.selectedObjects,1);
                midx = app.S2Vobj.surf2volMap(app.selectedObjects,2);

                % Find the face master index midx_midx to collaps onto
                if all(or(ismember(slaveSelectedObject(1:2),app.S2Vobj.newBdryPatches), isnan(slaveSelectedObject(1:2))))
                    if ismember(slaveSelectedObject(3),app.S2Vobj.newBdryPatches)
                        if mod(midx,2)
                            midx_midx = 3;
                        else % Direction has been flipped in the subNURBS routine
                            midx_midx = 4;
                        end
                    else
                        if mod(midx,2)
                            midx_midx = 4;
                        else % Direction has been flipped in the subNURBS routine
                            midx_midx = 3;
                        end
                    end
                elseif all(or(ismember(slaveSelectedObject(3:4),app.S2Vobj.newBdryPatches), isnan(slaveSelectedObject(3:4))))
                    if ismember(slaveSelectedObject(1),app.S2Vobj.newBdryPatches)
                        if mod(midx,2)
                            midx_midx = 1;
                        else % Direction has been flipped in the subNURBS routine
                            midx_midx = 2;
                        end
                    else
                        if mod(midx,2)
                            midx_midx = 2;
                        else % Direction has been flipped in the subNURBS routine
                            midx_midx = 1;
                        end
                    end
                else
                    printOutputMessage(app,sprintf('Could not find edge to collaps onto.'))
                    return
                end

                nurbs_vol_patch = collapseToEdge(app.S2Vobj.nurbs_vol{patch_vol},midx,midx_midx);

                patches_surf = app.S2Vobj.vol2surfMap(patch_vol,:);
                nurbs_bdries = subNURBS(nurbs_vol_patch,'outwardPointingNormals',true);
                for i = 1:6
                    if ~isnan(patches_surf(i))
                        app.S2Vobj.nurbs_bdry{patches_surf(i)}.coeffs = nurbs_bdries{i}.coeffs;
                        app.nurbsObjects{4,1}.nurbs{patches_surf(i)}.coeffs = nurbs_bdries{i}.coeffs;
                    end
                end

                % Update app.S2Vobj.topologyMap
                geometry = getTopology(app.S2Vobj.nurbs_bdry(surroundingPatches));
                connectionSub = geometry.topology.connection;

                for i = 1:numel(connectionSub)
                    patch_i = surroundingPatches(connectionSub{i}.Attributes.master);
                    slave_i = surroundingPatches(connectionSub{i}.Attributes.slave);
                    midx_i = connectionSub{i}.Attributes.midx;
                    sidx_i = connectionSub{i}.Attributes.sidx;
                    orient = connectionSub{i}.Attributes.orient;
            
                    app.S2Vobj.topologyMap{patch_i}(midx_i).slave = slave_i;
                    app.S2Vobj.topologyMap{patch_i}(midx_i).sidx = sidx_i;
                    app.S2Vobj.topologyMap{patch_i}(midx_i).orient = orient;
                end
                for i = 1:numel(surroundingPatches)
                    for midx = 1:4
                        if app.S2Vobj.topologyMap{surroundingPatches(i)}(midx).slave == app.selectedObjects
                            app.S2Vobj.topologyMap{surroundingPatches(i)}(midx).slave = [];
                            app.S2Vobj.topologyMap{surroundingPatches(i)}(midx).sidx = [];
                            app.S2Vobj.topologyMap{surroundingPatches(i)}(midx).orient = [];
                        end
                    end
                end
                app.S2Vobj.removePatch(app.selectedObjects);
                app.nurbsObjects{4,1}.nurbs = app.S2Vobj.nurbs_bdry;

                app.S2Vobj = app.S2Vobj.computeCornerData(surroundingPatches);

                app.S2Vobj.nurbs_vol{patch_vol} = nurbs_vol_patch;
                app.nurbsObjects{3,1}.nurbs{patch_vol} = nurbs_vol_patch;

                app.history(app.iteration+1).S2Vobj = app.S2Vobj; % Update the history

                options = getOptions(app);
                options.i_p_arr = 3:4;
                options.j_p_arr = 1;
                options.replot = true;
                slaveSelectedObject(isnan(slaveSelectedObject)) = [];
                options.indices = {patch_vol,[slaveSelectedObject, app.selectedObjects]};
                plot_nurbs(app,options)
            end
        end

        % Value changed function: EditUniformDegree
        function EditUniformDegreeValueChanged(app, event)
            value = app.EditUniformDegree.Value;
            p_max = findMaxDegree(nurbs);
            if value < p_max
                printOutputMessage(app,'Cannot set polynomial degree less then maximal polynomial degree of the object.')
                app.EditUniformDegree.Value = p_max;
            end
        end

        % Button pushed function: RefineButton
        function RefineButtonPushed(app, event)
            switchLamp(app,'Refining volumetric NURBS ...')
            [~,i_p] = ismember(app.RefineObjSelection.Value,app.RefineObjSelection.Items);

            if isempty(app.nurbsObjects{i_p,1}.nurbs)
                printOutputMessage(app,'Could not find any volumetric NURBS. Did you run the S2V algorithm?')
                switchLamp(app)
                return
            end
            if isfield(app.nurbsObjects{i_p,1},'topologyMap')
                topologyMap = app.nurbsObjects{i_p,1}.topologyMap;
            else
                geometry = getTopology(app.nurbsObjects{i_p,1}.nurbs);
                connection = geometry.topology.connection;
                noPatches = numel(app.nurbsObjects{i_p,1}.nurbs);
                d_p = app.nurbsObjects{i_p,1}.nurbs{1}.d_p;
                topologyMap = createTopologyMap(connection,noPatches,d_p);
                app.nurbsObjects{i_p,1}.topologyMap = topologyMap;
            end
            if ~isempty(app.nurbsObjects{i_p,2}.nurbs)
                cleanUpPlots(app,i_p,2)
                allocatePlotHandle(app,i_p,2)
            end
            if app.UseUniformDegree.Value
                app.nurbsObjects{i_p,2}.nurbs = makeUniformNURBSDegree(app.nurbsObjects{i_p,1}.nurbs,app.EditUniformDegree.Value);
            else
                app.nurbsObjects{i_p,2}.nurbs = app.nurbsObjects{i_p,1}.nurbs;
            end
            app.nurbsObjects{i_p,2}.nurbs = autoRefineNURBS(app.nurbsObjects{i_p,2}.nurbs,'topologyMap',topologyMap,'h_max',app.Seth_maxEditField.Value);
            switch i_p
                case 1
                    app.RefinedinputobjectCheckBox.Value = true;
                    app.InputobjectCheckBox.Value = false;
                case 2
                    app.RefinedinputsurfaceCheckBox.Value = true;
                    app.InputsurfaceCheckBox.Value = false;
                case 3
                    app.RefinedoutputvolumeCheckBox.Value = true;
                    app.OutputvolumeCheckBox.Value = false;
            end
            updateObjectVisibilityFromPanel(app)
            options = getOptions(app);
            options.i_p_arr = i_p;
            options.j_p_arr = 2;
            plot_nurbs(app,options)
            toggleObjects(app)
            updateNURBSinfo(app)
            switchLamp(app)
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            switchLamp(app,'Exporting file ...')
            [filename, pathname] = uiputfile('*.g2;*.mat', 'Export to a .g2/.mat file','NURBSgeometries/g2files/');
            if filename == -1
                printOutputMessage(app,sprintf('Could not save to file %s', filename))
                switchLamp(app)   
                return
            elseif filename == 0
                switchLamp(app)   
                return
            end
            [~,i] = ismember(app.ExportObjSelection.Value,app.ExportObjSelection.Items);
            if endsWith(filename,'.g2')
                write_g2(app.nurbsObjects{i}.nurbs, [pathname, filename]);
            elseif endsWith(filename,'.mat')
                nurbs = app.nurbsObjects{i}.nurbs;
                save(filename,'nurbs')
            else
                printOutputMessage(app,sprintf('Could not save to file %s: File format not supported', filename))
            end

            switchLamp(app)  
        end

        % Value changed function: InfoObjSelection
        function InfoObjSelectionValueChanged(app, event)
            updateNURBSinfo(app)
        end

        % Value changed function: SelectpatchSpinner
        function SelectpatchSpinnerValueChanged(app, event)
            updateNURBSinfo(app)
            patch = app.SelectpatchSpinner.Value;
            if ~ismember(patch,app.selectedObjects)
                printOutputMessage(app,sprintf('Patch %d selected.', patch))
                app.selectedObjects = [app.selectedObjects, patch];
                app.plotHandles{4,1}.objectHandle(patch).FaceColor = app.leftClickSelectionColor;
            end
        end

        % Button pushed function: ClearselectionButton
        function ClearselectionButtonPushed(app, event)
            if isempty(app.selectedObjects)
                return
            end
            if isa(event,'matlab.ui.eventdata.ButtonPushedData')
                switchLamp(app,'Unselecting patches ...')
                alterLampText = true;
            else
                alterLampText = false;
            end
            app.prevSelectedObjects = app.selectedObjects;
            app.selectedObjects = [];
            for patch = app.prevSelectedObjects
                color = getS2VpatchColor(app,patch,4);
                app.plotHandles{4,1}.objectHandle(patch).FaceColor = color;
            end
            app.InfoObjSelection.Value = 'S2V surface';
            updateNURBSinfo(app)
            app.SelectpatchSpinner.Value = 1;
            updateNURBSinfo(app)
            if alterLampText
                switchLamp(app)
            end
        end

        % Button pushed function: PopoutButton
        function PopoutButtonPushed(app, event)
            switchLamp(app,'Copying figure onto new figure ...')
            f2 = figure('units','normalized','outerposition',[0 0 1 1]);
            copyobj(app.UIAxes,f2);
            f2.CurrentAxes.Position = [0, 0, 1920, 1080];
            switchLamp(app)
        end

        % Callback function
        function OutputMessagesValueChanged(app, event)
            
        end

        % Value changed function: ShowobjectCheckBox
        function ShowobjectCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling visualization of object ...')
            options = getOptions(app);
            plot_nurbs(app,options)
            toggleObjects(app)
            switchLamp(app) 
        end

        % Value changed function: ShowcontrolpolygonCheckBox
        function ShowcontrolpolygonCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling visualization of control polygon ...')
            options = getOptions(app);
            plot_nurbs(app,options)
            toggleObjects(app)
            switchLamp(app)
        end

        % Value changed function: ShowelementedgesCheckBox
        function ShowelementedgesCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling visualization of elements ...')
            options = getOptions(app);
            plot_nurbs(app,options)
            toggleObjects(app)
            switchLamp(app)
        end

        % Value changed function: ShowparametricdirectionsCheckBox
        function ShowparametricdirectionsCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling visualization of parametric directions ...')
            options = getOptions(app);
            plot_nurbs(app,options)
            toggleObjects(app)
            switchLamp(app)  
        end

        % Value changed function: ShownormalvectorsCheckBox
        function ShownormalvectorsCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling visualization of normal vectors ...')
            options = getOptions(app);
            plot_nurbs(app,options)
            toggleObjects(app)
            switchLamp(app)  
        end

        % Value changed function: CamlightCheckBox
        function CamlightCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling camlight ...')
            value = app.CamlightCheckBox.Value;
            if value
                [AZ,~] = view(app.UIAxes);
                if -90 < AZ && AZ < 90
                    app.camlightHandle = camlight(app.UIAxes,'right');
                else
                    app.camlightHandle = camlight(app.UIAxes,'left');
                end
            else
                if isvalid(app.camlightHandle)
                    app.camlightHandle.Visible = 'off';
                end
            end
            switchLamp(app)
        end

        % Value changed function: coarseLinearSamplingCheckBox
        function coarseLinearSamplingCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling coarse linear sampling of object ...')
            cleanUpPlots(app)
            allocatePlotHandle(app)
            options = getOptions(app);
            options.replot = true;
            plot_nurbs(app,options)
            switchLamp(app)
        end

        % Value changed function: showAxis
        function showAxisValueChanged(app, event)
            ax = app.UIAxes;
            if app.showAxis.Value
                axis(ax,'on')
            else
                axis(ax,'off')
            end
        end

        % Value changed function: useSingleColorForInput
        function useSingleColorForInputValueChanged(app, event)
            toggleObjects(app)
        end

        % Value changed function: showAvgNormals
        function showAvgNormalsValueChanged(app, event)
            switchLamp(app,'Toggling average normal vectors ...')
            options = getOptions(app);
            options.replot = true;
            plot_nurbs(app,options)
            toggleObjects(app)
            switchLamp(app)
        end

        % Value changed function: PlottingresolutionEditField
        function PlottingresolutionEditFieldValueChanged(app, event)
            switchLamp(app,'Altering plotting resolution ...')
            cleanUpPlots(app)
            allocatePlotHandle(app)
            options = getOptions(app);
            app.PlottingresolutionEditField.Value = max(round(app.PlottingresolutionEditField.Value),0);
            options.resolution = app.PlottingresolutionEditField.Value*ones(1,3);
            options.replot = true;
            plot_nurbs(app,options)
            switchLamp(app)
        end

        % Value changed function: QuiverscaleEditField
        function QuiverscaleEditFieldValueChanged(app, event)
            switchLamp(app,'Editing quiver scale ...')
            cleanUpPlots(app)
            options = getOptions(app);
            options.replot = true;
            plot_nurbs(app,options)
            switchLamp(app)
        end

        % Value changed function: AutoscaleCheckBox
        function AutoscaleCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling autoscale for quiver plots ...')
            cleanUpPlots(app)
            allocatePlotHandle(app)
            options = getOptions(app);
            options.replot = true;
            plot_nurbs(app,options)
            switchLamp(app)
        end

        % Value changed function: QuiverstepEditField
        function QuiverstepEditFieldValueChanged(app, event)
            switchLamp(app,'Editing quiver step ...')
            cleanUpPlots(app)
            allocatePlotHandle(app)
            options = getOptions(app);
            options.replot = true;
            plot_nurbs(app,options)
            switchLamp(app)
        end

        % Value changed function: AlphavalueSlider
        function AlphavalueSliderValueChanged(app, event)
            switchLamp(app,'Changing alpha value ...')
            app.AlphavalueEditField.Value = event.Value;
            toggleObjects(app)
            switchLamp(app)
        end

        % Value changed function: AlphavalueEditField
        function AlphavalueEditFieldValueChanged(app, event)
            switchLamp(app,'Changing alpha value ...')
            app.AlphavalueSlider.Value = app.AlphavalueEditField.Value;
            toggleObjects(app)
            switchLamp(app)
        end

        % Value changed function: ColoringDropDown
        function ColoringDropDownValueChanged(app, event)
            switchLamp(app,'Altering coloring ...')
            switch app.ColoringDropDown.Value
                case 'None'
                    colorbar(app.UIAxes, 'off')
                    app.coarseLinearSamplingCheckBox.Value = true; % No reason to use redundant amount of evaluation points
                    toggleObjects(app)
                otherwise
                    app.coarseLinearSamplingCheckBox.Value = false;
                    ColorfunctionEditFieldValueChanged(app, event);
            end
            switchLamp(app)
        end

        % Value changed function: ColorfunctionEditField
        function ColorfunctionEditFieldValueChanged(app, event)
            switchLamp(app,'Changing color function...')
            if ~strcmp(app.ColoringDropDown.Value,'None')
                cleanUpPlots(app)
                allocatePlotHandle(app)
                options = getOptions(app);
                options.replot = true;
                plot_nurbs(app,options)
                colormap(app.UIAxes, 'parula');
                colorbar(app.UIAxes)
            end
            switchLamp(app)
        end

        % Value changed function: AZEditField
        function AZEditFieldValueChanged(app, event)
            [AZ,~] = view(app.UIAxes);
            view(app.UIAxes,[AZ, app.AZEditField.Value])
        end

        % Value changed function: ELEditField
        function ELEditFieldValueChanged(app, event)
            [~,EL] = view(app.UIAxes);
            view(app.UIAxes,[app.ELEditField.Value, EL])
        end

        % Value changed function: InputobjectCheckBox
        function InputobjectCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling input object visibility ...')
            updateObjectVisibilityFromPanel(app)
            plotIfNotPresent(app,app.InputobjectCheckBox.Value,1,1)
            switchLamp(app)
        end

        % Value changed function: RefinedinputobjectCheckBox
        function RefinedinputobjectCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling refined input object visibility ...')
            updateObjectVisibilityFromPanel(app)
            plotIfNotPresent(app,app.RefinedinputobjectCheckBox.Value,1,2)
            switchLamp(app)
        end

        % Value changed function: InputsurfaceCheckBox
        function InputsurfaceCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling input surface visibility ...')
            updateObjectVisibilityFromPanel(app)
            plotIfNotPresent(app,app.InputsurfaceCheckBox.Value,2,1)
            switchLamp(app)
        end

        % Value changed function: RefinedinputsurfaceCheckBox
        function RefinedinputsurfaceCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling refined input surface visibility ...')
            updateObjectVisibilityFromPanel(app)
            plotIfNotPresent(app,app.RefinedinputsurfaceCheckBox.Value,2,2)
            switchLamp(app)
        end

        % Value changed function: OutputvolumeCheckBox
        function OutputvolumeCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling input surface visibility ...')
            updateObjectVisibilityFromPanel(app)
            plotIfNotPresent(app,app.OutputvolumeCheckBox.Value,3,1)
            switchLamp(app)
        end

        % Value changed function: RefinedoutputvolumeCheckBox
        function RefinedoutputvolumeCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling refined output volume visibility ...')
            updateObjectVisibilityFromPanel(app)
            plotIfNotPresent(app,app.RefinedoutputvolumeCheckBox.Value,3,2)
            switchLamp(app)
        end

        % Value changed function: S2VsurfaceCheckBox
        function S2VsurfaceCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling S2V surface visibility ...')
            updateObjectVisibilityFromPanel(app)
            plotIfNotPresent(app,app.S2VsurfaceCheckBox.Value,4,1)
            switchLamp(app)
        end

        % Button pushed function: RefreshtreeButton
        function RefreshtreeButtonPushed(app, event)
            switchLamp(app,'Updating plot browser tree...')
            drawnow
            updateTree(app)
            switchLamp(app)
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            switchLamp(app,'Updating plot browser tree...')
            newCheckedNodes = setdiff(event.CheckedNodes,event.PreviousCheckedNodes);
            for i = 1:numel(newCheckedNodes)
                if isempty(newCheckedNodes(i).Children)
                    if newCheckedNodes(i).Parent == app.Tree
                        switch newCheckedNodes(i).Text
                            case 'Input object'
                                app.InputobjectCheckBox.Value = true;
                                InputobjectCheckBoxValueChanged(app)
                            case 'Input surface'
                                app.InputsurfaceCheckBox.Value = true;
                                InputsurfaceCheckBoxValueChanged(app)
                            case 'Output volume'
                                app.OutputvolumeCheckBox.Value = true;
                                OutputvolumeCheckBoxValueChanged(app)
                            case 'S2V surface'
                                app.S2VsurfaceCheckBox.Value = true;
                                S2VsurfaceCheckBoxValueChanged(app)
                        end
                        updateTree(app)
                    else
                        if isa(newCheckedNodes(i).NodeData,'matlab.graphics.GraphicsPlaceholder')
                            options = getOptions(app);
                            switch newCheckedNodes(i).Text
                                case 'Object'
                                    options.plotObject = true;
                                case 'Element edges'
                                    options.plotElementEdges = true;
                                case 'Control polygon'
                                    options.plotControlPolygon = true;
                                case {'Parametric directions', 'dir = 1', 'dir = 2', 'dir = 3'}
                                    options.plotParmDir = true;
                                case 'Normal vectors'
                                    options.plotNormalVectors = true;
                            end
        
                            plot_nurbs(app,options)
                            updateTree(app)
                            if ~strcmp(newCheckedNodes(i).Text,'Parametric directions')
                                newCheckedNodes(i).NodeData.Visible = true;
                            end
                        else
                            newCheckedNodes(i).NodeData.Visible = true;
                        end
                    end
                end
            end
            newUnCheckeddNodes = setdiff(event.PreviousCheckedNodes,event.CheckedNodes);
            for i = 1:numel(newUnCheckeddNodes)
                if isempty(newUnCheckeddNodes(i).Children) && ~isa(newUnCheckeddNodes(i).NodeData,'matlab.graphics.GraphicsPlaceholder')
                    newUnCheckeddNodes(i).NodeData.Visible = false;
                end
            end
            switchLamp(app)
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
        end

        % Value changed function: ThicknessEditField
        function ThicknessEditFieldValueChanged(app, event)
            reiterateS2V(app)
            switchLamp(app)
        end

        % Button pushed function: ConvexifyoutersurfaceButton
        function ConvexifyoutersurfaceButtonPushed(app, event)
            switchLamp(app,'Convexifying outer surface ...')
            if isfield(app.nurbsObjects{3,1},'geometry')
                geometry = app.nurbsObjects{3,1}.geometry;
            else
                geometry = getTopology(app.nurbsObjects{3,1}.nurbs);
                app.nurbsObjects{3,1}.geometry = geometry;
            end
            idx = findSet(geometry.topologysets.set,'outer');
            if isnan(idx)
                printOutputMessage(app,'Could not find free outer surface. Note that convexify only make sense for exterior problems')
            end
            topologysets.set = geometry.topologysets.set(idx);

            [outerSurface,surf2volMap] = extractFreeSurface(app.nurbsObjects{3,1}.nurbs,'topologysets',topologysets);
            outerSurface = convexifyNURBS(outerSurface);
            for i = 1:size(surf2volMap,1)
                patch = surf2volMap(i,1);
                midx = surf2volMap(i,2);
                switch midx
                    case 1
                        app.nurbsObjects{3,1}.nurbs{patch}.coeffs(:,1,:,:) = outerSurface{i}.coeffs;
                    case 2
                        app.nurbsObjects{3,1}.nurbs{patch}.coeffs(:,end,:,:) = outerSurface{i}.coeffs;
                    case 3
                        app.nurbsObjects{3,1}.nurbs{patch}.coeffs(:,:,1,:) = outerSurface{i}.coeffs;
                    case 4
                        app.nurbsObjects{3,1}.nurbs{patch}.coeffs(:,:,end,:) = outerSurface{i}.coeffs;
                    case 5
                        app.nurbsObjects{3,1}.nurbs{patch}.coeffs(:,:,:,1) = outerSurface{i}.coeffs;
                    case 6
                        app.nurbsObjects{3,1}.nurbs{patch}.coeffs(:,:,:,end) = outerSurface{i}.coeffs;
                end
            end
            app.nurbsObjects{3,1}.nurbs = cleanNURBS(app.nurbsObjects{3,1}.nurbs,[],app.Eps);
            options = getOptions(app);
            options.i_p_arr = 3;
            options.j_p_arr = 1;
            options.replot = true;
            plot_nurbs(app,options)
            switchLamp(app)
        end

        % Value changed function: MaintaincollapsednessCheckBox
        function MaintaincollapsednessCheckBoxValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: UseaveragenormalvectorsCheckBox
        function UseaveragenormalvectorsCheckBoxValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Button pushed function: reiterate
        function reiterateButtonPushed(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: EnforcesymmetryaboutthexyplaneCheckBox
        function EnforcesymmetryaboutthexyplaneCheckBoxValueChanged(app, event)
            switchLamp(app,'Reseting S2V object ...')
            cleanUpPlots(app)
            resetS2Vobj(app)
            options = getOptions(app);
            options.i_p_arr = 4;
            options.j_p_arr = 1;
            options.replot = true;
            plot_nurbs(app,options)
            switchLamp(app)
        end

        % Value changed function: EnforcesymmetryaboutthexzplaneCheckBox
        function EnforcesymmetryaboutthexzplaneCheckBoxValueChanged(app, event)
            EnforcesymmetryaboutthexyplaneCheckBoxValueChanged(app)
        end

        % Value changed function: EnforcesymmetryabouttheyzplaneCheckBox
        function EnforcesymmetryabouttheyzplaneCheckBoxValueChanged(app, event)
            EnforcesymmetryaboutthexyplaneCheckBoxValueChanged(app)
        end

        % Key press function: Surface2volumealgorithmUIFigure
        function Surface2volumealgorithmUIFigureKeyPress(app, event)
            key = event.Key;
%             app.UIAxes.Toolbar.Visible = 'off';
            
            switch key
                case 'r'
                    if strcmp(app.rotateHandle.Enable,'off')
                        app.rotateHandle.Enable = 'on';
                    else
                        app.rotateHandle.Enable = 'off';
                    end
            end
            figure(app.Surface2volumealgorithmUIFigure)
        end

        % Key release function: Surface2volumealgorithmUIFigure
        function Surface2volumealgorithmUIFigureKeyRelease(app, event)
%             key = event.Key;
% %             app.UIAxes.Toolbar.Visible = 'off';
            
%             switch key
%                 case 'r'
%                     app.rotateHandle.Enable = 'off';
%             end
%             figure(app.Surface2volumealgorithmUIFigure)
        end

        % Button down function: CenterPanel
        function CenterPanelButtonDown(app, event)
            deactivateRightSelection(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            if app.iteration == 0 || app.lastSelectedObject.UserData.patch <= app.S2Vobj.noOriginalBdryPatches
                printOutputMessage(app,sprintf('Not possible to edit patch without changing original surface.'))
                return
            end
            indices = event.Indices;
            newData = event.NewData;
            if isempty(app.lastSelectedObject.UserData.patch)
                printOutputMessage(app,sprintf('Left click a patch to edit the control points.'))
                return
            end
            patch = app.lastSelectedObject.UserData.patch;
            if all(isnan(app.S2Vobj.angles(patch,:)))
                printOutputMessage(app,sprintf('Patch is not editable.'))
                return
            end


            patch_vol = app.S2Vobj.surf2volMap(patch,1);
            midx = app.S2Vobj.surf2volMap(patch,2);
            nurbs_vol_patch = app.S2Vobj.nurbs_vol{patch_vol};
            coeffs = nurbs_vol_patch.coeffs;
            number = nurbs_vol_patch.number;
            switch midx
                case 1
                    i1 = 1;
                    i2 = number(2) - mod(indices(1)-1,number(2)); % flip the "first" parametric direction on the surface due to subNURBS usage
                    i3 = ceil(indices(1)/number(2));
                    idx = i1 + (i2-1)*number(1) + (i3-1)*number(1)*number(2);
                    coeffs(indices(2)-3,idx) = newData;
                case 2
                    i1 = number(1);
                    i2 = mod(indices(1)-1,number(2))+1;
                    i3 = ceil(indices(1)/number(2));
                    idx = i1 + (i2-1)*number(1) + (i3-1)*number(1)*number(2);
                    coeffs(indices(2)-3,idx) = newData;
            end
            nurbs_vol_patch.coeffs = coeffs;


            nurbs_vol_patch = GordonHall(subNURBS(nurbs_vol_patch)); % Recreate interior control points with the GordonHall algorithm
            app.S2Vobj.nurbs_vol(patch_vol) = nurbs_vol_patch;
            app.nurbsObjects{3,1}.nurbs(patch_vol) = nurbs_vol_patch;
            

            patches_surf = app.S2Vobj.vol2surfMap(patch_vol,:);
            nurbs_bdries = subNURBS(nurbs_vol_patch{1},'outwardPointingNormals',true);
            for i = 1:6
                if ~isnan(patches_surf(i))
                    app.S2Vobj.nurbs_bdry{patches_surf(i)}.coeffs = nurbs_bdries{i}.coeffs;
                    app.nurbsObjects{4,1}.nurbs{patches_surf(i)}.coeffs = nurbs_bdries{i}.coeffs;
                end
            end

            options = getOptions(app);
            options.i_p_arr = 3:4;
            options.j_p_arr = 1;
            options.replot = true;
            patches_surf(isnan(patches_surf)) = [];
            options.indices = {patch_vol,patches_surf};
            plot_nurbs(app,options)
            UITableCellSelection(app, event)
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if app.objIsValid(app.SelectedPointHandle)
                delete(app.SelectedPointHandle)
            end
            if isempty(indices) 
                if app.objIsValid(app.SelectedPointHandle)
                    delete(app.SelectedPointHandle)
                end
                return
            end
            i_p = app.lastSelectedObject.UserData.i_p;
            j_p = app.lastSelectedObject.UserData.j_p;
            patch = app.lastSelectedObject.UserData.patch;
    
            X = app.nurbsObjects{i_p,j_p}.nurbs{patch}.coeffs(1:3,indices(1));
            app.SelectedPointHandle = line(app.UIAxes,X(1,:),X(2,:),X(3,:),'linestyle','none','Marker','o','MarkerFaceColor', app.selectedPointColor, ...
                                            'MarkerEdgeColor', app.selectedPointColor);
        end

        % Menu selected function: ObjectMenu
        function ObjectMenuSelected(app, event)
            patch = app.lastSelectedObject.UserData.patch;
            i_p = app.lastSelectedObject.UserData.i_p;
            j_p = app.lastSelectedObject.UserData.j_p;
            app.plotHandles{i_p,j_p}.objectHandle(patch).Visible = ~app.plotHandles{i_p,j_p}.objectHandle(patch).Visible;
            deactivateRightSelection(app)
        end

        % Menu selected function: ElementedgesMenu
        function ElementedgesMenuSelected(app, event)
            patch = app.lastSelectedObject.UserData.patch;
            i_p = app.lastSelectedObject.UserData.i_p;
            j_p = app.lastSelectedObject.UserData.j_p;
            if objIsValid(app,app.plotHandles{i_p,j_p}.elementEdgesHandle,patch)
                app.plotHandles{i_p,j_p}.elementEdgesHandle(patch).Visible = ~app.plotHandles{i_p,j_p}.elementEdgesHandle(patch).Visible;
            else
                options = getOptions(app);
                options.plotElementEdges = true;
                options.i_p_arr = i_p;
                options.j_p_arr = j_p;
                options.indices = {patch, patch};
                plot_nurbs(app,options)
                app.plotHandles{i_p,j_p}.elementEdgesHandle(patch).Visible = true;
                switchLamp(app)
            end
            deactivateRightSelection(app)
        end

        % Menu selected function: ControlpolygonMenu
        function ControlpolygonMenuSelected(app, event)
            patch = app.lastSelectedObject.UserData.patch;
            i_p = app.lastSelectedObject.UserData.i_p;
            j_p = app.lastSelectedObject.UserData.j_p;
            if objIsValid(app,app.plotHandles{i_p,j_p}.controlPolygonHandle,patch)
                app.plotHandles{i_p,j_p}.controlPolygonHandle(patch).Visible = ~app.plotHandles{i_p,j_p}.controlPolygonHandle(patch).Visible;
            else
                options = getOptions(app);
                options.plotControlPolygon = true;
                options.i_p_arr = i_p;
                options.j_p_arr = j_p;
                options.indices = {patch, patch};
                plot_nurbs(app,options)
                app.plotHandles{i_p,j_p}.controlPolygonHandle(patch).Visible = true;
                switchLamp(app)
            end
            deactivateRightSelection(app)
        end

        % Menu selected function: ParametricdirectionsMenu
        function ParametricdirectionsMenuSelected(app, event)
            patch = app.lastSelectedObject.UserData.patch;
            i_p = app.lastSelectedObject.UserData.i_p;
            j_p = app.lastSelectedObject.UserData.j_p;
            if objIsValid(app,app.plotHandles{i_p,j_p}.parmDirHandle(1,:),patch)
                for i = 1:size(app.plotHandles{i_p,j_p}.parmDirHandle,1)
                    alterVisibility(app,app.plotHandles{i_p,j_p}.parmDirHandle(i,:), patch)
                end
            else
                options = getOptions(app);
                options.plotParmDir = true;
                options.i_p_arr = i_p;
                options.j_p_arr = j_p;
                options.indices = {patch, patch};
                plot_nurbs(app,options)
                for i = 1:size(app.plotHandles{i_p,j_p}.parmDirHandle,1)
                    alterVisibility(app,app.plotHandles{i_p,j_p}.parmDirHandle(i,:), patch, true)
                end
                switchLamp(app)
            end
            deactivateRightSelection(app)
        end

        % Menu selected function: NormalvectorsMenu
        function NormalvectorsMenuSelected(app, event)
            patch = app.lastSelectedObject.UserData.patch;
            i_p = app.lastSelectedObject.UserData.i_p;
            j_p = app.lastSelectedObject.UserData.j_p;
            if objIsValid(app,app.plotHandles{i_p,j_p}.normalVectorsHandle,patch)
                app.plotHandles{i_p,j_p}.normalVectorsHandle(patch).Visible = ~app.plotHandles{i_p,j_p}.normalVectorsHandle(patch).Visible;
            else
                options = getOptions(app);
                options.plotNormalVectors = true;
                options.i_p_arr = i_p;
                options.j_p_arr = j_p;
                options.indices = {patch, patch};
                plot_nurbs(app,options)
                app.plotHandles{i_p,j_p}.normalVectorsHandle(patch).Visible = true;
                switchLamp(app)
            end
            deactivateRightSelection(app)
        end

        % Value changed function: noExtraLayersEditField
        function noExtraLayersEditFieldValueChanged(app, event)
            app.S2Vobj.options.noExtraLayers = app.noExtraLayersEditField.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Surface2volumealgorithmUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {900, 900, 900};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {900, 900};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {311, '1x', 312};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Surface2volumealgorithmUIFigure = uifigure('Visible', 'off');
            app.Surface2volumealgorithmUIFigure.Name = 'Surface2volume algorithm';
            app.Surface2volumealgorithmUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Surface2volumealgorithmUIFigure.KeyPressFcn = createCallbackFcn(app, @Surface2volumealgorithmUIFigureKeyPress, true);
            app.Surface2volumealgorithmUIFigure.KeyReleaseFcn = createCallbackFcn(app, @Surface2volumealgorithmUIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.Surface2volumealgorithmUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadstateMenu = uimenu(app.FileMenu);
            app.LoadstateMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadstateMenuSelected, true);
            app.LoadstateMenu.Text = 'Load state ...';
            app.SavestateMenu = uimenu(app.FileMenu);
            app.SavestateMenu.MenuSelectedFcn = createCallbackFcn(app, @SavestateMenuSelected, true);
            app.SavestateMenu.Text = 'Save state ...';
            app.HelpMenu = uimenu(app.Surface2volumealgorithmUIFigure);
            app.HelpMenu.Text = 'Help';
            app.READMEMenu = uimenu(app.HelpMenu);
            app.READMEMenu.MenuSelectedFcn = createCallbackFcn(app, @READMEMenuSelected, true);
            app.READMEMenu.Text = 'README';
            app.GridLayout = uigridlayout(app.Surface2volumealgorithmUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModelDropDownLabel = uilabel(app.LeftPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.LeftPanel);
            app.ModelDropDown.Items = {'<Select model>', 'S1', 'Cube', 'Barrel', 'randomCubicHole', 'BeTSSiM1', 'BeTSSiM2', 'BeTSSiM3', 'BeTSSiM4', 'BeTSSiM4_parm2', 'S1_interior', 'Mutter', 'Pawn', 'BCA', 'Bridge'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = '<Select model>';
            app.ORLabel = uilabel(app.LeftPanel);
            app.ORLabel.Text = 'OR';
            app.Loadg2fileButton = uibutton(app.LeftPanel, 'push');
            app.Loadg2fileButton.ButtonPushedFcn = createCallbackFcn(app, @Loadg2fileButtonPushed, true);
            app.Loadg2fileButton.Text = 'Load g2-file';
            app.reset = uibutton(app.LeftPanel, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'stop.svg');
            app.reset.Text = '';
            app.BackwardEnd = uibutton(app.LeftPanel, 'push');
            app.BackwardEnd.ButtonPushedFcn = createCallbackFcn(app, @BackwardEndButtonPushed, true);
            app.BackwardEnd.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'backwardEnd.svg');
            app.BackwardEnd.Text = '';
            app.Backward = uibutton(app.LeftPanel, 'push');
            app.Backward.ButtonPushedFcn = createCallbackFcn(app, @BackwardButtonPushed, true);
            app.Backward.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'backward.svg');
            app.Backward.Text = '';
            app.Play = uibutton(app.LeftPanel, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.Play.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'play.svg');
            app.Play.Text = '';
            app.PlayNext = uibutton(app.LeftPanel, 'push');
            app.PlayNext.ButtonPushedFcn = createCallbackFcn(app, @PlayNextButtonPushed, true);
            app.PlayNext.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'playNext.svg');
            app.PlayNext.Text = '';
            app.Forward = uibutton(app.LeftPanel, 'push');
            app.Forward.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.Forward.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'forward.svg');
            app.Forward.Text = '';
            app.ForwardEnd = uibutton(app.LeftPanel, 'push');
            app.ForwardEnd.ButtonPushedFcn = createCallbackFcn(app, @ForwardEndButtonPushed, true);
            app.ForwardEnd.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'forwardEnd.svg');
            app.ForwardEnd.Text = '';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.S2VTab = uitab(app.TabGroup);
            app.S2VTab.Title = 'S2V';
            app.TabGroup3 = uitabgroup(app.S2VTab);
            app.AdjustTab = uitab(app.TabGroup3);
            app.AdjustTab.Title = 'Adjust';
            app.CollapseselectedsurfaceButton = uibutton(app.AdjustTab, 'push');
            app.CollapseselectedsurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @CollapseselectedsurfaceButtonPushed, true);
            app.CollapseselectedsurfaceButton.Text = 'Collapse selected surface';
            app.ConvexifyoutersurfaceButton = uibutton(app.AdjustTab, 'push');
            app.ConvexifyoutersurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @ConvexifyoutersurfaceButtonPushed, true);
            app.ConvexifyoutersurfaceButton.Text = 'Convexify outer surface';
            app.DomainTab = uitab(app.TabGroup3);
            app.DomainTab.Title = 'Domain';
            app.EnforcesymmetryaboutthexyplaneCheckBox = uicheckbox(app.DomainTab);
            app.EnforcesymmetryaboutthexyplaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnforcesymmetryaboutthexyplaneCheckBoxValueChanged, true);
            app.EnforcesymmetryaboutthexyplaneCheckBox.Text = 'Enforce symmetry about the xy-plane';
            app.EnforcesymmetryaboutthexzplaneCheckBox = uicheckbox(app.DomainTab);
            app.EnforcesymmetryaboutthexzplaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnforcesymmetryaboutthexzplaneCheckBoxValueChanged, true);
            app.EnforcesymmetryaboutthexzplaneCheckBox.Text = 'Enforce symmetry about the xz-plane';
            app.EnforcesymmetryabouttheyzplaneCheckBox = uicheckbox(app.DomainTab);
            app.EnforcesymmetryabouttheyzplaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnforcesymmetryabouttheyzplaneCheckBoxValueChanged, true);
            app.EnforcesymmetryabouttheyzplaneCheckBox.Text = 'Enforce symmetry about the yz-plane';
            app.DomainlimitsPanel = uipanel(app.DomainTab);
            app.DomainlimitsPanel.Title = 'Domain limits';
            app.xminEditFieldLabel = uilabel(app.DomainlimitsPanel);
            app.xminEditFieldLabel.Text = 'xmin';
            app.xminEditField = uieditfield(app.DomainlimitsPanel, 'numeric');
            app.xminEditField.Value = -Inf;
            app.xmaxEditFieldLabel = uilabel(app.DomainlimitsPanel);
            app.xmaxEditFieldLabel.Text = 'xmax';
            app.xmaxEditField = uieditfield(app.DomainlimitsPanel, 'numeric');
            app.xmaxEditField.Value = Inf;
            app.ymaxEditFieldLabel = uilabel(app.DomainlimitsPanel);
            app.ymaxEditFieldLabel.Text = 'ymax';
            app.ymaxEditField = uieditfield(app.DomainlimitsPanel, 'numeric');
            app.ymaxEditField.Value = Inf;
            app.yminEditFieldLabel = uilabel(app.DomainlimitsPanel);
            app.yminEditFieldLabel.Text = 'ymin';
            app.yminEditField = uieditfield(app.DomainlimitsPanel, 'numeric');
            app.yminEditField.Value = -Inf;
            app.zmaxEditFieldLabel = uilabel(app.DomainlimitsPanel);
            app.zmaxEditFieldLabel.Text = 'zmax';
            app.zmaxEditField = uieditfield(app.DomainlimitsPanel, 'numeric');
            app.zmaxEditField.Value = Inf;
            app.zminEditFieldLabel = uilabel(app.DomainlimitsPanel);
            app.zminEditFieldLabel.Text = 'zmin';
            app.zminEditField = uieditfield(app.DomainlimitsPanel, 'numeric');
            app.zminEditField.Value = -Inf;
            app.AlgorithmsTab = uitab(app.TabGroup3);
            app.AlgorithmsTab.Title = 'Algorithms';
            app.CaseS1Label = uilabel(app.AlgorithmsTab);
            app.CaseS1Label.Text = 'Case S = {1}';
            app.AlgorithmForCase1 = uidropdown(app.AlgorithmsTab);
            app.AlgorithmForCase1.Items = {'A1_21', 'A1_31'};
            app.AlgorithmForCase1.ValueChangedFcn = createCallbackFcn(app, @AlgorithmForCase1ValueChanged, true);
            app.AlgorithmForCase1.Value = 'A1_21';
            app.CaseS13Label = uilabel(app.AlgorithmsTab);
            app.CaseS13Label.Text = 'Case S = {1,3}';
            app.AlgorithmForCase13 = uidropdown(app.AlgorithmsTab);
            app.AlgorithmForCase13.Items = {'A13_11', 'A13_21', 'A13_31', 'A13_33', 'A13_41'};
            app.AlgorithmForCase13.ValueChangedFcn = createCallbackFcn(app, @AlgorithmForCase13ValueChanged, true);
            app.AlgorithmForCase13.Value = 'A13_21';
            app.CaseS135Label = uilabel(app.AlgorithmsTab);
            app.CaseS135Label.Text = 'Case S = {1,3,5}';
            app.AlgorithmForCase135 = uidropdown(app.AlgorithmsTab);
            app.AlgorithmForCase135.Items = {'A135_11', 'A135_21', 'A135_31', 'A135_33', 'A135_35', 'A135_41'};
            app.AlgorithmForCase135.ValueChangedFcn = createCallbackFcn(app, @AlgorithmForCase135ValueChanged, true);
            app.AlgorithmForCase135.Value = 'A135_21';
            app.CaseS134Label = uilabel(app.AlgorithmsTab);
            app.CaseS134Label.Text = 'Case S = {1,3,4}';
            app.AlgorithmForCase134 = uidropdown(app.AlgorithmsTab);
            app.AlgorithmForCase134.Items = {'A134_11'};
            app.AlgorithmForCase134.ValueChangedFcn = createCallbackFcn(app, @AlgorithmForCase134ValueChanged, true);
            app.AlgorithmForCase134.Value = 'A134_11';
            app.CaseS1234Label = uilabel(app.AlgorithmsTab);
            app.CaseS1234Label.Text = 'Case S = {1,2,3,4}';
            app.AlgorithmForCase1234 = uidropdown(app.AlgorithmsTab);
            app.AlgorithmForCase1234.Items = {'A1234_11'};
            app.AlgorithmForCase1234.ValueChangedFcn = createCallbackFcn(app, @AlgorithmForCase1234ValueChanged, true);
            app.AlgorithmForCase1234.Value = 'A1234_11';
            app.CaseS1345Label = uilabel(app.AlgorithmsTab);
            app.CaseS1345Label.Text = 'Case S = {1,3,4,5}';
            app.AlgorithmForCase1345 = uidropdown(app.AlgorithmsTab);
            app.AlgorithmForCase1345.Items = {'A1345_11'};
            app.AlgorithmForCase1345.ValueChangedFcn = createCallbackFcn(app, @AlgorithmForCase1345ValueChanged, true);
            app.AlgorithmForCase1345.Value = 'A1345_11';
            app.CaseS13456Label = uilabel(app.AlgorithmsTab);
            app.CaseS13456Label.Text = 'Case S = {1,3,4,5,6}';
            app.AlgorithmForCase13456 = uidropdown(app.AlgorithmsTab);
            app.AlgorithmForCase13456.Items = {'A13456_11'};
            app.AlgorithmForCase13456.ValueChangedFcn = createCallbackFcn(app, @AlgorithmForCase13456ValueChanged, true);
            app.AlgorithmForCase13456.Value = 'A13456_11';
            app.CaseS123456DropDownLabel = uilabel(app.AlgorithmsTab);
            app.CaseS123456DropDownLabel.Text = 'Case S = {1,2,3,4,5,6}';
            app.AlgorithmForCase123456 = uidropdown(app.AlgorithmsTab);
            app.AlgorithmForCase123456.Items = {'A123456_11'};
            app.AlgorithmForCase123456.ValueChangedFcn = createCallbackFcn(app, @AlgorithmForCase123456ValueChanged, true);
            app.AlgorithmForCase123456.Value = 'A123456_11';
            app.SettingsTab = uitab(app.TabGroup3);
            app.SettingsTab.Title = 'Settings';
            app.AnglethresholdEditFieldLabel = uilabel(app.SettingsTab);
            app.AnglethresholdEditFieldLabel.Text = 'Angle threshold';
            app.AnglethresholdEditField = uieditfield(app.SettingsTab, 'numeric');
            app.AnglethresholdEditField.Limits = [0 180];
            app.AnglethresholdEditField.ValueChangedFcn = createCallbackFcn(app, @AnglethresholdEditFieldValueChanged, true);
            app.AnglethresholdEditField.Value = 130;
            app.ThicknessEditFieldLabel = uilabel(app.SettingsTab);
            app.ThicknessEditFieldLabel.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ThicknessEditField.Limits = [0 Inf];
            app.ThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessEditFieldValueChanged, true);
            app.ThicknessEditField.Value = 0.1;
            app.AngledeviationEditFieldLabel = uilabel(app.SettingsTab);
            app.AngledeviationEditFieldLabel.Text = 'Angle deviation';
            app.AngledeviationEditField = uieditfield(app.SettingsTab, 'numeric');
            app.AngledeviationEditField.Limits = [0 180];
            app.AngledeviationEditField.ValueChangedFcn = createCallbackFcn(app, @AngledeviationEditFieldValueChanged, true);
            app.AngledeviationEditField.Value = 11.25;
            app.GeometrictoleranceEditFieldLabel = uilabel(app.SettingsTab);
            app.GeometrictoleranceEditFieldLabel.Text = 'Geometric tolerance';
            app.EpsEditField = uieditfield(app.SettingsTab, 'numeric');
            app.EpsEditField.Limits = [0 Inf];
            app.EpsEditField.ValueChangedFcn = createCallbackFcn(app, @EpsEditFieldValueChanged, true);
            app.EpsEditField.Value = 1e-10;
            app.NumberofextralayersLabel = uilabel(app.SettingsTab);
            app.NumberofextralayersLabel.Text = 'Number of extra layers';
            app.noExtraLayersEditField = uieditfield(app.SettingsTab, 'numeric');
            app.noExtraLayersEditField.Limits = [0 Inf];
            app.noExtraLayersEditField.RoundFractionalValues = 'on';
            app.noExtraLayersEditField.ValueDisplayFormat = '%d';
            app.noExtraLayersEditField.ValueChangedFcn = createCallbackFcn(app, @noExtraLayersEditFieldValueChanged, true);
            app.MaintaincollapsednessCheckBox = uicheckbox(app.SettingsTab);
            app.MaintaincollapsednessCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaintaincollapsednessCheckBoxValueChanged, true);
            app.MaintaincollapsednessCheckBox.Text = 'Maintain collapsedness';
            app.MaintaincollapsednessCheckBox.Value = true;
            app.UseaveragenormalvectorsCheckBox = uicheckbox(app.SettingsTab);
            app.UseaveragenormalvectorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseaveragenormalvectorsCheckBoxValueChanged, true);
            app.UseaveragenormalvectorsCheckBox.Text = 'Use average normal vectors';
            app.UseaveragenormalvectorsCheckBox.Value = true;
            app.WeightsformakingprioritizationsPanel = uipanel(app.SettingsTab);
            app.WeightsformakingprioritizationsPanel.Title = 'Weights for making prioritizations';
            app.CaseswithoppositefacesEditFieldLabel = uilabel(app.WeightsformakingprioritizationsPanel);
            app.CaseswithoppositefacesEditFieldLabel.Text = 'Cases with opposite faces';
            app.CaseswithoppositefacesEditField = uieditfield(app.WeightsformakingprioritizationsPanel, 'numeric');
            app.CaseswithoppositefacesEditField.Limits = [0 0.33];
            app.CaseswithoppositefacesEditField.Value = 0.1;
            app.CaseswithsingularitiesEditFieldLabel = uilabel(app.WeightsformakingprioritizationsPanel);
            app.CaseswithsingularitiesEditFieldLabel.Text = 'Cases with singularities';
            app.CaseswithsingularitiesEditField = uieditfield(app.WeightsformakingprioritizationsPanel, 'numeric');
            app.CaseswithsingularitiesEditField.Limits = [0 0.33];
            app.CaseswithsingularitiesEditField.Value = 0.2;
            app.InputsurfaceEditFieldLabel = uilabel(app.WeightsformakingprioritizationsPanel);
            app.InputsurfaceEditFieldLabel.Text = 'Input surface';
            app.InputsurfaceEditField = uieditfield(app.WeightsformakingprioritizationsPanel, 'numeric');
            app.InputsurfaceEditField.Limits = [0 0.33];
            app.InputsurfaceEditField.Value = 0.21;
            app.PrioritizeleastnormaldeviationCheckBox = uicheckbox(app.SettingsTab);
            app.PrioritizeleastnormaldeviationCheckBox.Text = 'Prioritize least normal deviation';
            app.PrioritizeleastnormaldeviationCheckBox.Value = true;
            app.PostprocTab = uitab(app.TabGroup);
            app.PostprocTab.Title = 'Post proc';
            app.RefinementPanel = uipanel(app.PostprocTab);
            app.RefinementPanel.Title = 'Refinement';
            app.UseUniformDegree = uicheckbox(app.RefinementPanel);
            app.UseUniformDegree.Text = 'Uniform degree elevation using degree';
            app.UseUniformDegree.Value = true;
            app.EditUniformDegree = uieditfield(app.RefinementPanel, 'numeric');
            app.EditUniformDegree.Limits = [1 Inf];
            app.EditUniformDegree.RoundFractionalValues = 'on';
            app.EditUniformDegree.ValueDisplayFormat = '%.0f';
            app.EditUniformDegree.ValueChangedFcn = createCallbackFcn(app, @EditUniformDegreeValueChanged, true);
            app.EditUniformDegree.Value = 2;
            app.Seth_maxEditFieldLabel = uilabel(app.RefinementPanel);
            app.Seth_maxEditFieldLabel.Text = 'Set h_max';
            app.Seth_maxEditField = uieditfield(app.RefinementPanel, 'numeric');
            app.Seth_maxEditField.Limits = [0 Inf];
            app.Seth_maxEditField.Value = 0.3;
            app.RefineObjSelection = uidropdown(app.RefinementPanel);
            app.RefineObjSelection.Items = {'Input object', 'Input surface', 'Output volume'};
            app.RefineObjSelection.Value = 'Output volume';
            app.RefineButton = uibutton(app.RefinementPanel, 'push');
            app.RefineButton.ButtonPushedFcn = createCallbackFcn(app, @RefineButtonPushed, true);
            app.RefineButton.Text = 'Refine';
            app.ExportPanel = uipanel(app.PostprocTab);
            app.ExportPanel.Title = 'Export';
            app.ExportObjSelection = uidropdown(app.ExportPanel);
            app.ExportObjSelection.Items = {'Input object', 'Input surface', 'Output volume', 'Refined input object', 'Refined input surface', 'Refined output volume'};
            app.ExportObjSelection.Value = 'Output volume';
            app.ExportButton = uibutton(app.ExportPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.SelectobjectDropDownLabel = uilabel(app.InfoTab);
            app.SelectobjectDropDownLabel.Text = 'Select object';
            app.InfoObjSelection = uidropdown(app.InfoTab);
            app.InfoObjSelection.Items = {'Input object', 'Input surface', 'Output volume', 'S2V surface', 'Refined input object', 'Refined input surface', 'Refined output volume'};
            app.InfoObjSelection.ValueChangedFcn = createCallbackFcn(app, @InfoObjSelectionValueChanged, true);
            app.InfoObjSelection.Value = 'Input object';
            app.SelectpatchSpinnerLabel = uilabel(app.InfoTab);
            app.SelectpatchSpinnerLabel.Text = 'Select patch';
            app.SelectpatchSpinner = uispinner(app.InfoTab);
            app.SelectpatchSpinner.Limits = [1 Inf];
            app.SelectpatchSpinner.RoundFractionalValues = 'on';
            app.SelectpatchSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectpatchSpinnerValueChanged, true);
            app.SelectpatchSpinner.Value = 1;
            app.ClearselectionButton = uibutton(app.InfoTab, 'push');
            app.ClearselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearselectionButtonPushed, true);
            app.ClearselectionButton.Text = 'Clear selection';
            app.nurbsInfo = uitextarea(app.InfoTab);
            app.nurbsInfo.Editable = 'off';
            app.NURBSTab = uitab(app.TabGroup);
            app.NURBSTab.Title = 'NURBS';
            app.UITable = uitable(app.NURBSTab);
            app.UITable.ColumnName = {'i'; 'j'; 'k'; 'x'; 'y'; 'z'; 'w'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.reiterate = uibutton(app.LeftPanel, 'push');
            app.reiterate.ButtonPushedFcn = createCallbackFcn(app, @reiterateButtonPushed, true);
            app.reiterate.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'reiterate.svg');
            app.reiterate.Text = '';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.ButtonDownFcn = createCallbackFcn(app, @CenterPanelButtonDown, true);
            app.UIAxes = uiaxes(app.CenterPanel);
            xlabel(app.UIAxes, 'x axis')
            ylabel(app.UIAxes, 'y axis')
            zlabel(app.UIAxes, 'z axis')
            app.ReadyLamp = uilamp(app.CenterPanel);
            app.ReadyLampLabel = uilabel(app.CenterPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.PopoutButton = uibutton(app.CenterPanel, 'push');
            app.PopoutButton.ButtonPushedFcn = createCallbackFcn(app, @PopoutButtonPushed, true);
            app.PopoutButton.Text = 'Pop out';
            app.OutputmessagesPanel = uipanel(app.CenterPanel);
            app.OutputmessagesPanel.Title = 'Output messages';
            app.OutputMessages = uitextarea(app.OutputmessagesPanel);
            app.OutputMessages.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.PlotsettingsTab = uitab(app.TabGroup2);
            app.PlotsettingsTab.Title = 'Plot settings';
            app.SettingsforplottingPanel = uipanel(app.PlotsettingsTab);
            app.SettingsforplottingPanel.Title = 'Settings for plotting';
            app.ShowobjectCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.ShowobjectCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowobjectCheckBoxValueChanged, true);
            app.ShowobjectCheckBox.Text = 'Show object';
            app.ShowobjectCheckBox.Value = true;
            app.ShowelementedgesCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.ShowelementedgesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowelementedgesCheckBoxValueChanged, true);
            app.ShowelementedgesCheckBox.Text = 'Show element edges';
            app.ShowelementedgesCheckBox.Value = true;
            app.ShowcontrolpolygonCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.ShowcontrolpolygonCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowcontrolpolygonCheckBoxValueChanged, true);
            app.ShowcontrolpolygonCheckBox.Text = 'Show control polygon';
            app.ShowparametricdirectionsCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.ShowparametricdirectionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowparametricdirectionsCheckBoxValueChanged, true);
            app.ShowparametricdirectionsCheckBox.Text = 'Show parametric directions';
            app.ShownormalvectorsCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.ShownormalvectorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShownormalvectorsCheckBoxValueChanged, true);
            app.ShownormalvectorsCheckBox.Text = 'Show normal vectors';
            app.CamlightCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.CamlightCheckBox.ValueChangedFcn = createCallbackFcn(app, @CamlightCheckBoxValueChanged, true);
            app.CamlightCheckBox.Text = 'Camlight';
            app.CamlightCheckBox.Value = true;
            app.coarseLinearSamplingCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.coarseLinearSamplingCheckBox.ValueChangedFcn = createCallbackFcn(app, @coarseLinearSamplingCheckBoxValueChanged, true);
            app.coarseLinearSamplingCheckBox.Text = 'Use two-point sampling for linear directions';
            app.coarseLinearSamplingCheckBox.Value = true;
            app.showAxis = uicheckbox(app.SettingsforplottingPanel);
            app.showAxis.ValueChangedFcn = createCallbackFcn(app, @showAxisValueChanged, true);
            app.showAxis.Text = 'Show axis';
            app.useSingleColorForInput = uicheckbox(app.SettingsforplottingPanel);
            app.useSingleColorForInput.ValueChangedFcn = createCallbackFcn(app, @useSingleColorForInputValueChanged, true);
            app.useSingleColorForInput.Text = 'Use single color for input objects';
            app.useSingleColorForInput.Value = true;
            app.showAvgNormals = uicheckbox(app.SettingsforplottingPanel);
            app.showAvgNormals.ValueChangedFcn = createCallbackFcn(app, @showAvgNormalsValueChanged, true);
            app.showAvgNormals.Text = 'Show average normal vectors';
            app.PlottingresolutionEditFieldLabel = uilabel(app.SettingsforplottingPanel);
            app.PlottingresolutionEditFieldLabel.Text = 'Plotting resolution';
            app.PlottingresolutionEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.PlottingresolutionEditField.Limits = [0 400];
            app.PlottingresolutionEditField.RoundFractionalValues = 'on';
            app.PlottingresolutionEditField.ValueChangedFcn = createCallbackFcn(app, @PlottingresolutionEditFieldValueChanged, true);
            app.PlottingresolutionEditField.Value = 32;
            app.QuiverscaleEditFieldLabel = uilabel(app.SettingsforplottingPanel);
            app.QuiverscaleEditFieldLabel.Text = 'Quiver scale';
            app.QuiverscaleEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.QuiverscaleEditField.Limits = [0 10];
            app.QuiverscaleEditField.ValueChangedFcn = createCallbackFcn(app, @QuiverscaleEditFieldValueChanged, true);
            app.QuiverscaleEditField.Value = 0.5;
            app.AutoscaleCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.AutoscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoscaleCheckBoxValueChanged, true);
            app.AutoscaleCheckBox.Text = 'Autoscale';
            app.QuiverstepEditFieldLabel = uilabel(app.SettingsforplottingPanel);
            app.QuiverstepEditFieldLabel.Text = 'Quiver step';
            app.QuiverstepEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.QuiverstepEditField.Limits = [1 Inf];
            app.QuiverstepEditField.ValueChangedFcn = createCallbackFcn(app, @QuiverstepEditFieldValueChanged, true);
            app.QuiverstepEditField.Value = 10;
            app.AlphavalueSliderLabel = uilabel(app.SettingsforplottingPanel);
            app.AlphavalueSliderLabel.Text = 'Alpha value';
            app.AlphavalueSlider = uislider(app.SettingsforplottingPanel);
            app.AlphavalueSlider.Limits = [0 1];
            app.AlphavalueSlider.MajorTicks = [];
            app.AlphavalueSlider.MajorTickLabels = {''};
            app.AlphavalueSlider.ValueChangedFcn = createCallbackFcn(app, @AlphavalueSliderValueChanged, true);
            app.AlphavalueSlider.MinorTicks = [];
            app.AlphavalueSlider.Value = 1;
            app.AlphavalueEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.AlphavalueEditField.Limits = [0 1];
            app.AlphavalueEditField.ValueChangedFcn = createCallbackFcn(app, @AlphavalueEditFieldValueChanged, true);
            app.AlphavalueEditField.Value = 1;
            app.ColoringDropDownLabel = uilabel(app.SettingsforplottingPanel);
            app.ColoringDropDownLabel.Text = 'Coloring';
            app.ColoringDropDown = uidropdown(app.SettingsforplottingPanel);
            app.ColoringDropDown.Items = {'None', 'Custom', 'Jacobian', 'Weights'};
            app.ColoringDropDown.ValueChangedFcn = createCallbackFcn(app, @ColoringDropDownValueChanged, true);
            app.ColoringDropDown.Value = 'None';
            app.CustomfunctionforcoloringLabel = uilabel(app.SettingsforplottingPanel);
            app.CustomfunctionforcoloringLabel.Text = 'Custom function for coloring:';
            app.ColorfunctionEditField = uieditfield(app.SettingsforplottingPanel, 'text');
            app.ColorfunctionEditField.ValueChangedFcn = createCallbackFcn(app, @ColorfunctionEditFieldValueChanged, true);
            app.ColorfunctionEditField.Value = '@(x) log10(abs(norm2(x(:,1:3))-1))';
            app.ViewLabel = uilabel(app.SettingsforplottingPanel);
            app.ViewLabel.Text = 'View:';
            app.AZEditFieldLabel = uilabel(app.SettingsforplottingPanel);
            app.AZEditFieldLabel.Text = 'AZ';
            app.AZEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.AZEditField.ValueChangedFcn = createCallbackFcn(app, @AZEditFieldValueChanged, true);
            app.ELEditFieldLabel = uilabel(app.SettingsforplottingPanel);
            app.ELEditFieldLabel.Text = 'EL';
            app.ELEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.ELEditField.ValueChangedFcn = createCallbackFcn(app, @ELEditFieldValueChanged, true);
            app.useDrawnowCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.useDrawnowCheckBox.Text = 'Use drawnow';
            app.useDrawnowCheckBox.Value = true;
            app.ObjectvisibilityPanel = uipanel(app.PlotsettingsTab);
            app.ObjectvisibilityPanel.Title = 'Object visibility';
            app.InputobjectCheckBox = uicheckbox(app.ObjectvisibilityPanel);
            app.InputobjectCheckBox.ValueChangedFcn = createCallbackFcn(app, @InputobjectCheckBoxValueChanged, true);
            app.InputobjectCheckBox.Text = 'Input object';
            app.RefinedinputobjectCheckBox = uicheckbox(app.ObjectvisibilityPanel);
            app.RefinedinputobjectCheckBox.ValueChangedFcn = createCallbackFcn(app, @RefinedinputobjectCheckBoxValueChanged, true);
            app.RefinedinputobjectCheckBox.Text = 'Refined input object';
            app.InputsurfaceCheckBox = uicheckbox(app.ObjectvisibilityPanel);
            app.InputsurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @InputsurfaceCheckBoxValueChanged, true);
            app.InputsurfaceCheckBox.Text = 'Input surface';
            app.RefinedinputsurfaceCheckBox = uicheckbox(app.ObjectvisibilityPanel);
            app.RefinedinputsurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @RefinedinputsurfaceCheckBoxValueChanged, true);
            app.RefinedinputsurfaceCheckBox.Text = 'Refined input surface';
            app.OutputvolumeCheckBox = uicheckbox(app.ObjectvisibilityPanel);
            app.OutputvolumeCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputvolumeCheckBoxValueChanged, true);
            app.OutputvolumeCheckBox.Text = 'Output volume';
            app.RefinedoutputvolumeCheckBox = uicheckbox(app.ObjectvisibilityPanel);
            app.RefinedoutputvolumeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RefinedoutputvolumeCheckBoxValueChanged, true);
            app.RefinedoutputvolumeCheckBox.Text = 'Refined output volume';
            app.S2VsurfaceCheckBox = uicheckbox(app.ObjectvisibilityPanel);
            app.S2VsurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @S2VsurfaceCheckBoxValueChanged, true);
            app.S2VsurfaceCheckBox.Text = 'S2V surface';
            app.S2VsurfaceCheckBox.Value = true;
            app.PlotbrowserTab = uitab(app.TabGroup2);
            app.PlotbrowserTab.Title = 'Plot browser';
            app.RefreshtreeButton = uibutton(app.PlotbrowserTab, 'push');
            app.RefreshtreeButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshtreeButtonPushed, true);
            app.RefreshtreeButton.Text = 'Refresh tree';
            app.Tree = uitree(app.PlotbrowserTab, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.InputobjectNode = uitreenode(app.Tree);
            app.InputobjectNode.Text = 'Input object';
            app.InputsurfaceNode = uitreenode(app.Tree);
            app.InputsurfaceNode.Text = 'Input surface';
            app.OutputvolumeNode = uitreenode(app.Tree);
            app.OutputvolumeNode.Text = 'Output volume';
            app.S2VsurfaceNode = uitreenode(app.Tree);
            app.S2VsurfaceNode.Text = 'S2V surface';
            app.RefinedinputobjectNode = uitreenode(app.Tree);
            app.RefinedinputobjectNode.Text = 'Refined input object';
            app.RefinedinputsurfaceNode = uitreenode(app.Tree);
            app.RefinedinputsurfaceNode.Text = 'Refined input surface';
            app.RefinedoutputvolumeNode = uitreenode(app.Tree);
            app.RefinedoutputvolumeNode.Text = 'Refined output volume';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.ContextMenu = uicontextmenu(app.Surface2volumealgorithmUIFigure);
            app.ObjectMenu = uimenu(app.ContextMenu);
            app.ObjectMenu.MenuSelectedFcn = createCallbackFcn(app, @ObjectMenuSelected, true);
            app.ObjectMenu.Accelerator = 'q';
            app.ObjectMenu.Text = 'Object';
            app.ObjectMenu.Tag = 'objectHandle';
            app.ElementedgesMenu = uimenu(app.ContextMenu);
            app.ElementedgesMenu.MenuSelectedFcn = createCallbackFcn(app, @ElementedgesMenuSelected, true);
            app.ElementedgesMenu.Accelerator = 'w';
            app.ElementedgesMenu.Text = 'Element edges';
            app.ElementedgesMenu.Tag = 'elementEdgesHandle';
            app.ControlpolygonMenu = uimenu(app.ContextMenu);
            app.ControlpolygonMenu.MenuSelectedFcn = createCallbackFcn(app, @ControlpolygonMenuSelected, true);
            app.ControlpolygonMenu.Accelerator = 'e';
            app.ControlpolygonMenu.Text = 'Control polygon';
            app.ControlpolygonMenu.Tag = 'controlPolygonHandle';
            app.ParametricdirectionsMenu = uimenu(app.ContextMenu);
            app.ParametricdirectionsMenu.MenuSelectedFcn = createCallbackFcn(app, @ParametricdirectionsMenuSelected, true);
            app.ParametricdirectionsMenu.Accelerator = 'r';
            app.ParametricdirectionsMenu.Text = 'Parametric directions';
            app.ParametricdirectionsMenu.Tag = 'parmDirHandle';
            app.NormalvectorsMenu = uimenu(app.ContextMenu);
            app.NormalvectorsMenu.MenuSelectedFcn = createCallbackFcn(app, @NormalvectorsMenuSelected, true);
            app.NormalvectorsMenu.Accelerator = 't';
            app.NormalvectorsMenu.Text = 'Normal vectors';
            app.NormalvectorsMenu.Tag = 'normalVectorsHandle';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.Surface2volumealgorithmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = S2V

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Surface2volumealgorithmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Surface2volumealgorithmUIFigure)
        end
    end
end