classdef surface2volume < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Surface2volumealgorithmUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SavestateMenu                   matlab.ui.container.Menu
        LoadstateMenu                   matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        READMEMenu                      matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        PlayNext                        matlab.ui.control.Button
        BackwardEnd                     matlab.ui.control.Button
        ForwardEnd                      matlab.ui.control.Button
        reset                           matlab.ui.control.Button
        Backward                        matlab.ui.control.Button
        Forward                         matlab.ui.control.Button
        Play                            matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        S2VTab                          matlab.ui.container.Tab
        Surface2volumesettingsPanel     matlab.ui.container.Panel
        CollapseselectedsurfaceButton   matlab.ui.control.Button
        AlgorithmForCase123456          matlab.ui.control.DropDown
        CaseS123456DropDownLabel_8      matlab.ui.control.Label
        AlgorithmForCase13456           matlab.ui.control.DropDown
        CaseS13456Label                 matlab.ui.control.Label
        AlgorithmForCase1345            matlab.ui.control.DropDown
        CaseS1345Label                  matlab.ui.control.Label
        AlgorithmForCase1234            matlab.ui.control.DropDown
        CaseS1234Label                  matlab.ui.control.Label
        AlgorithmForCase134             matlab.ui.control.DropDown
        CaseS134Label                   matlab.ui.control.Label
        AlgorithmForCase135             matlab.ui.control.DropDown
        CaseS135Label                   matlab.ui.control.Label
        AlgorithmForCase13              matlab.ui.control.DropDown
        CaseS13Label                    matlab.ui.control.Label
        AlgorithmForCase1               matlab.ui.control.DropDown
        CaseS1Label                     matlab.ui.control.Label
        EpsEditField                    matlab.ui.control.NumericEditField
        GeometrictoleranceEditFieldLabel  matlab.ui.control.Label
        AngledeviationEditField         matlab.ui.control.NumericEditField
        AngledeviationEditFieldLabel    matlab.ui.control.Label
        ThicknessEditField              matlab.ui.control.NumericEditField
        ThicknessEditFieldLabel         matlab.ui.control.Label
        AnglethresholdEditField         matlab.ui.control.NumericEditField
        AnglethresholdEditFieldLabel    matlab.ui.control.Label
        PostprocTab                     matlab.ui.container.Tab
        RefinementPanel                 matlab.ui.container.Panel
        RefineObjSelection              matlab.ui.control.DropDown
        EditUniformDegree               matlab.ui.control.NumericEditField
        UseUniformDegree                matlab.ui.control.CheckBox
        Seth_maxEditField               matlab.ui.control.NumericEditField
        Seth_maxEditFieldLabel          matlab.ui.control.Label
        RefineButton                    matlab.ui.control.Button
        ExportPanel                     matlab.ui.container.Panel
        ExportObjSelection              matlab.ui.control.DropDown
        Exportg2fileButton              matlab.ui.control.Button
        InfoTab                         matlab.ui.container.Tab
        ClearselectionButton            matlab.ui.control.Button
        SelectpatchSpinner              matlab.ui.control.Spinner
        SelectpatchSpinnerLabel         matlab.ui.control.Label
        nurbsInfo                       matlab.ui.control.TextArea
        InfoObjSelection                matlab.ui.control.DropDown
        SelectobjectDropDownLabel       matlab.ui.control.Label
        ORLabel                         matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        Loadg2fileButton                matlab.ui.control.Button
        ModelDropDownLabel              matlab.ui.control.Label
        MiddlePanel                     matlab.ui.container.Panel
        ReadyLamp                       matlab.ui.control.Lamp
        ReadyLampLabel                  matlab.ui.control.Label
        OutputmessagesPanel             matlab.ui.container.Panel
        OutputMessages                  matlab.ui.control.TextArea
        TabGroup2                       matlab.ui.container.TabGroup
        PlotsettingsTab                 matlab.ui.container.Tab
        ObjectvisibilityPanel           matlab.ui.container.Panel
        RefinedS2VsurfaceCheckBox       matlab.ui.control.CheckBox
        S2VsurfaceCheckBox              matlab.ui.control.CheckBox
        RefinedinputobjectCheckBox      matlab.ui.control.CheckBox
        RefinedinputsurfaceCheckBox     matlab.ui.control.CheckBox
        InputobjectCheckBox             matlab.ui.control.CheckBox
        RefinedoutputvolumeCheckBox     matlab.ui.control.CheckBox
        OutputvolumeCheckBox            matlab.ui.control.CheckBox
        InputsurfaceCheckBox            matlab.ui.control.CheckBox
        SettingsforplottingPanel        matlab.ui.container.Panel
        PlottingresolutionEditField     matlab.ui.control.NumericEditField
        PlottingresolutionEditFieldLabel  matlab.ui.control.Label
        ELEditField                     matlab.ui.control.NumericEditField
        ELEditFieldLabel                matlab.ui.control.Label
        ViewLabel                       matlab.ui.control.Label
        AZEditField                     matlab.ui.control.NumericEditField
        AZEditFieldLabel                matlab.ui.control.Label
        showAvgNormals                  matlab.ui.control.CheckBox
        useSingleColorForInput          matlab.ui.control.CheckBox
        showAxis                        matlab.ui.control.CheckBox
        CustomfunctionforcoloringLabel  matlab.ui.control.Label
        ColoringDropDown                matlab.ui.control.DropDown
        ColoringDropDownLabel           matlab.ui.control.Label
        AutoscaleCheckBox               matlab.ui.control.CheckBox
        QuiverstepEditField             matlab.ui.control.NumericEditField
        QuiverstepEditFieldLabel        matlab.ui.control.Label
        QuiverscaleEditField            matlab.ui.control.NumericEditField
        QuiverscaleEditFieldLabel       matlab.ui.control.Label
        AlphavalueEditField             matlab.ui.control.NumericEditField
        ColorfunctionEditField          matlab.ui.control.EditField
        AlphavalueSlider                matlab.ui.control.Slider
        AlphavalueSliderLabel           matlab.ui.control.Label
        coarseLinearSamplingCheckBox    matlab.ui.control.CheckBox
        ShowobjectCheckBox              matlab.ui.control.CheckBox
        ShownormalvectorsCheckBox       matlab.ui.control.CheckBox
        ShowparametricdirectionsCheckBox  matlab.ui.control.CheckBox
        ShowelementedgesCheckBox        matlab.ui.control.CheckBox
        ShowcontrolpolygonCheckBox      matlab.ui.control.CheckBox
        CamlightCheckBox                matlab.ui.control.CheckBox
        PlotbrowserTab                  matlab.ui.container.Tab
        RefreshtreeButton               matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
        InputobjectNode                 matlab.ui.container.TreeNode
        InputsurfaceNode                matlab.ui.container.TreeNode
        OutputvolumeNode                matlab.ui.container.TreeNode
        S2VsurfaceNode                  matlab.ui.container.TreeNode
        RefinedinputobjectNode          matlab.ui.container.TreeNode
        RefinedinputsurfaceNode         matlab.ui.container.TreeNode
        RefinedoutputvolumeNode         matlab.ui.container.TreeNode
        RefinedS2VsurfaceNode           matlab.ui.container.TreeNode
        PopoutButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        thickness   % Thickness of patches to be extruded
        S2Vobj   % Object containing current state of the S2V algorithm
        noSurfPatches
        sharpAngle 
        avg_v_n_threshholdAngle
        plotHandles
        camlightHandle
        plotColor
        options_default
        idleAutomation
        noBdryPatches
        colors % Colors used for volumetric meshing
        no_colors % Colors used for volumetric meshing
        history
        iteration
        stringShift
        objectVisibility
        selectedObjects
    end

    properties (Access = public)
        nurbsObjects % A 3 by 2 cell array of all NURBS objects and their refinement objects
        Eps % Geometric tolerance matching
        selectionColor % Color for selected surfaces
    end
    
    methods (Access = private)
            
        
        function allocatePlotHandle(app,i_p_arr,j_p_arr)
            if nargin < 2
                i_p_arr = 1:size(app.nurbsObjects,1);
            end
            if nargin < 3
                j_p_arr = 1:size(app.nurbsObjects,2);
            end
            for i_p = i_p_arr
                for j_p = j_p_arr
                    noPatches = numel(app.nurbsObjects{i_p,j_p}.nurbs);
                    app.plotHandles{i_p,j_p}.objectHandle = gobjects(1,noPatches);
                    app.plotHandles{i_p,j_p}.elementEdgesHandle = gobjects(1,noPatches);
                    app.plotHandles{i_p,j_p}.controlPolygonHandle = gobjects(1,noPatches);
                    app.plotHandles{i_p,j_p}.normalVectorsHandle = gobjects(1,noPatches);
                    app.plotHandles{i_p,j_p}.parmDirHandle = gobjects(3,noPatches);
                end
            end
        end

        function plot_nurbs(app,varargin)
            switchLamp(app,'Plotting ...')
            updateObjectVisibilityFromPanel(app)
            options = app.options_default;
            if nargin > 1
                if numel(varargin) > 2
                    newOptions = varargin(1:end);
                else
                    newOptions = varargin{1};
                end
                options = updateOptions(options,newOptions);
            end
            if isfield(options, 'i_p_arr')
                i_p_arr = options.i_p_arr;
            else
                i_p_arr = 1:size(app.nurbsObjects,1);
            end
            if isfield(options, 'j_p_arr')
                j_p_arr = options.j_p_arr;
            else
                j_p_arr = 1:size(app.nurbsObjects,2);
            end
            ax = app.UIAxes;
            tic
            printOutputMessage(app,sprintf(['%-' num2str(app.stringShift) 's'], 'Plotting patches ... '))
            for i_p = i_p_arr
                for j_p = j_p_arr
                    if app.objectVisibility(i_p,j_p)
                        if isfield(options, 'indices')
                            indices = options.indices;
                        else
                            indices = 1:numel(app.nurbsObjects{i_p,j_p}.nurbs);
                        end
                        if ~isempty(app.nurbsObjects{i_p,j_p}.nurbs) && ...
                                (options.replot || indices(1) > numel(app.plotHandles{i_p,j_p}.objectHandle) ...
                                                || isempty(app.plotHandles{i_p,j_p}.objectHandle(indices)) ...
                                                || isa(app.plotHandles{i_p,j_p}.objectHandle(1),'matlab.graphics.GraphicsPlaceholder') ...
                                                || ~all(isvalid(app.plotHandles{i_p,j_p}.objectHandle(indices))))
                            if i_p == 3 || i_p == 4
                                options.color = app.colors(mod(indices.'-1,app.no_colors)+1,:);
                                if app.showAvgNormals.Value %j_p == 1
                                    plotAverageNormalVectors(app)
                                end
                            end
                            options.displayName = @(patch_i) ['Patch ' num2str(indices(patch_i))];
                            h = plotNURBS(ax,app.nurbsObjects{i_p,j_p}.nurbs(indices),options);
                            app.plotHandles{i_p,j_p}.objectHandle(indices) = h.objectHandle;
                            app.plotHandles{i_p,j_p}.elementEdgesHandle(indices) = h.elementEdgesHandle;
                            app.plotHandles{i_p,j_p}.controlPolygonHandle(indices) = h.controlPolygonHandle;
                            app.plotHandles{i_p,j_p}.normalVectorsHandle(indices) = h.normalVectorsHandle;
                            app.plotHandles{i_p,j_p}.parmDirHandle(:,indices) = h.parmDirHandle;
                            toggleObjects(app,true,i_p,j_p)
%                             updateTree(app,i_p,j_p,indices)
                        end
                    end
                end
            end
            printOutputMessage(app,sprintf('using %12f seconds.', toc))
            showAxisValueChanged(app)
            drawnow
            
            if app.CamlightCheckBox.Value && ~(~isempty(app.camlightHandle) && isvalid(app.camlightHandle) && strcmp(app.camlightHandle.Visible,'on'))
                app.camlightHandle = camlight(ax);
            end
        end

        function plotAverageNormalVectors(app)
            for patch = 1:numel(app.S2Vobj.nurbs_bdry)
                for midx = 1:4
                    if ~all(isnan(app.S2Vobj.angles(patch,:)))
                        app.history(app.iteration).avgNormalVectorsHandle(midx,patch) = quiver3(app.UIAxes, ...
                                app.S2Vobj.edgeData(patch).X{midx}(:,1), ...
                                app.S2Vobj.edgeData(patch).X{midx}(:,2), ...
                                app.S2Vobj.edgeData(patch).X{midx}(:,3), ...
                                app.S2Vobj.edgeData(patch).avg_v_n{midx}(:,1), ...
                                app.S2Vobj.edgeData(patch).avg_v_n{midx}(:,2), ...
                                app.S2Vobj.edgeData(patch).avg_v_n{midx}(:,3), ...
                                'LineWidth',1,'AutoScale','off','DisplayName',['Avg. normals, patch = ' num2str(patch)]);
                    end
                end
            end
        end
        function valid = objIsValid(~,obj)
            valid = ~isa(obj,'matlab.graphics.GraphicsPlaceholder') && isvalid(obj);
        end

        function alterVisibility(app,obj,visible)
            if objIsValid(app,obj) && visible
                obj.Visible = visible;
            end
        end

        function toggleObjects(app,showObjects,i_p_arr,j_p_arr)
            if nargin < 2
                showObjects = true;
            end
            showObjects_old = showObjects;
            if nargin < 3
                i_p_arr = 1:size(app.plotHandles,1);
            end
            if nargin < 4
                j_p_arr = 1:size(app.plotHandles,2);
            end
            options = getOptions(app);
            for i_p = i_p_arr
                for j_p = j_p_arr
                    for patch = 1:numel(app.nurbsObjects{i_p,j_p}.nurbs)
                        if i_p == 3
                            showObject = showObjects_old && patch <= app.iteration && app.objectVisibility(i_p,j_p);
                        elseif i_p == 4
                            showObject = showObjects_old && patch <= app.noBdryPatches(app.iteration+1) && app.objectVisibility(i_p,j_p);
                        else
                            showObject = showObjects_old && app.objectVisibility(i_p,j_p);
                        end
                        alterVisibility(app,app.plotHandles{i_p,j_p}.objectHandle(patch),           app.ShowobjectCheckBox.Value        && showObject)
                        alterVisibility(app,app.plotHandles{i_p,j_p}.elementEdgesHandle(patch),     app.ShowelementedgesCheckBox.Value  && showObject)
                        alterVisibility(app,app.plotHandles{i_p,j_p}.controlPolygonHandle(patch),   app.ShowcontrolpolygonCheckBox.Value && showObject)
                        alterVisibility(app,app.plotHandles{i_p,j_p}.normalVectorsHandle(patch),    app.ShownormalvectorsCheckBox.Value && showObject)
                        for j = 1:size(app.plotHandles{i_p,j_p}.parmDirHandle,1)
                            alterVisibility(app,app.plotHandles{i_p,j_p}.parmDirHandle(j,patch), app.ShowparametricdirectionsCheckBox.Value && showObject)
                        end

                        if app.AutoscaleCheckBox.Value
                            app.plotHandles{i_p,j_p}.normalVectorsHandle(patch).AutoScale = 'on';
                            app.plotHandles{i_p,j_p}.normalVectorsHandle(patch).AutoScaleFactor = app.QuiverscaleEditField.Value;
                            for ii = 1:size(app.plotHandles{i_p,j_p}.parmDirHandle,1)
                                if ~isa(app.plotHandles{i_p,j_p}.parmDirHandle(ii,patch),'matlab.graphics.GraphicsPlaceholder')
                                    app.plotHandles{i_p,j_p}.parmDirHandle(ii,patch).AutoScale = 'on';
                                    app.plotHandles{i_p,j_p}.parmDirHandle(ii,patch).AutoScaleFactor = app.QuiverscaleEditField.Value;
                                end
                            end
                        end
                        obj = app.plotHandles{i_p,j_p}.objectHandle(patch);
                        if ~isa(obj,'matlab.graphics.GraphicsPlaceholder') && isvalid(obj) 
                            obj.FaceAlpha = app.AlphavalueEditField.Value;
                            if strcmp(app.ColoringDropDown.Value,'None')
                                if i_p == 3 || i_p == 4 || ~app.useSingleColorForInput.Value
                                    color = app.colors(mod(patch-1,app.no_colors)+1,:);
                                    obj.FaceColor = color;
                                else
                                    obj.FaceColor = options.color;
                                end
                            end
                        end
                    end
                    if app.showAvgNormals.Value
                        if i_p == 4 && j_p == 1
                            for i = 1:numel(app.history)
                                showObject = showObjects_old && app.objectVisibility(i_p,j_p);
                                for patch = 1:numel(app.history(i).S2Vobj.nurbs_bdry)
                                    for midx = 1:4
                                        alterVisibility(app,app.history(i).avgNormalVectorsHandle(midx,patch),app.showAvgNormals.Value && showObject && i == app.iteration)
                                    end
                                end
                            end
                        end
                    end
                end
            end
        end

        function plotIfNotPresent(app,showPlot,i_p,j_p)
            if showPlot && (isempty(app.plotHandles{i_p,j_p}.objectHandle) ...
                         || isa(app.plotHandles{i_p,j_p}.objectHandle(1),'matlab.graphics.GraphicsPlaceholder') ...
                         || ~isvalid(app.plotHandles{i_p,j_p}.objectHandle(1))) && ~isempty(app.nurbsObjects{i_p,j_p}.nurbs)
                options = getOptions(app);
                options.i_p_arr = i_p;
                options.j_p_arr = j_p;
                plot_nurbs(app,options)
            end
            app.objectVisibility(i_p,j_p) = showPlot;
            toggleObjects(app,showPlot,i_p,j_p)
        end

        function iterateCounters(app,shift)
            app.iteration = max(app.iteration + shift,0);
        end

        function cleanUpPlots(app,i_p_arr,j_p_arr,indices)
            if nargin < 2
                i_p_arr = 1:size(app.plotHandles,1);
            end
            if nargin < 3
                j_p_arr = 1:size(app.plotHandles,2);
            end
            for i_p = i_p_arr
                for j_p = j_p_arr
                    if ~isempty(app.plotHandles{i_p,j_p})
                        if nargin < 4
                            indices = 1:numel(app.plotHandles{i_p,j_p}.objectHandle);
                        end

                        delete(app.plotHandles{i_p,j_p}.objectHandle(indices))
                        delete(app.plotHandles{i_p,j_p}.elementEdgesHandle(indices))
                        delete(app.plotHandles{i_p,j_p}.controlPolygonHandle(indices))
                        delete(app.plotHandles{i_p,j_p}.normalVectorsHandle(indices))
                        delete(app.plotHandles{i_p,j_p}.parmDirHandle(:,indices))
                    end
                end
            end
            for i = 1:numel(app.history)
                if isfield(app.history(i),'avgNormalVectorsHandle')
                    if nargin < 4
                        indices = 1:numel(app.history(i).avgNormalVectorsHandle);
                    end
                    delete(app.history(i).avgNormalVectorsHandle(indices))
                end
            end
            ClearselectionButtonPushed(app)
        end

        function switchLamp(app,text)
            if nargin < 2
                app.ReadyLamp.Color = 'green';
                app.ReadyLampLabel.Text = 'Ready';
            else
                app.ReadyLamp.Color = 'red';
                app.ReadyLampLabel.Text = text;
            end
            pause(0.01)            
        end
        
        function options = getOptions(app)
            options = struct('plotObject',app.ShowobjectCheckBox.Value,...
                                'plotElementEdges',app.ShowelementedgesCheckBox.Value,...
                                'plotControlPolygon',app.ShowcontrolpolygonCheckBox.Value,...
                                'plotNormalVectors',app.ShownormalvectorsCheckBox.Value,...
                                'plotParmDir',app.ShowparametricdirectionsCheckBox.Value,...
                                'plotColorFun',strcmp(app.ColoringDropDown.Value,'Custom'),...
                                'plotWeights',strcmp(app.ColoringDropDown.Value,'Weights'),...
                                'plotJacobian',strcmp(app.ColoringDropDown.Value,'Jacobian'),...
                                'coarseLinearSampling',app.coarseLinearSamplingCheckBox.Value,...
                                'alphaValue',app.AlphavalueSlider.Value,...
                                'quiverScale',app.QuiverscaleEditField.Value,...
                                'qstep',app.QuiverstepEditField.Value,...
                                'quiverAutoScale',app.AutoscaleCheckBox.Value,...
                                'color',app.plotColor,...
                                'colorFun', str2func(app.ColorfunctionEditField.Value), ...
                                'resolution',app.PlottingresolutionEditField.Value*ones(1,3), ...
                                'app', app, ...
                                'replot', false);
        end

        function clearTree(app,i_p_arr,j_p_arr)
            for i_p = i_p_arr
                for j_p = j_p_arr
                    i_obj = (j_p-1)*size(app.plotHandles,1) + i_p;
                    delete(app.Tree.Children(i_obj).Children)
                end
            end
        end
        
        function processNURBSinput(app)
            app.nurbsObjects{1,1}.nurbs = cleanNURBS(app.nurbsObjects{1,1}.nurbs,[],app.Eps);
            app.nurbsObjects{2,1}.nurbs = explodeNURBS(app.nurbsObjects{1,1}.nurbs);
            if app.nurbsObjects{2,1}.nurbs{1}.d_p == 3
                app.nurbsObjects{2,1}.nurbs = extractFreeSurface(app.nurbsObjects{2,1}.nurbs);
            elseif ~(app.nurbsObjects{2,1}.nurbs{1}.d_p == 2)
                printOutputMessage(app,'Nonsensical input')
            end
            app.nurbsObjects{4,1}.nurbs = app.nurbsObjects{2,1}.nurbs;
            app.S2Vobj.nurbs_bdry = app.nurbsObjects{4,1}.nurbs;
            app.noSurfPatches = numel(app.nurbsObjects{2,1}.nurbs);
            app.noBdryPatches = app.noSurfPatches;
            app.iteration = 0;
            p_max = findMaxDegree(app.nurbsObjects{2,1}.nurbs);
            app.EditUniformDegree.Value = max(p_max);
            delete(app.Tree.CheckedNodes)
            delete(app.Tree.SelectedNodes)
            clearTree(app,1:4,1:2)
        end

        function resetS2Vobj(app)
            app.S2Vobj = [];
            app.nurbsObjects{4,1}.nurbs = app.nurbsObjects{2,1}.nurbs;
            app.nurbsObjects{3,2}.nurbs = [];
            app.nurbsObjects{4,2}.nurbs = [];
            app.nurbsObjects{3,1}.nurbs = [];
            app.S2Vobj.nurbs_bdry = app.nurbsObjects{4,1}.nurbs;
        end

        function updatePrivateVariables(app)

            app.sharpAngle = app.AnglethresholdEditField.Value*pi/180;
            app.avg_v_n_threshholdAngle = app.AngledeviationEditField.Value*pi/180;
            
            app.options_default = getOptions(app);
        end

        function updateObjectVisibilityFromPanel(app)
            app.objectVisibility = [app.InputobjectCheckBox.Value, app.RefinedinputobjectCheckBox.Value;
                                    app.InputsurfaceCheckBox.Value, app.RefinedinputsurfaceCheckBox.Value;
                                    app.OutputvolumeCheckBox.Value, app.RefinedoutputvolumeCheckBox.Value;
                                    app.S2VsurfaceCheckBox.Value, app.RefinedS2VsurfaceCheckBox.Value];

        end

        function updateNURBSinfo(app)
            [~,i] = ismember(app.InfoObjSelection.Value,app.InfoObjSelection.Items);
            app.SelectpatchSpinner.Value = min([app.SelectpatchSpinner.Value, numel(app.nurbsObjects{i}.nurbs)]);
            patch = app.SelectpatchSpinner.Value;
            if isempty(app.nurbsObjects{i}.nurbs)
                return
            end
            noPatches = numel(app.nurbsObjects{i}.nurbs);
            if noPatches == 1
                app.SelectpatchSpinner.Limits = [0,noPatches];
                patch = 1;
                app.SelectpatchSpinner.Value = patch;
            else
                app.SelectpatchSpinner.Limits = [1,noPatches];
            end
            
            s = sprintf('Info summary for object: %s\n', app.InfoObjSelection.Items{i});
            s = sprintf('%sNumber of patches in object: %d\n', s, noPatches);

            if ~isempty(app.selectedObjects) && i == 4
                s = sprintf('%s\n\nData for selected NURBS surfaces: %d\n', s);
                for patch = app.selectedObjects
                    s = printPatchInfo(app,s,i,patch);
                end
            else
                s = printPatchInfo(app,s,i,patch);
            end
            app.nurbsInfo.Value = s;
        end

        function s = printPatchInfo(app,s,i,patch)
            isRational = ~all(app.nurbsObjects{i}.nurbs{patch}.coeffs(end,:) == 1);
            if isRational
                rationalStr = 'True';
            else
                rationalStr = 'False';
            end
            d_p = app.nurbsObjects{i}.nurbs{patch}.d_p;
            s = sprintf('%s\nInfo for patch = %d:\n', s, patch);
            s = sprintf('%s\tNumber of parametric directions, d_p = %d\n', s, d_p);
            s = sprintf('%s\tNumber of physical dimensions, d = %d\n', s, app.nurbsObjects{i}.nurbs{patch}.d);
            s = sprintf('%s\tTotal number of basis functions: %d\n', s, prod(app.nurbsObjects{i}.nurbs{patch}.number));
            s = sprintf(['%s\tNumber of basis functions: (' repmat('%d, ',1,d_p-1), '%d)\n'], s, app.nurbsObjects{i}.nurbs{patch}.number);
            s = sprintf(['%s\tDegrees: (' repmat('%d, ',1,d_p-1), '%d)\n'], s, app.nurbsObjects{i}.nurbs{patch}.degree);
            s = sprintf('%s\tRational: %s\n', s, rationalStr);
        end

        function iterateS2V(app)
            tic
            printOutputMessage(app,sprintf(['%-' num2str(app.stringShift) 's'], 'Creating new volumetric patch ... '))
            app.S2Vobj.selectedObjects = app.selectedObjects;
            try
                app.S2Vobj = surfaceToVolume(app.S2Vobj,'t',app.ThicknessEditField.Value,'sharpAngle',app.sharpAngle,'avg_v_n_threshholdAngle',app.avg_v_n_threshholdAngle, ...
                             'S2V_algorithm', struct('A1',app.AlgorithmForCase1.Value,...
                                                'A13',app.AlgorithmForCase13.Value,...
                                                'A135',app.AlgorithmForCase135.Value,...
                                                'A134',app.AlgorithmForCase134.Value,...
                                                'A1234',app.AlgorithmForCase1234.Value,...
                                                'A1345',app.AlgorithmForCase1345.Value,...
                                                'A13456',app.AlgorithmForCase13456.Value,...
                                                'A123456',app.AlgorithmForCase123456.Value));
            catch ME
                printOutputMessage(app,sprintf('Could not iterate the S2V algorithm due to the following error: %s', ME.message))
            end
            app.nurbsObjects{3,1}.nurbs = app.S2Vobj.nurbs_vol(1:app.S2Vobj.counter);
            app.nurbsObjects{4,1}.nurbs = app.S2Vobj.nurbs_bdry;
            iterateCounters(app,+1)
            app.noBdryPatches(app.iteration+1) = numel(app.S2Vobj.nurbs_bdry);
            app.history(app.iteration).S2Vobj = app.S2Vobj;
            options = getOptions(app);
            options.i_p_arr = 3:4;
            options.j_p_arr = 1;
            options.indices = app.S2Vobj.newBdryPatches;
            options.replot = true;
            printOutputMessage(app,sprintf('using %12f seconds.', toc))
            plot_nurbs(app,options)
            ClearselectionButtonPushed(app)
        end


        function reiterateS2V(app)
            if app.iteration > 0
                iterateCounters(app,-1)
                toggleObjects(app,true,4,1)
                if app.iteration == 0
                    resetS2Vobj(app)
                else
                    app.S2Vobj = app.history(app.iteration).S2Vobj;
                end
                app.S2VsurfaceCheckBox.Value = true;
                iterateS2V(app)
            end
        end

        function makeNextCaseBold(app)

            app.CaseS1Label.FontWeight = 'normal';
            app.CaseS13Label.FontWeight = 'normal';
            app.CaseS135Label.FontWeight = 'normal';
            app.CaseS134Label.FontWeight = 'normal';
            app.CaseS1234Label.FontWeight = 'normal';
            app.CaseS1345Label.FontWeight = 'normal';
            app.CaseS13456Label.FontWeight = 'normal';
            app.CaseS123456DropDownLabel_8.FontWeight = 'normal';
%             switch app.S2Vobj.maxNoSharpAngles
%                 case 0
%                     app.CaseS1Label.FontWeight = 'bold';
%                 case 1
%                     app.CaseS1Label.FontWeight = 'bold';
%                 case 2
%                     app.CaseS1Label.FontWeight = 'bold';
%                 case 3
%                     app.CaseS1Label.FontWeight = 'bold';
%                 case 4
%                     app.CaseS1Label.FontWeight = 'bold';
%                 case 5
%                     app.CaseS1Label.FontWeight = 'bold';
%             end
        end
        function refreshPlots(app)
            cleanUpPlots(app)
            options = getOptions(app);
            options.i_p_arr = 4;
            options.j_p_arr = 1;
            options.replot = true;
            app.S2VsurfaceCheckBox.Value = true;
            allocatePlotHandle(app,1:4,1:2)
            plot_nurbs(app,options)
            set(app.UIAxes,'xlimMode', 'auto') 
            set(app.UIAxes,'ylimMode', 'auto')
            set(app.UIAxes,'zlimMode', 'auto')
            updateNURBSinfo(app)
        end

        function printOutputMessage(app,s)
            if strcmp(s(1:5),'using') || (numel(app.OutputMessages.Value) == 1 && isempty(app.OutputMessages.Value{1}))
                app.OutputMessages.Value{end} = [app.OutputMessages.Value{end}, s];
            else
                app.OutputMessages.Value{end+1} = s;
            end
            scroll(app.OutputMessages, 'bottom')
        end

        function updateTree(app,i_p_arr,j_p_arr,indices)
            if nargin < 2
                i_p_arr = 1:size(app.plotHandles,1);
            end
            if nargin < 3
                j_p_arr = 1:size(app.plotHandles,2);
            end
            CheckedNodes = gobjects(1000,1);
            counter = numel(app.Tree.CheckedNodes)+1;
            for j_p = j_p_arr
                for i_p = i_p_arr
                    if nargin < 4
                        indices = 1:numel(app.plotHandles{i_p,j_p}.objectHandle);
                    end
                    i_obj = (j_p-1)*size(app.plotHandles,1) + i_p;
                    for patch = indices
                        if ~isempty(app.plotHandles{i_p,j_p}.objectHandle) ...
                                && ~isa(app.plotHandles{i_p,j_p}.objectHandle(patch),'matlab.graphics.GraphicsPlaceholder') ...
                                && isvalid(app.plotHandles{i_p,j_p}.objectHandle(patch))
                            patchNode = uitreenode(app.Tree.Children(i_obj));
                            patchNode.Text = app.plotHandles{i_p,j_p}.objectHandle(patch).DisplayName;


                            objectNode = uitreenode(patchNode, 'Text', 'Object');
                            objectNode.NodeData = app.plotHandles{i_p,j_p}.objectHandle(patch);  

                            elementEdgesNode = uitreenode(patchNode, 'Text', 'Element edges');
                            elementEdgesNode.NodeData = app.plotHandles{i_p,j_p}.elementEdgesHandle(patch);  

                            controlPolygonNode = uitreenode(patchNode, 'Text', 'Control polygon');
                            controlPolygonNode.NodeData = app.plotHandles{i_p,j_p}.controlPolygonHandle(patch);  

                            normalVectorsNode = uitreenode(patchNode, 'Text', 'Normal vectors');
                            normalVectorsNode.NodeData = app.plotHandles{i_p,j_p}.normalVectorsHandle(patch);  
                            
                            parmDirNode = uitreenode(patchNode, 'Text', 'Parametric directions');
                            parmDirNode_dir1 = uitreenode(parmDirNode, 'Text', 'dir = 1');
                            parmDirNode_dir1.NodeData = app.plotHandles{i_p,j_p}.parmDirHandle(1,patch);  
                            
                            if ~isa(app.plotHandles{i_p,j_p}.parmDirHandle(2,patch),'matlab.graphics.GraphicsPlaceholder')
                                parmDirNode_dir2 = uitreenode(parmDirNode, 'Text', 'dir = 2');
                                parmDirNode_dir2.NodeData = app.plotHandles{i_p,j_p}.parmDirHandle(2,patch);  
                                obj = app.plotHandles{i_p,j_p}.parmDirHandle(3,patch);  
                                if objIsValid(app,obj) && obj.Visible
                                    CheckedNodes(counter) = parmDirNode_dir2;
                                    counter = counter + 1;
                                end
                            end

                            if ~isa(app.plotHandles{i_p,j_p}.parmDirHandle(3,patch),'matlab.graphics.GraphicsPlaceholder')
                                parmDirNode_dir3 = uitreenode(parmDirNode, 'Text', 'dir = 3');
                                parmDirNode_dir3.NodeData = app.plotHandles{i_p,j_p}.parmDirHandle(3,patch);  
                                obj = app.plotHandles{i_p,j_p}.parmDirHandle(3,patch);  
                                if objIsValid(app,obj) && obj.Visible
                                    CheckedNodes(counter) = parmDirNode_dir3;
                                    counter = counter + 1;
                                end
                            end
                            obj = app.plotHandles{i_p,j_p}.objectHandle(patch);    
                            if objIsValid(app,obj) && obj.Visible
                                CheckedNodes(counter) = objectNode;
                                counter = counter + 1;
                            end
                            obj = app.plotHandles{i_p,j_p}.elementEdgesHandle(patch);    
                            if objIsValid(app,obj) && obj.Visible
                                CheckedNodes(counter) = elementEdgesNode;
                                counter = counter + 1;
                            end
                            obj = app.plotHandles{i_p,j_p}.controlPolygonHandle(patch);                            
                            if objIsValid(app,obj) && obj.Visible
                                CheckedNodes(counter) = controlPolygonNode;
                                counter = counter + 1;
                            end
                            obj = app.plotHandles{i_p,j_p}.normalVectorsHandle(patch);    
                            if objIsValid(app,obj) && obj.Visible
                                CheckedNodes(counter) = normalVectorsNode;
                                counter = counter + 1;
                            end
                            obj = app.plotHandles{i_p,j_p}.parmDirHandle(patch);    
                            if objIsValid(app,obj) && obj.Visible
                                CheckedNodes(counter) = parmDirNode_dir1;
                                counter = counter + 1;
                            end
                        end
                    end
                end
            end
            app.Tree.CheckedNodes = [app.Tree.CheckedNodes; CheckedNodes(1:counter-1)];
            expand(app.Tree.Children(4))
        end
    end

    
    methods (Access = public)
        
        function S2Vmouse_click(app,~,eventData)
            patch = str2double(eventData.Source.DisplayName(7:end));
            if ismember(patch,app.selectedObjects)
                printOutputMessage(app,sprintf('Patch %d unselected.', patch))
                app.selectedObjects(app.selectedObjects == patch) = [];
                color = app.colors(mod(patch-1,app.no_colors)+1,:);
                app.plotHandles{4,1}.objectHandle(patch).FaceColor = color;
            else
                printOutputMessage(app,sprintf('Patch %d selected.', patch))
                app.selectedObjects = [app.selectedObjects, patch];
                app.plotHandles{4,1}.objectHandle(patch).FaceColor = app.selectionColor;
            end
            app.InfoObjSelection.Value = 'S2V surface';
            updateNURBSinfo(app)
            app.SelectpatchSpinner.Value = patch;
            updateNURBSinfo(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set default values
            drawnow limitrate % Speed up background simulations
            app.Eps = app.EpsEditField.Value;
            app.selectionColor = [0.8,0,0];
            app.stringShift = 40;
            app.no_colors = 30;
            ax = app.UIAxes;
            app.colors = getColorMap('shsv',ax,app.no_colors);
            app.plotColor = getColor();
            app.idleAutomation = true;
            app.iteration = 0;
            updatePrivateVariables(app)
            app.plotHandles = cell(4,2);
            app.nurbsObjects = cell(4,2);
            for i_p = 1:size(app.nurbsObjects,1)
                for j_p = 1:size(app.nurbsObjects,2)
                    app.nurbsObjects{i_p,j_p}.nurbs = [];
                end
            end
            allocatePlotHandle(app)
            axis(ax,'equal')

            updateObjectVisibilityFromPanel(app)

            % Set tool tip
            app.coarseLinearSamplingCheckBox.Tooltip = 'Evaluate every element only on the boundary in linear parametric directions';
            app.ShowobjectCheckBox.Tooltip = 'Visualize the patches of the NURBS object';
            app.ShownormalvectorsCheckBox.Tooltip = 'Visualize the normal vectors on the surface of the NURBS object';
            app.ShowparametricdirectionsCheckBox.Tooltip = 'Visualize the parametric directions at the  surface of the NURBS object';
            app.ShowelementedgesCheckBox.Tooltip = 'Visualize the elements of the NURBS object (shows also the interior elements of a NURBS patch)';
            app.ShowcontrolpolygonCheckBox.Tooltip = 'Visualize the full control polygon for the NURBS object';
            app.PlottingresolutionEditField.Tooltip = 'Edit the plotting resolution for the NURBS object. This number is scaled with the ratio between number of C0-elements and the total number of elements';
            app.PlottingresolutionEditFieldLabel.Tooltip = app.PlottingresolutionEditField.Tooltip;
            app.CamlightCheckBox.Tooltip = 'Turn on or off the camlight';
            app.ThicknessEditField.Tooltip = 'Specify the thickness of the patch away from a single surface patch';
            app.AnglethresholdEditField.Tooltip = 'Set the threshold angle between two patches for which the algorithm will try to form a patch between these surface patches';
            app.Play.Tooltip = 'Run/pause automated surface to volume patch generator';
            app.Loadg2fileButton.Tooltip = 'Open a .g2-file from your file system. For linux users: The app window might move down some layers';
            app.ModelDropDown.Tooltip = 'Select a predefined model that loads a NURBS geometry and a predefined set of parameters for the surface2volume algorithm';
            app.PopoutButton.Tooltip = 'Pop out figure to a separate window to enable plot browser/inspector (i.e. "View" -> "Plot Browser" is very convenient in order to hide/edit objects)';
            app.ReadyLamp.Tooltip = 'The lamp turns red when program is busy';
            app.QuiverstepEditField.Tooltip = 'Step over evaluation points when plotting any normal vectors/parametric directions. Setting quiver step = 1 will plot vectors at every evaluation points.';
            app.QuiverstepEditFieldLabel.Tooltip = app.QuiverstepEditField.Tooltip;
            app.QuiverscaleEditField.Tooltip = 'Scale the length of the quiver vectors used for normal vectors/parametric directions.';
            app.QuiverscaleEditFieldLabel.Tooltip = app.QuiverscaleEditField.Tooltip;
            app.AutoscaleCheckBox.Tooltip = 'Scale the length of the quiver vectors automatically using MATLAB built in algorithm.';
            app.AlphavalueEditField.Tooltip = 'Alter the transparancy of the object by changing the alpha value';
            app.AlphavalueSlider.Tooltip = app.AlphavalueEditField.Tooltip;
            app.AlphavalueSliderLabel.Tooltip = app.AlphavalueEditField.Tooltip;
            app.ReadyLampLabel.Tooltip = 'Status of app';
            app.Seth_maxEditField.Tooltip = 'Set the maximal (up to rounding) element edge length for all elements throughout the mesh for the refinement procedure';
            app.Seth_maxEditFieldLabel.Tooltip = app.Seth_maxEditField.Tooltip;
            app.RefineButton.Tooltip = 'Refine the volumetric mesh resulting from the S2V algorithm';
            app.ExportObjSelection.Tooltip = 'Select NURBS to export';
            app.Exportg2fileButton.Tooltip = 'Export selected NURBS to a file in your file system.';
            app.ColorfunctionEditField.Tooltip = 'Write a MATLAB function handle for coloring the NURBS object. This is convenient to show the geometric error, i.e., the exactness of the sphere representation using NURBS, which is the default expression.';
            app.ColoringDropDown.Tooltip = 'Choose coloring when plotting NURBS objects';

            app.ModelDropDown.Value = 'S1';
            ModelDropDownValueChanged(app)


        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            switchLamp(app,'Loading model ...')
            resetS2Vobj(app)
            view(app.UIAxes,getView());
            model = app.ModelDropDown.Value;
            if strcmp(model,'<Select model>')
                cleanUpPlots(app)
                switchLamp(app)
                return
            end
            app = getS2Vmodel(app,model);
            updatePrivateVariables(app)
            processNURBSinput(app)
            refreshPlots(app)
            switchLamp(app)
        end

        % Value changed function: AnglethresholdEditField
        function AnglethresholdEditFieldValueChanged(app, event)
            app.sharpAngle = app.AnglethresholdEditField.Value*pi/180;
        end

        % Value changed function: ThicknessEditField
        function ThicknessEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: Play
        function PlayPushed(app, event)
            switchLamp(app,'Running S2V algorithm ...')
            app.idleAutomation = ~app.idleAutomation;

            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Play.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'pause.svg');
            if isempty(app.nurbsObjects{4,1}.nurbs)
                app.idleAutomation = true;
                app.Play.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'play.svg');
                switchLamp(app)
                return
            else
                app.S2VsurfaceCheckBox.Value = true;
                t_start = tic;
                
                while (~isfield(app.S2Vobj,'S2Vcompleted') || ~app.S2Vobj.S2Vcompleted) ...
                       && (~isfield(app.S2Vobj,'selfIntersection') || ~app.S2Vobj.selfIntersection) && ~app.idleAutomation
                    iterateS2V(app)
                end
                if isfield(app.S2Vobj,'S2Vcompleted') && app.S2Vobj.S2Vcompleted
                    printOutputMessage(app,sprintf(['%-' num2str(app.stringShift) 's'], 'Completed S2V algorithm ... '))
                    printOutputMessage(app,sprintf('using %12f seconds.', toc(t_start)))
                end
            end
            updateNURBSinfo(app)
            app.idleAutomation = true;
            app.Play.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'play.svg');
            switchLamp(app)
        end

        % Button pushed function: Loadg2fileButton
        function Loadg2fileButtonPushed(app, event)
            switchLamp(app,'Opening file ...')
            [filename, pathname] = uigetfile('*.g2', 'Open a .g2 file','NURBSgeometries/g2files/');
            if filename == -1
                printOutputMessage(app,sprintf('Could not open file %s', filename))
                switchLamp(app)   
                return
            elseif filename == 0
                switchLamp(app)   
                return
            end
            resetS2Vobj(app)
            app.nurbsObjects{1,1}.nurbs = read_g2([pathname, filename]);
            processNURBSinput(app)
            refreshPlots(app)
            view(app.UIAxes,getView());
            app.ModelDropDown.Value = '<Select model>';
            switchLamp(app)
        end

        % Value changed function: CamlightCheckBox
        function CamlightCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling camlight ...')
            value = app.CamlightCheckBox.Value;
            if value
                app.camlightHandle = camlight(app.UIAxes);
            else
                if isvalid(app.camlightHandle)
                    app.camlightHandle.Visible = 'off';
                end
            end
            switchLamp(app)
        end

        % Value changed function: PlottingresolutionEditField
        function PlottingresolutionEditFieldValueChanged(app, event)
            switchLamp(app,'Altering plotting resolution ...')
            cleanUpPlots(app)
            options = getOptions(app);
            app.PlottingresolutionEditField.Value = max(round(app.PlottingresolutionEditField.Value),0);
            options.resolution = app.PlottingresolutionEditField.Value*ones(1,3);
            options.replot = true;
            plot_nurbs(app,options)
            switchLamp(app)
        end

        % Value changed function: ShowcontrolpolygonCheckBox
        function ShowcontrolpolygonCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling visualization of control polygon ...')
            toggleObjects(app)
            switchLamp(app)
        end

        % Value changed function: ShowelementedgesCheckBox
        function ShowelementedgesCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling visualization of elements ...')
            toggleObjects(app)
            switchLamp(app)
        end

        % Value changed function: ShowparametricdirectionsCheckBox
        function ShowparametricdirectionsCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling visualization of parametric directions ...')
            toggleObjects(app)
            switchLamp(app)         
        end

        % Value changed function: ShownormalvectorsCheckBox
        function ShownormalvectorsCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling visualization of normal vectors ...')
            toggleObjects(app)
            switchLamp(app)            
        end

        % Value changed function: ShowobjectCheckBox
        function ShowobjectCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling visualization of object ...')
            toggleObjects(app)
            switchLamp(app)            
        end

        % Value changed function: coarseLinearSamplingCheckBox
        function coarseLinearSamplingCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling coarse linear sampling of object ...')
            cleanUpPlots(app)
            options = getOptions(app);
            options.replot = true;
            plot_nurbs(app,options)
            switchLamp(app)
        end

        % Button pushed function: PopoutButton
        function PopoutButtonPushed(app, event)
            switchLamp(app,'Copying figure onto new figure ...')
            f2 = figure('units','normalized','outerposition',[0 0 1 1]);
            copyobj(app.UIAxes,f2);
            f2.CurrentAxes.Position = [0, 0, 1920, 1080];
            switchLamp(app)
        end

        % Value changing function: AlphavalueSlider
        function AlphavalueSliderValueChanging(app, event)
            switchLamp(app,'Changing alpha value ...')
            app.AlphavalueEditField.Value = event.Value;
            toggleObjects(app)
            switchLamp(app)
        end

        % Value changed function: ColorfunctionEditField
        function ColorfunctionEditFieldValueChanged(app, event)
            switchLamp(app,'Changing color function...')
            if ~strcmp(app.ColoringDropDown.Value,'None')
                cleanUpPlots(app)
                options = getOptions(app);
                options.replot = true;
                plot_nurbs(app,options)
                colormap(app.UIAxes, 'parula');
                colorbar(app.UIAxes)
            end
            switchLamp(app)
        end

        % Callback function
        function PlotColorFunCheckBoxValueChanged(app, event)
            keyboard
        end

        % Value changed function: AlphavalueEditField
        function AlphavalueEditFieldValueChanged(app, event)
            switchLamp(app,'Changing alpha value ...')
            app.AlphavalueSlider.Value = app.AlphavalueEditField.Value;
            toggleObjects(app)
            switchLamp(app)
        end

        % Value changed function: QuiverscaleEditField
        function QuiverscaleEditFieldValueChanged(app, event)
            switchLamp(app,'Editing quiver scale ...')
            cleanUpPlots(app)
            options = getOptions(app);
            options.replot = true;
            plot_nurbs(app,options)
            switchLamp(app)
        end

        % Value changed function: QuiverstepEditField
        function QuiverstepEditFieldValueChanged(app, event)
            switchLamp(app,'Editing quiver step ...')
            cleanUpPlots(app)
            options = getOptions(app);
            options.replot = true;
            plot_nurbs(app,options)
            switchLamp(app)
        end

        % Button pushed function: RefineButton
        function RefineButtonPushed(app, event)
            switchLamp(app,'Refining volumetric NURBS ...')
            [~,i_p] = ismember(app.RefineObjSelection.Value,app.RefineObjSelection.Items);

            if isempty(app.nurbsObjects{i_p,1}.nurbs)
                printOutputMessage(app,'Could not find any volumetric NURBS. Did you run the S2V algorithm?')
                switchLamp(app)
                return
            end
            if isfield(app.nurbsObjects{i_p,1},'connection')
                connection = app.nurbsObjects{i_p,1}.connection;
            else
                geometry = getTopology(app.nurbsObjects{i_p,1}.nurbs);
                connection = geometry.topology.connection;
                app.nurbsObjects{i_p,1}.connection = connection;
            end
            if ~isempty(app.nurbsObjects{i_p,2}.nurbs)
                cleanUpPlots(app,i_p,2)
            end
            if app.UseUniformDegree.Value
                app.nurbsObjects{i_p,2}.nurbs = makeUniformNURBSDegree(app.nurbsObjects{i_p,1}.nurbs,app.EditUniformDegree.Value);
            end
            app.nurbsObjects{i_p,2}.nurbs = autoRefineNURBS(app.nurbsObjects{i_p,2}.nurbs,connection,app.Seth_maxEditField.Value);
            switch i_p
                case 1
                    app.RefinedinputobjectCheckBox.Value = true;
                    app.InputobjectCheckBox.Value = false;
                case 2
                    app.RefinedinputsurfaceCheckBox.Value = true;
                    app.InputsurfaceCheckBox.Value = false;
                case 3
                    app.RefinedoutputvolumeCheckBox.Value = true;
                    app.OutputvolumeCheckBox.Value = false;
                case 4
                    app.RefinedS2VsurfaceCheckBox.Value = true;
                    app.S2VsurfaceCheckBox.Value = false;
            end
            updateObjectVisibilityFromPanel(app)
            options = getOptions(app);
            options.i_p_arr = i_p;
            options.j_p_arr = 2;
            plot_nurbs(app,options)
            toggleObjects(app)
            updateNURBSinfo(app)
            switchLamp(app)
        end

        % Button pushed function: Exportg2fileButton
        function Exportg2fileButtonPushed(app, event)
            switchLamp(app,'Exporting file ...')
            [filename, pathname] = uiputfile('*.g2', 'Save a .g2 file','NURBSgeometries/g2files/');
            if filename == -1
                printOutputMessage(app,sprintf('Could not save to file %s', filename))
                switchLamp(app)   
                return
            elseif filename == 0
                switchLamp(app)   
                return
            end
            [~,i] = ismember(app.ExportObjSelection.Value,app.ExportObjSelection.Items);
            write_g2(app.nurbsObjects{i}.nurbs, [pathname, filename]);

            switchLamp(app)   
        end

        % Value changed function: AutoscaleCheckBox
        function AutoscaleCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling autoscale for quiver plots ...')
            cleanUpPlots(app)
            options = getOptions(app);
            options.replot = true;
            plot_nurbs(app,options)
            switchLamp(app)
            
        end

        % Button pushed function: reset
        function resetPushed(app, event)
            switchLamp(app,'Cleaning up plots and reloading the model ...')
            cleanUpPlots(app,3)
            cleanUpPlots(app,4,1,numel(app.nurbsObjects{2,1}.nurbs)+1:numel(app.nurbsObjects{4,1}.nurbs))
            resetS2Vobj(app)
            app.history = [];
            app.iteration = 0;
            ClearselectionButtonPushed(app)
            switchLamp(app)
        end

        % Button pushed function: Forward
        function ForwardButtonPushed(app, event)
            switchLamp(app,'Iterating forwards ...')
            if app.iteration < numel(app.history)
                iterateCounters(app,+1)
                app.S2Vobj = app.history(app.iteration).S2Vobj;
                app.nurbsObjects{4,1}.nurbs = app.S2Vobj.nurbs_bdry;
                toggleObjects(app,true,4,1)
            end
            switchLamp(app)
        end

        % Button pushed function: Backward
        function BackwardButtonPushed(app, event)
            switchLamp(app,'Iterating backwards ...')
            if app.iteration > 0
                iterateCounters(app,-1)
                toggleObjects(app,true,4,1)
                if app.iteration == 0
                    resetS2Vobj(app)
                else
                    app.S2Vobj = app.history(app.iteration).S2Vobj;
                end
                makeNextCaseBold(app)
            end
            switchLamp(app)
        end

        % Button pushed function: BackwardEnd
        function BackwardEndButtonPushed(app, event)
            switchLamp(app,'Iterating back to the begining  ...')
            if app.iteration > 0
                iterateCounters(app,-app.iteration)
                toggleObjects(app,true,4,1)
                resetS2Vobj(app)
            end
            switchLamp(app)
        end

        % Button pushed function: ForwardEnd
        function ForwardEndButtonPushed(app, event)
            switchLamp(app,'Iterating forward to the current state  ...')
            if app.iteration < numel(app.history)
                app.S2Vobj = app.history(end).S2Vobj;
                app.nurbsObjects{4,1}.nurbs = app.S2Vobj.nurbs_bdry;
                iterateCounters(app,numel(app.history)-app.iteration)
                toggleObjects(app,true,4,1)
            end
            switchLamp(app)
        end

        % Value changed function: InputsurfaceCheckBox
        function InputsurfaceCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling input surface visibility ...')
            plotIfNotPresent(app,app.InputsurfaceCheckBox.Value,2,1)
            switchLamp(app)
        end

        % Value changed function: OutputvolumeCheckBox
        function OutputvolumeCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling input surface visibility ...')
            plotIfNotPresent(app,app.OutputvolumeCheckBox.Value,3,1)
            switchLamp(app)
        end

        % Value changed function: RefinedoutputvolumeCheckBox
        function RefinedoutputvolumeCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling refined output volume visibility ...')
            plotIfNotPresent(app,app.RefinedoutputvolumeCheckBox.Value,3,2)
            switchLamp(app)
        end

        % Value changed function: ColoringDropDown
        function ColoringDropDownValueChanged(app, event)
            switchLamp(app,'Altering coloring ...')
            switch app.ColoringDropDown.Value
                case 'None'
                    colorbar(app.UIAxes, 'off')
                    app.coarseLinearSamplingCheckBox.Value = true; % No reason to use redundant amount of evaluation points
                    toggleObjects(app)
                otherwise
                    app.coarseLinearSamplingCheckBox.Value = false;
                    ColorfunctionEditFieldValueChanged(app, event);
            end
            switchLamp(app)
        end

        % Value changed function: AngledeviationEditField
        function AngledeviationEditFieldValueChanged(app, event)
            app.avg_v_n_threshholdAngle = app.AngledeviationEditField.Value*pi/180;
        end

        % Value changed function: InputobjectCheckBox
        function InputobjectCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling input object visibility ...')
            plotIfNotPresent(app,app.InputobjectCheckBox.Value,1,1)
            switchLamp(app)
        end

        % Value changed function: RefinedinputobjectCheckBox
        function RefinedinputobjectCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling refined input object visibility ...')
            plotIfNotPresent(app,app.RefinedinputobjectCheckBox.Value,1,2)
            switchLamp(app)
        end

        % Value changed function: RefinedinputsurfaceCheckBox
        function RefinedinputsurfaceCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling refined input surface visibility ...')
            plotIfNotPresent(app,app.RefinedinputsurfaceCheckBox.Value,2,2)
            switchLamp(app)
        end

        % Value changed function: EpsEditField
        function EpsEditFieldValueChanged(app, event)
            app.Eps = app.EpsEditField.Value;
        end

        % Value changed function: showAxis
        function showAxisValueChanged(app, event)
            ax = app.UIAxes;
            if app.showAxis.Value
                axis(ax,'on')
            else
                axis(ax,'off')
            end
        end

        % Value changed function: EditUniformDegree
        function EditUniformDegreeValueChanged(app, event)
            value = app.EditUniformDegree.Value;
            p_max = findMaxDegree(nurbs);
            if value < p_max
                printOutputMessage(app,'Cannot set polynomial degree less then maximal polynomial degree of the object.')
                app.EditUniformDegree.Value = p_max;
            end
        end

        % Value changed function: InfoObjSelection
        function InfoObjSelectionValueChanged(app, event)
            updateNURBSinfo(app)
        end

        % Value changed function: SelectpatchSpinner
        function SelectpatchSpinnerValueChanged(app, event)
            updateNURBSinfo(app)
        end

        % Value changed function: useSingleColorForInput
        function useSingleColorForInputValueChanged(app, event)
            toggleObjects(app)
        end

        % Value changed function: showAvgNormals
        function showAvgNormalsValueChanged(app, event)
            toggleObjects(app)
        end

        % Button pushed function: PlayNext
        function PlayNextButtonPushed(app, event)
            switchLamp(app,'Applying one iteration of the S2V algorithm ...')
            app.S2VsurfaceCheckBox.Value = true;
            iterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AlgorithmForCase1
        function AlgorithmForCase1ValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AlgorithmForCase13
        function AlgorithmForCase13ValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AlgorithmForCase135
        function AlgorithmForCase135ValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AlgorithmForCase134
        function AlgorithmForCase134ValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AlgorithmForCase1234
        function AlgorithmForCase1234ValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
        end

        % Value changed function: AlgorithmForCase1345
        function AlgorithmForCase1345ValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AlgorithmForCase13456
        function AlgorithmForCase13456ValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AlgorithmForCase123456
        function AlgorithmForCase123456ValueChanged(app, event)
            switchLamp(app,'Reiterating current iteration of the S2V algorithm ...')
            reiterateS2V(app)
            switchLamp(app)
        end

        % Value changed function: AZEditField
        function AZEditFieldValueChanged(app, event)
            [~,EL] = view(app.UIAxes);
            view(app.UIAxes,[app.AZEditField.Value, EL])
        end

        % Value changed function: ELEditField
        function ELEditFieldValueChanged(app, event)
            [AZ,~] = view(app.UIAxes);
            view(app.UIAxes,[AZ, app.ELEditField.Value])
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            keyboard
        end

        % Value changed function: S2VsurfaceCheckBox
        function S2VsurfaceCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling S2V surface visibility ...')
            plotIfNotPresent(app,app.S2VsurfaceCheckBox.Value,4,1)
            switchLamp(app)
        end

        % Value changed function: RefinedS2VsurfaceCheckBox
        function RefinedS2VsurfaceCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling refined S2V surface visibility ...')
            plotIfNotPresent(app,app.RefinedS2VsurfaceCheckBox.Value,4,2)
            switchLamp(app)
        end

        % Menu selected function: SavestateMenu
        function SavestateMenuSelected(app, event)
            switchLamp(app,'Exporting file ...')
            [filename, pathname] = uiputfile('*.mat', 'Save a .mat state file','S2V/savedStates/');
            if filename == -1
                printOutputMessage(app,sprintf('Could not save state to file %s', filename))
                switchLamp(app)   
                return
            elseif filename == 0
                switchLamp(app)   
                return
            end
            S2Vobj_copy = app.S2Vobj;
            history_copy = app.history;
            iteration_copy = app.iteration;
            nurbsObjects_copy = app.nurbsObjects;
            save([pathname, filename],'S2Vobj_copy','history_copy','iteration_copy','nurbsObjects_copy');
            switchLamp(app)
        end

        % Menu selected function: LoadstateMenu
        function LoadstateMenuSelected(app, event)
            switchLamp(app,'Loading state ...')
            [filename, pathname] = uigetfile('*.mat', 'Load a .mat state file','S2V/savedStates/');
            if filename == -1
                printOutputMessage(app,sprintf('Could not load state from file %s', filename))
                switchLamp(app)   
                return
            elseif filename == 0
                switchLamp(app)   
                return
            end
            cleanUpPlots(app)

            load([pathname, filename],'S2Vobj_copy','history_copy','iteration_copy','nurbsObjects_copy');
            app.S2Vobj = S2Vobj_copy;
            app.history = history_copy;
            app.iteration = iteration_copy;
            app.nurbsObjects = nurbsObjects_copy;

            options = getOptions(app);
            options.replot = true;
            plot_nurbs(app,options)
            switchLamp(app)
        end

        % Button pushed function: ClearselectionButton
        function ClearselectionButtonPushed(app, event)
            if isempty(app.selectedObjects)
                return
            end
            switchLamp(app,'Unselecting patches ...')
            for patch = app.selectedObjects
                app.selectedObjects(app.selectedObjects == patch) = [];
                color = app.colors(mod(patch-1,app.no_colors)+1,:);
                app.plotHandles{4,1}.objectHandle(patch).FaceColor = color;
            end
            app.InfoObjSelection.Value = 'S2V surface';
            updateNURBSinfo(app)
            app.SelectpatchSpinner.Value = patch;
            updateNURBSinfo(app)
            switchLamp(app)
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            newCheckedNodes = setdiff(event.CheckedNodes,event.PreviousCheckedNodes);
            newUnCheckeddNodes = setdiff(event.PreviousCheckedNodes,event.CheckedNodes);
            for i = 1:numel(newCheckedNodes)
                newCheckedNodes(i).NodeData.Visible = true;
            end
            for i = 1:numel(newUnCheckeddNodes)
                newUnCheckeddNodes(i).NodeData.Visible = false;
            end
%             checkedNodes = app.Tree.CheckedNodes;
%             checkedNodes.NodeData.Visible = false;
%             checkedNodes.NodeDa
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
        end

        % Node text changed function: Tree
        function TreeNodeTextChanged(app, event)
            node = event.Node;
            
        end

        % Button pushed function: CollapseselectedsurfaceButton
        function CollapseselectedsurfaceButtonPushed(app, event)
            if isempty(app.selectedObjects)
                printOutputMessage(app,sprintf('You must select a patch to colapse.'))
                return
            end
            if app.iteration == 0
                printOutputMessage(app,sprintf('Not possible to collapse patch without changing original surface.'))
                return
            end
            if numel(app.selectedObjects) > 1
                printOutputMessage(app,sprintf('Only a single patch can be collapsed.'))
            end
            slaveXI = zeros(1,4);
            for i = 1:4
                slaveXI(i) = app.S2Vobj.topologyMap{app.selectedObjects}(i).slave;
            end
            if all(slaveXI <= app.S2Vobj.noBdryPatches) || app.selectedObjects <= app.S2Vobj.noBdryPatches
                printOutputMessage(app,sprintf('Not possible to collapse patch without changing original surface.'))
            else
                coeffs = app.S2Vobj.nurbs_bdry{app.selectedObjects}.coeffs;
                number = app.S2Vobj.nurbs_bdry{app.selectedObjects}.number;
                if all(slaveXI(1:2) > app.S2Vobj.noBdryPatches)
                    coeffs = app.S2Vobj.nurbs_bdry{app.selectedObjects}.coeffs;
                    if slaveXI(3) > app.S2Vobj.noBdryPatches
                        midx = app.S2Vobj.topologyMap{app.selectedObjects}(3).midx;
                    else
                        midx = app.S2Vobj.topologyMap{app.selectedObjects}(4).midx;
                    end
                    switch midx
                        case 1
                            coeffs = repmat(coeffs(:,1,:),[1,number(1),1]);
                        case 2
                            coeffs = repmat(coeffs(:,end,:),[1,number(1),1]);
                        case 3
                            coeffs = repmat(coeffs(:,:,1),[1,1,number(2)]);
                        case 4
                            coeffs = repmat(coeffs(:,:,end),[1,1,number(2)]);
                    end
                elseif all(slaveXI(3:4) > app.S2Vobj.noBdryPatches)
                    ...
                end
                app.S2Vobj.nurbs_bdry{app.selectedObjects}.coeffs = coeffs;
            end
        end

        % Button down function: PlotbrowserTab
        function PlotbrowserTabButtonDown(app, event)
            ...
        end

        % Button pushed function: RefreshtreeButton
        function RefreshtreeButtonPushed(app, event)
            switchLamp(app,'Updating plot browser tree...')
            updateTree(app)
            switchLamp(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Surface2volumealgorithmUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {789, 789};
                app.GridLayout.ColumnWidth = {'1x'};
                app.MiddlePanel.Layout.Row = 2;
                app.MiddlePanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {305, '1x'};
                app.MiddlePanel.Layout.Row = 1;
                app.MiddlePanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Surface2volumealgorithmUIFigure = uifigure('Visible', 'off');
            app.Surface2volumealgorithmUIFigure.Name = 'Surface2volume algorithm';
            app.Surface2volumealgorithmUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.Surface2volumealgorithmUIFigure);
            app.FileMenu.Text = 'File';
            app.SavestateMenu = uimenu(app.FileMenu);
            app.SavestateMenu.MenuSelectedFcn = createCallbackFcn(app, @SavestateMenuSelected, true);
            app.SavestateMenu.Accelerator = 's';
            app.SavestateMenu.Text = 'Save state ...';
            app.LoadstateMenu = uimenu(app.FileMenu);
            app.LoadstateMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadstateMenuSelected, true);
            app.LoadstateMenu.Accelerator = 'o';
            app.LoadstateMenu.Text = 'Load state ...';
            app.HelpMenu = uimenu(app.Surface2volumealgorithmUIFigure);
            app.HelpMenu.Text = 'Help';
            app.READMEMenu = uimenu(app.HelpMenu);
            app.READMEMenu.Text = 'README';
            app.GridLayout = uigridlayout(app.Surface2volumealgorithmUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModelDropDownLabel = uilabel(app.LeftPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.Loadg2fileButton = uibutton(app.LeftPanel, 'push');
            app.Loadg2fileButton.ButtonPushedFcn = createCallbackFcn(app, @Loadg2fileButtonPushed, true);
            app.Loadg2fileButton.Text = 'Load g2-file';
            app.ModelDropDown = uidropdown(app.LeftPanel);
            app.ModelDropDown.Items = {'<Select model>', 'Barrel', 'BCA', 'BeTSSiM1', 'BeTSSiM2', 'BeTSSiM3', 'BeTSSiM4', 'BeTSSiM4_parm2', 'S1', 'S1_interior', 'Cube', 'Mutter', 'randomCubicHole', 'Pawn'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = '<Select model>';
            app.ORLabel = uilabel(app.LeftPanel);
            app.ORLabel.Text = 'OR';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.S2VTab = uitab(app.TabGroup);
            app.S2VTab.Title = 'S2V';
            app.Surface2volumesettingsPanel = uipanel(app.S2VTab);
            app.Surface2volumesettingsPanel.Title = 'Surface2volume settings';
            app.AnglethresholdEditFieldLabel = uilabel(app.Surface2volumesettingsPanel);
            app.AnglethresholdEditFieldLabel.Text = 'Angle threshold';
            app.AnglethresholdEditField = uieditfield(app.Surface2volumesettingsPanel, 'numeric');
            app.AnglethresholdEditField.Limits = [0 180];
            app.AnglethresholdEditField.ValueChangedFcn = createCallbackFcn(app, @AnglethresholdEditFieldValueChanged, true);
            app.AnglethresholdEditField.Value = 130;
            app.ThicknessEditFieldLabel = uilabel(app.Surface2volumesettingsPanel);
            app.ThicknessEditFieldLabel.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.Surface2volumesettingsPanel, 'numeric');
            app.ThicknessEditField.Limits = [0 Inf];
            app.ThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessEditFieldValueChanged, true);
            app.ThicknessEditField.Value = 0.1;
            app.AngledeviationEditFieldLabel = uilabel(app.Surface2volumesettingsPanel);
            app.AngledeviationEditFieldLabel.Text = 'Angle deviation';
            app.AngledeviationEditField = uieditfield(app.Surface2volumesettingsPanel, 'numeric');
            app.AngledeviationEditField.Limits = [0 180];
            app.AngledeviationEditField.ValueChangedFcn = createCallbackFcn(app, @AngledeviationEditFieldValueChanged, true);
            app.AngledeviationEditField.Value = 11.25;
            app.GeometrictoleranceEditFieldLabel = uilabel(app.Surface2volumesettingsPanel);
            app.GeometrictoleranceEditFieldLabel.Text = 'Geometric tolerance';
            app.EpsEditField = uieditfield(app.Surface2volumesettingsPanel, 'numeric');
            app.EpsEditField.Limits = [0 Inf];
            app.EpsEditField.ValueChangedFcn = createCallbackFcn(app, @EpsEditFieldValueChanged, true);
            app.EpsEditField.Value = 1e-10;
            app.CaseS1Label = uilabel(app.Surface2volumesettingsPanel);
            app.CaseS1Label.Text = 'Case S = {1}';
            app.AlgorithmForCase1 = uidropdown(app.Surface2volumesettingsPanel);
            app.AlgorithmForCase1.Items = {'A1_21', 'A1_31'};
            app.AlgorithmForCase1.ValueChangedFcn = createCallbackFcn(app, @AlgorithmForCase1ValueChanged, true);
            app.AlgorithmForCase1.Value = 'A1_21';
            app.CaseS13Label = uilabel(app.Surface2volumesettingsPanel);
            app.CaseS13Label.Text = 'Case S = {1,3}';
            app.AlgorithmForCase13 = uidropdown(app.Surface2volumesettingsPanel);
            app.AlgorithmForCase13.Items = {'A13_11', 'A13_21', 'A13_31', 'A13_33', 'A13_41'};
            app.AlgorithmForCase13.ValueChangedFcn = createCallbackFcn(app, @AlgorithmForCase13ValueChanged, true);
            app.AlgorithmForCase13.Value = 'A13_21';
            app.CaseS135Label = uilabel(app.Surface2volumesettingsPanel);
            app.CaseS135Label.Text = 'Case S = {1,3,5}';
            app.AlgorithmForCase135 = uidropdown(app.Surface2volumesettingsPanel);
            app.AlgorithmForCase135.Items = {'A135_11', 'A135_21', 'A135_31', 'A135_33', 'A135_35', 'A135_41'};
            app.AlgorithmForCase135.ValueChangedFcn = createCallbackFcn(app, @AlgorithmForCase135ValueChanged, true);
            app.AlgorithmForCase135.Value = 'A135_21';
            app.CaseS134Label = uilabel(app.Surface2volumesettingsPanel);
            app.CaseS134Label.Text = 'Case S = {1,3,4}';
            app.AlgorithmForCase134 = uidropdown(app.Surface2volumesettingsPanel);
            app.AlgorithmForCase134.Items = {'A134_11'};
            app.AlgorithmForCase134.ValueChangedFcn = createCallbackFcn(app, @AlgorithmForCase134ValueChanged, true);
            app.AlgorithmForCase134.Value = 'A134_11';
            app.CaseS1234Label = uilabel(app.Surface2volumesettingsPanel);
            app.CaseS1234Label.Text = 'Case S = {1,2,3,4}';
            app.AlgorithmForCase1234 = uidropdown(app.Surface2volumesettingsPanel);
            app.AlgorithmForCase1234.Items = {'A1234_11'};
            app.AlgorithmForCase1234.ValueChangedFcn = createCallbackFcn(app, @AlgorithmForCase1234ValueChanged, true);
            app.AlgorithmForCase1234.Value = 'A1234_11';
            app.CaseS1345Label = uilabel(app.Surface2volumesettingsPanel);
            app.CaseS1345Label.Text = 'Case S = {1,3,4,5}';
            app.AlgorithmForCase1345 = uidropdown(app.Surface2volumesettingsPanel);
            app.AlgorithmForCase1345.Items = {'A1345_11'};
            app.AlgorithmForCase1345.ValueChangedFcn = createCallbackFcn(app, @AlgorithmForCase1345ValueChanged, true);
            app.AlgorithmForCase1345.Value = 'A1345_11';
            app.CaseS13456Label = uilabel(app.Surface2volumesettingsPanel);
            app.CaseS13456Label.Text = 'Case S = {1,3,4,5,6}';
            app.AlgorithmForCase13456 = uidropdown(app.Surface2volumesettingsPanel);
            app.AlgorithmForCase13456.Items = {'A13456_11'};
            app.AlgorithmForCase13456.ValueChangedFcn = createCallbackFcn(app, @AlgorithmForCase13456ValueChanged, true);
            app.AlgorithmForCase13456.Value = 'A13456_11';
            app.CaseS123456DropDownLabel_8 = uilabel(app.Surface2volumesettingsPanel);
            app.CaseS123456DropDownLabel_8.Text = 'Case S = {1,2,3,4,5,6}';
            app.AlgorithmForCase123456 = uidropdown(app.Surface2volumesettingsPanel);
            app.AlgorithmForCase123456.Items = {'A123456_11'};
            app.AlgorithmForCase123456.ValueChangedFcn = createCallbackFcn(app, @AlgorithmForCase123456ValueChanged, true);
            app.AlgorithmForCase123456.Value = 'A123456_11';
            app.CollapseselectedsurfaceButton = uibutton(app.Surface2volumesettingsPanel, 'push');
            app.CollapseselectedsurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @CollapseselectedsurfaceButtonPushed, true);
            app.CollapseselectedsurfaceButton.Text = 'Collapse selected surface';
            app.PostprocTab = uitab(app.TabGroup);
            app.PostprocTab.Title = 'Post proc';
            app.ExportPanel = uipanel(app.PostprocTab);
            app.ExportPanel.Title = 'Export';
            app.Exportg2fileButton = uibutton(app.ExportPanel, 'push');
            app.Exportg2fileButton.ButtonPushedFcn = createCallbackFcn(app, @Exportg2fileButtonPushed, true);
            app.Exportg2fileButton.Text = 'Export g2-file';
            app.ExportObjSelection = uidropdown(app.ExportPanel);
            app.ExportObjSelection.Items = {'Input object', 'Input surface', 'Output volume', 'Refined input object', 'Refined input surface', 'Refined output volume'};
            app.ExportObjSelection.Value = 'Output volume';
            app.RefinementPanel = uipanel(app.PostprocTab);
            app.RefinementPanel.Title = 'Refinement';
            app.RefineButton = uibutton(app.RefinementPanel, 'push');
            app.RefineButton.ButtonPushedFcn = createCallbackFcn(app, @RefineButtonPushed, true);
            app.RefineButton.Text = 'Refine';
            app.Seth_maxEditFieldLabel = uilabel(app.RefinementPanel);
            app.Seth_maxEditFieldLabel.Text = 'Set h_max';
            app.Seth_maxEditField = uieditfield(app.RefinementPanel, 'numeric');
            app.Seth_maxEditField.Limits = [0 Inf];
            app.Seth_maxEditField.Value = 0.3;
            app.UseUniformDegree = uicheckbox(app.RefinementPanel);
            app.UseUniformDegree.Text = 'Uniform degree elevation using degree';
            app.UseUniformDegree.Value = true;
            app.EditUniformDegree = uieditfield(app.RefinementPanel, 'numeric');
            app.EditUniformDegree.Limits = [1 Inf];
            app.EditUniformDegree.RoundFractionalValues = 'on';
            app.EditUniformDegree.ValueDisplayFormat = '%.0f';
            app.EditUniformDegree.ValueChangedFcn = createCallbackFcn(app, @EditUniformDegreeValueChanged, true);
            app.EditUniformDegree.Value = 2;
            app.RefineObjSelection = uidropdown(app.RefinementPanel);
            app.RefineObjSelection.Items = {'Input object', 'Input surface', 'Output volume'};
            app.RefineObjSelection.Value = 'Output volume';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.SelectobjectDropDownLabel = uilabel(app.InfoTab);
            app.SelectobjectDropDownLabel.Text = 'Select object';
            app.InfoObjSelection = uidropdown(app.InfoTab);
            app.InfoObjSelection.Items = {'Input object', 'Input surface', 'Output volume', 'S2V surface', 'Refined input object', 'Refined input surface', 'Refined output volume', 'Refined S2V surface'};
            app.InfoObjSelection.ValueChangedFcn = createCallbackFcn(app, @InfoObjSelectionValueChanged, true);
            app.InfoObjSelection.Value = 'Input object';
            app.nurbsInfo = uitextarea(app.InfoTab);
            app.nurbsInfo.Editable = 'off';
            app.SelectpatchSpinnerLabel = uilabel(app.InfoTab);
            app.SelectpatchSpinnerLabel.Text = 'Select patch';
            app.SelectpatchSpinner = uispinner(app.InfoTab);
            app.SelectpatchSpinner.Limits = [1 Inf];
            app.SelectpatchSpinner.RoundFractionalValues = 'on';
            app.SelectpatchSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectpatchSpinnerValueChanged, true);
            app.SelectpatchSpinner.Value = 1;
            app.ClearselectionButton = uibutton(app.InfoTab, 'push');
            app.ClearselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearselectionButtonPushed, true);
            app.ClearselectionButton.Text = 'Clear selection';
            app.Play = uibutton(app.LeftPanel, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayPushed, true);
            app.Play.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'play.svg');
            app.Play.Text = '';
            app.Forward = uibutton(app.LeftPanel, 'push');
            app.Forward.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.Forward.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'forward.svg');
            app.Forward.Text = '';
            app.Backward = uibutton(app.LeftPanel, 'push');
            app.Backward.ButtonPushedFcn = createCallbackFcn(app, @BackwardButtonPushed, true);
            app.Backward.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'backward.svg');
            app.Backward.Text = '';
            app.reset = uibutton(app.LeftPanel, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetPushed, true);
            app.reset.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'stop.svg');
            app.reset.Text = '';
            app.ForwardEnd = uibutton(app.LeftPanel, 'push');
            app.ForwardEnd.ButtonPushedFcn = createCallbackFcn(app, @ForwardEndButtonPushed, true);
            app.ForwardEnd.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'forwardEnd.svg');
            app.ForwardEnd.Text = '';
            app.BackwardEnd = uibutton(app.LeftPanel, 'push');
            app.BackwardEnd.ButtonPushedFcn = createCallbackFcn(app, @BackwardEndButtonPushed, true);
            app.BackwardEnd.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'backwardEnd.svg');
            app.BackwardEnd.Text = '';
            app.PlayNext = uibutton(app.LeftPanel, 'push');
            app.PlayNext.ButtonPushedFcn = createCallbackFcn(app, @PlayNextButtonPushed, true);
            app.PlayNext.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'playNext.svg');
            app.PlayNext.Text = '';
            app.MiddlePanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.MiddlePanel);
            xlabel(app.UIAxes, 'x axis')
            ylabel(app.UIAxes, 'y axis')
            zlabel(app.UIAxes, 'z axis')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PopoutButton = uibutton(app.MiddlePanel, 'push');
            app.PopoutButton.ButtonPushedFcn = createCallbackFcn(app, @PopoutButtonPushed, true);
            app.PopoutButton.Text = 'Pop out';
            app.TabGroup2 = uitabgroup(app.MiddlePanel);
            app.PlotsettingsTab = uitab(app.TabGroup2);
            app.PlotsettingsTab.Title = 'Plot settings';
            app.SettingsforplottingPanel = uipanel(app.PlotsettingsTab);
            app.SettingsforplottingPanel.Title = 'Settings for plotting';
            app.CamlightCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.CamlightCheckBox.ValueChangedFcn = createCallbackFcn(app, @CamlightCheckBoxValueChanged, true);
            app.CamlightCheckBox.Text = 'Camlight';
            app.CamlightCheckBox.Value = true;
            app.ShowcontrolpolygonCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.ShowcontrolpolygonCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowcontrolpolygonCheckBoxValueChanged, true);
            app.ShowcontrolpolygonCheckBox.Text = 'Show control polygon';
            app.ShowelementedgesCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.ShowelementedgesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowelementedgesCheckBoxValueChanged, true);
            app.ShowelementedgesCheckBox.Text = 'Show element edges';
            app.ShowelementedgesCheckBox.Value = true;
            app.ShowparametricdirectionsCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.ShowparametricdirectionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowparametricdirectionsCheckBoxValueChanged, true);
            app.ShowparametricdirectionsCheckBox.Text = 'Show parametric directions';
            app.ShownormalvectorsCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.ShownormalvectorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShownormalvectorsCheckBoxValueChanged, true);
            app.ShownormalvectorsCheckBox.Text = 'Show normal vectors';
            app.ShowobjectCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.ShowobjectCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowobjectCheckBoxValueChanged, true);
            app.ShowobjectCheckBox.Text = 'Show object';
            app.ShowobjectCheckBox.Value = true;
            app.coarseLinearSamplingCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.coarseLinearSamplingCheckBox.ValueChangedFcn = createCallbackFcn(app, @coarseLinearSamplingCheckBoxValueChanged, true);
            app.coarseLinearSamplingCheckBox.Text = 'Use two-point sampling for linear directions';
            app.coarseLinearSamplingCheckBox.Value = true;
            app.AlphavalueSliderLabel = uilabel(app.SettingsforplottingPanel);
            app.AlphavalueSliderLabel.Text = 'Alpha value';
            app.AlphavalueSlider = uislider(app.SettingsforplottingPanel);
            app.AlphavalueSlider.Limits = [0 1];
            app.AlphavalueSlider.MajorTicks = [];
            app.AlphavalueSlider.MajorTickLabels = {''};
            app.AlphavalueSlider.ValueChangingFcn = createCallbackFcn(app, @AlphavalueSliderValueChanging, true);
            app.AlphavalueSlider.MinorTicks = [];
            app.AlphavalueSlider.Value = 1;
            app.ColorfunctionEditField = uieditfield(app.SettingsforplottingPanel, 'text');
            app.ColorfunctionEditField.ValueChangedFcn = createCallbackFcn(app, @ColorfunctionEditFieldValueChanged, true);
            app.ColorfunctionEditField.Value = '@(x) log10(abs(norm2(x(:,1:3))-1))';
            app.AlphavalueEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.AlphavalueEditField.Limits = [0 1];
            app.AlphavalueEditField.ValueChangedFcn = createCallbackFcn(app, @AlphavalueEditFieldValueChanged, true);
            app.AlphavalueEditField.Value = 1;
            app.QuiverscaleEditFieldLabel = uilabel(app.SettingsforplottingPanel);
            app.QuiverscaleEditFieldLabel.Text = 'Quiver scale';
            app.QuiverscaleEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.QuiverscaleEditField.Limits = [0 10];
            app.QuiverscaleEditField.ValueChangedFcn = createCallbackFcn(app, @QuiverscaleEditFieldValueChanged, true);
            app.QuiverscaleEditField.Value = 0.5;
            app.QuiverstepEditFieldLabel = uilabel(app.SettingsforplottingPanel);
            app.QuiverstepEditFieldLabel.Text = 'Quiver step';
            app.QuiverstepEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.QuiverstepEditField.Limits = [1 Inf];
            app.QuiverstepEditField.ValueChangedFcn = createCallbackFcn(app, @QuiverstepEditFieldValueChanged, true);
            app.QuiverstepEditField.Value = 10;
            app.AutoscaleCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.AutoscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoscaleCheckBoxValueChanged, true);
            app.AutoscaleCheckBox.Text = 'Autoscale';
            app.ColoringDropDownLabel = uilabel(app.SettingsforplottingPanel);
            app.ColoringDropDownLabel.Text = 'Coloring';
            app.ColoringDropDown = uidropdown(app.SettingsforplottingPanel);
            app.ColoringDropDown.Items = {'None', 'Custom', 'Jacobian', 'Weights'};
            app.ColoringDropDown.ValueChangedFcn = createCallbackFcn(app, @ColoringDropDownValueChanged, true);
            app.ColoringDropDown.Value = 'None';
            app.CustomfunctionforcoloringLabel = uilabel(app.SettingsforplottingPanel);
            app.CustomfunctionforcoloringLabel.Text = 'Custom function for coloring:';
            app.showAxis = uicheckbox(app.SettingsforplottingPanel);
            app.showAxis.ValueChangedFcn = createCallbackFcn(app, @showAxisValueChanged, true);
            app.showAxis.Text = 'Show axis';
            app.useSingleColorForInput = uicheckbox(app.SettingsforplottingPanel);
            app.useSingleColorForInput.ValueChangedFcn = createCallbackFcn(app, @useSingleColorForInputValueChanged, true);
            app.useSingleColorForInput.Text = 'Use single color for input objects';
            app.useSingleColorForInput.Value = true;
            app.showAvgNormals = uicheckbox(app.SettingsforplottingPanel);
            app.showAvgNormals.ValueChangedFcn = createCallbackFcn(app, @showAvgNormalsValueChanged, true);
            app.showAvgNormals.Text = 'Show average normal vectors';
            app.AZEditFieldLabel = uilabel(app.SettingsforplottingPanel);
            app.AZEditFieldLabel.Text = 'AZ';
            app.AZEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.AZEditField.ValueChangedFcn = createCallbackFcn(app, @AZEditFieldValueChanged, true);
            app.ViewLabel = uilabel(app.SettingsforplottingPanel);
            app.ViewLabel.Text = 'View:';
            app.ELEditFieldLabel = uilabel(app.SettingsforplottingPanel);
            app.ELEditFieldLabel.Text = 'EL';
            app.ELEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.ELEditField.ValueChangedFcn = createCallbackFcn(app, @ELEditFieldValueChanged, true);
            app.PlottingresolutionEditFieldLabel = uilabel(app.SettingsforplottingPanel);
            app.PlottingresolutionEditFieldLabel.Text = 'Plotting resolution';
            app.PlottingresolutionEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.PlottingresolutionEditField.Limits = [0 400];
            app.PlottingresolutionEditField.RoundFractionalValues = 'on';
            app.PlottingresolutionEditField.ValueChangedFcn = createCallbackFcn(app, @PlottingresolutionEditFieldValueChanged, true);
            app.PlottingresolutionEditField.Value = 32;
            app.ObjectvisibilityPanel = uipanel(app.PlotsettingsTab);
            app.ObjectvisibilityPanel.Title = 'Object visibility';
            app.InputsurfaceCheckBox = uicheckbox(app.ObjectvisibilityPanel);
            app.InputsurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @InputsurfaceCheckBoxValueChanged, true);
            app.InputsurfaceCheckBox.Text = 'Input surface';
            app.OutputvolumeCheckBox = uicheckbox(app.ObjectvisibilityPanel);
            app.OutputvolumeCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputvolumeCheckBoxValueChanged, true);
            app.OutputvolumeCheckBox.Text = 'Output volume';
            app.RefinedoutputvolumeCheckBox = uicheckbox(app.ObjectvisibilityPanel);
            app.RefinedoutputvolumeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RefinedoutputvolumeCheckBoxValueChanged, true);
            app.RefinedoutputvolumeCheckBox.Text = 'Refined output volume';
            app.InputobjectCheckBox = uicheckbox(app.ObjectvisibilityPanel);
            app.InputobjectCheckBox.ValueChangedFcn = createCallbackFcn(app, @InputobjectCheckBoxValueChanged, true);
            app.InputobjectCheckBox.Text = 'Input object';
            app.RefinedinputsurfaceCheckBox = uicheckbox(app.ObjectvisibilityPanel);
            app.RefinedinputsurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @RefinedinputsurfaceCheckBoxValueChanged, true);
            app.RefinedinputsurfaceCheckBox.Text = 'Refined input surface';
            app.RefinedinputobjectCheckBox = uicheckbox(app.ObjectvisibilityPanel);
            app.RefinedinputobjectCheckBox.ValueChangedFcn = createCallbackFcn(app, @RefinedinputobjectCheckBoxValueChanged, true);
            app.RefinedinputobjectCheckBox.Text = 'Refined input object';
            app.S2VsurfaceCheckBox = uicheckbox(app.ObjectvisibilityPanel);
            app.S2VsurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @S2VsurfaceCheckBoxValueChanged, true);
            app.S2VsurfaceCheckBox.Text = 'S2V surface';
            app.S2VsurfaceCheckBox.Value = true;
            app.RefinedS2VsurfaceCheckBox = uicheckbox(app.ObjectvisibilityPanel);
            app.RefinedS2VsurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @RefinedS2VsurfaceCheckBoxValueChanged, true);
            app.RefinedS2VsurfaceCheckBox.Text = 'Refined S2V surface';
            app.PlotbrowserTab = uitab(app.TabGroup2);
            app.PlotbrowserTab.Title = 'Plot browser';
            app.PlotbrowserTab.ButtonDownFcn = createCallbackFcn(app, @PlotbrowserTabButtonDown, true);
            app.Tree = uitree(app.PlotbrowserTab, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.NodeTextChangedFcn = createCallbackFcn(app, @TreeNodeTextChanged, true);
            app.InputobjectNode = uitreenode(app.Tree);
            app.InputobjectNode.Text = 'Input object';
            app.InputsurfaceNode = uitreenode(app.Tree);
            app.InputsurfaceNode.Text = 'Input surface';
            app.OutputvolumeNode = uitreenode(app.Tree);
            app.OutputvolumeNode.Text = 'Output volume';
            app.S2VsurfaceNode = uitreenode(app.Tree);
            app.S2VsurfaceNode.Text = 'S2V surface';
            app.RefinedinputobjectNode = uitreenode(app.Tree);
            app.RefinedinputobjectNode.Text = 'Refined input object';
            app.RefinedinputsurfaceNode = uitreenode(app.Tree);
            app.RefinedinputsurfaceNode.Text = 'Refined input surface';
            app.RefinedoutputvolumeNode = uitreenode(app.Tree);
            app.RefinedoutputvolumeNode.Text = 'Refined output volume';
            app.RefinedS2VsurfaceNode = uitreenode(app.Tree);
            app.RefinedS2VsurfaceNode.Text = 'Refined S2V surface';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.RefreshtreeButton = uibutton(app.PlotbrowserTab, 'push');
            app.RefreshtreeButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshtreeButtonPushed, true);
            app.RefreshtreeButton.Text = 'Refresh tree';
            app.OutputmessagesPanel = uipanel(app.MiddlePanel);
            app.OutputmessagesPanel.Title = 'Output messages';
            app.OutputMessages = uitextarea(app.OutputmessagesPanel);
            app.OutputMessages.Editable = 'off';
            app.ReadyLampLabel = uilabel(app.MiddlePanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.MiddlePanel);
            % Show the figure after all components are created
            app.Surface2volumealgorithmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = surface2volume

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Surface2volumealgorithmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Surface2volumealgorithmUIFigure)
        end
    end
end